classdef VIStudio_AnomalyDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VIStudio_AnomalyDetectionUIFigure  matlab.ui.Figure
        OnlyFCDDneedanomalytrainingdataLabel  matlab.ui.control.Label
        ModelLoadedLamp           matlab.ui.control.Lamp
        ModelLoadedLampLabel      matlab.ui.control.Label
        DataSourceEditField       matlab.ui.control.EditField
        DataSourceEditFieldLabel  matlab.ui.control.Label
        ModelDropDown             matlab.ui.control.DropDown
        ModelDropDownLabel        matlab.ui.control.Label
        StateChangeSwitch         matlab.ui.control.Switch
        ModelReloadButton         matlab.ui.control.Button
        Inference_Panel           matlab.ui.container.Panel
        InferenceImgUIAxes        matlab.ui.control.UIAxes
        ToolbarPanel              matlab.ui.container.Panel
        TestDataImportButton      matlab.ui.control.Button
        AnomalyDataImportButton   matlab.ui.control.Button
        LoadImageButton           matlab.ui.control.Button
        AnomalyDetectionButton    matlab.ui.control.Button
        ExportModelButton         matlab.ui.control.Button
        TrainingOptionsButton     matlab.ui.control.StateButton
        StartTrainButton          matlab.ui.control.Button
        NormalDataImportButton    matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        AnomalyDetectionLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)

        TrainingOptionApp % Description
        trainingDict = dictionary
        trainingOptionsName =[...
            "Solver";...
            "MaxEpochs";...
            "MiniBatchSize";...
            "ValidationData";...
            "ValidationFrequency";...
            "Verbose";...
            "Plots";...
            "Shuffle";...
            "InitialLearnRate";...
            "LearnRateSchedule";...
            "LearnRateDropPeriod";...
            "LearnRateDropFactor"
            ];

        trainingOpts
        splitRatio

        rawDataSrc
        normalDataSrc
        abnormalDataSrc
        validationDataSrc
        normalValDataSrc
        abnormalValDataSrc
        TestDataSrc
        

        AnomalyMdl % Description
        detectorType

        trainedDetector

        inferenceImg
        inferenceResult
        
        explainMap

    end
    
    methods (Access = private)

         function gatherTrainingOptionsParameters(app)

            % Check if TrainingOptionApp is empty 
            if ~isempty(app.TrainingOptionApp)
                
                subAppFig = app.TrainingOptionApp.TrainingOptionUIFigure;
                trainingOptionObj = findobj(subAppFig,'Tag','TrainingOptions');
                trainingOptionValue = get(trainingOptionObj,'Value');

                app.trainingDict = insert(app.trainingDict,app.trainingOptionsName,trainingOptionValue);

                if isempty(app.trainingDict{'ValidationData'})
                    app.trainingDict{'ValidationData'} = [];
                end

                % Create training options
                app.trainingOpts = trainingOptions(...
                    app.trainingDict{"Solver"},...
                    MaxEpochs = app.trainingDict{"MaxEpochs"},...
                    MiniBatchSize = app.trainingDict{"MiniBatchSize"},...
                    Verbose = app.trainingDict{"Verbose"},...
                    Plots = app.trainingDict{"Plots"},...
                    Shuffle = app.trainingDict{"Shuffle"},...
                    InitialLearnRate = app.trainingDict{"InitialLearnRate"},...
                    LearnRateSchedule = app.trainingDict{"LearnRateSchedule"},...
                    LearnRateDropPeriod = app.trainingDict{"LearnRateDropPeriod"},...
                    LearnRateDropFactor = app.trainingDict{"LearnRateDropFactor"},...
                    ExecutionEnvironment  = "auto"...
                    );
                    %ValidationData = [],...%app.trainingDict{"ValidationData"},...
                    %ValidationFrequency = app.trainingDict{"ValidationFrequency"},...
            else
                app.trainingOpts = trainingOptions("adam", ...
                    InitialLearnRate=0.01,...
                    MaxEpochs=1,...
                    MiniBatchSize=2,...
                    ExecutionEnvironment="auto",...
                    Plots="training-progress", ...
                    Verbose=false);
            end
                    %ValidationData={app.normalValDataSrc, app.abnormalValDataSrc}, ...
                    %ValidationFrequency=5, ...
         end
        
        function  imgOut = preprocessDsImg(app,filename)
            img = imread(filename);
            imgOut = imresize(img, [125,125]); % Temp size
        end

       function chkModelLoaded(app)
            if ~isempty(app.AnomalyMdl)
                app.ModelLoadedLamp.Color = [0.39,0.83,0.07];
            end
       end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            app.detectorType = app.ModelDropDown.Value;
            
 
            switch app.detectorType
                case "FCDD"
                    backbone = pretrainedEncoderNetwork("resnet18",3);
                    app.AnomalyMdl = fcddAnomalyDetector(backbone);

                case "PatchCore_resnet18"
                    app.AnomalyMdl = patchCoreAnomalyDetector(Backbone="resnet18");
                   
                case "PatchCore_resnet50"
                    app.AnomalyMdl = patchCoreAnomalyDetector(Backbone="resnet50");

                case "EfficientAD_small"
                    app.AnomalyMdl = efficientADAnomalyDetector(Network="pdn-small");

                case "EfficientAD_medium"
                    app.AnomalyMdl = efficientADAnomalyDetector(Network="pdn-medium");

                case "User Trained Model"
                    [file, path] = uigetfile('*.mat');
                    if isscalar(file) || isscalar(path)
                        disp('User cancel import')
                        return
                    end
                
                    % 讀取使用者已訓練模型
                    load(fullfile(path, file))
                    app.trainedDetector = model;
                    modelType = class(app.trainedDetector);
                    
                    if contains(modelType, 'fcdd')
                        app.detectorType = "FCDD";
                    elseif contains(modelType, 'PatchCore')
                        app.detectorType = "PatchCore";
                    else
                        app.detectorType = "EfficientAD";
                    end

            end
        end

        % Button pushed function: NormalDataImportButton
        function NormalDataImportButtonPushed(app, event)
            folderpath = uigetdir;


            if isscalar(folderpath)
                disp('User cancel import')
                return
            else
                   progressbar = uiprogressdlg(app.VIStudio_AnomalyDetectionUIFigure,...
                    Message="Data Importing" ,...
                    Title="Import Data",...
                    Indeterminate="on");

                app.normalDataSrc = imageDatastore(folderpath,...
                    IncludeSubfolders=true,...
                    LabelSource="foldernames");
                app.DataSourceEditField.Value = folderpath;
                delete(progressbar)
            end

            %{
            labelNum = numel(app.rawDataSrc.Labels);
            if labelNum < 2
                disp('Only one class')
            else
                [app.normalDataSrc, app.validationDataSrc, app.abnormalDataSrc] = splitAnomalyData(app.rawDataSrc,"Anomaly");
            end

            normIdx = app.validationDataSrc.Labels == "Normal";
             app.normalValDataSrc = subset(app.validationDataSrc, normIdx);
             app.abnormalValDataSrc = subset(app.validationDataSrc, ~normIdx);
            %}

        end

        % Button pushed function: StartTrainButton
        function StartTrainButtonPushed(app, event)
            gatherTrainingOptionsParameters(app)

            trainingProgressBar = uiprogressdlg(app.VIStudio_AnomalyDetectionUIFigure,...
                Message="Network Training...",...
                Title='Train network',...
                Indeterminate='on');
            switch app.detectorType
                case "FCDD"
                    app.trainedDetector = trainFCDDAnomalyDetector(app.normalDataSrc, app.abnormalDataSrc, app.AnomalyMdl, app.trainingOpts);
                case "PatchCore_resnet18"
                    app.trainedDetector = trainPatchCoreAnomalyDetector(app.normalDataSrc, app.AnomalyMdl, CompressionRatio=0.1);
                case "PatchCore_resnet50"
                    app.trainedDetector = trainPatchCoreAnomalyDetector(app.normalDataSrc, app.AnomalyMdl, CompressionRatio=0.1);
                case "EfficientAD_small"
                    app.trainedDetector = trainEfficientADAnomalyDetector(app.normalDataSrc,app.AnomalyMdl,app.trainingOpts);
                case "EfficientAD_medium"
                    app.trainedDetector = trainEfficientADAnomalyDetector(app.normalDataSrc,app.AnomalyMdl,app.trainingOpts);
                case "User Trained Model"
                    % app.trainedDetector = app.AnomalyMdl;
            end
            delete(trainingProgressBar)
            
            % analyze
            AnomalyProgressBar = uiprogressdlg(app.VIStudio_AnomalyDetectionUIFigure,...
                Message="Analyz...",...
                Title='Analyze Data',...
                Indeterminate='on');

            % set Anomaly
            scores = predict(app.trainedDetector,app.TestDataSrc);
            labels = app.TestDataSrc.Labels ~=  "NormalDS";
            % 想辦法設計此名稱
            
            % Anomaly Histogram
            numBins = 20;
            [~,edges] = histcounts(scores,numBins);
            figure
            hold on
            hNormal = histogram(scores(labels==0),edges);
            hAnomaly = histogram(scores(labels==1),edges);
            hold off
            legend([hNormal,hAnomaly],"Normal","Anomaly")
            xlabel("Mean Anomaly Score")
            ylabel("Counts")

            [thresh,roc] = anomalyThreshold(labels,scores,true);
            app.trainedDetector.Threshold = thresh;

            % show model
            if ~isempty(app.TestDataSrc)
                testSetOutputLabels = classify(app.trainedDetector,app.TestDataSrc);
                testSetTargetLabels = app.TestDataSrc.Labels;
                metrics = evaluateAnomalyDetection(testSetOutputLabels,testSetTargetLabels,anomalyClasses);
                % 煩惱anomalyClasses如何設定

                M = metrics.ConfusionMatrix{:,:};
                confusionchart(M,["Normal","Anomaly"])
                acc = sum(diag(M)) / sum(M,"all");
                title("Accuracy: "+acc)

            end
            
            delete(AnomalyProgressBar)


        end

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)
            ModelDropDownValueChanged(app)
            chkModelLoaded(app)
        end

        % Button pushed function: AnomalyDetectionButton
        function AnomalyDetectionButtonPushed(app, event)
            app.explainMap = anomalyMap(app.trainedDetector, app.inferenceImg);
            ExplainMethods = app.ModelDropDown.Value;
             progressbar = uiprogressdlg(app.VIStudio_AnomalyDetectionUIFigure,...
                Message="Model explain using: "+ ExplainMethods ,...
                Title="Model Explain",...
                Indeterminate="on"); 
            app.inferenceResult = anomalyMapOverlay(app.inferenceImg, app.explainMap, Blend="equal");
            imshow(app.inferenceResult, Parent=app.InferenceImgUIAxes)
            delete(progressbar)
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             [file, path] = uigetfile('*.*',MultiSelect="off");

            if isscalar(file) || isscalar(path)
                disp('User discard import image')
                return
            end

            app.inferenceImg = preprocessDsImg(app,fullfile(path,file));

            % Visualization
            imshow(app.inferenceImg, Parent=app.InferenceImgUIAxes)

        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            % 匯出訓練好的 Detector
            if ~isempty(app.trainedDetector)
                [file,location] = uiputfile('.*', 'Save trained model', 'trainedModel.mat');
                model = app.trainedDetector;
                save(fullfile(location,file), 'model')
            end
        end

        % Value changed function: TrainingOptionsButton
        function TrainingOptionsButtonValueChanged(app, event)
            OptionOnOff = app.TrainingOptionsButton.Value;

            switch OptionOnOff
                case true
                    if isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp = VIStudio_AnomalyTrainingOption(app);
                    else
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'On';
                    end
                case false
                    if ~isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'Off';
                    end
            end
        end

        % Button pushed function: AnomalyDataImportButton
        function AnomalyDataImportButtonPushed(app, event)
            folderpath = uigetdir;


            if isscalar(folderpath)
                disp('User cancel import')
                return
            else
                   progressbar = uiprogressdlg(app.VIStudio_AnomalyDetectionUIFigure,...
                    Message="Data Importing" ,...
                    Title="Import Data",...
                    Indeterminate="on");

                app.abnormalDataSrc = imageDatastore(folderpath,...
                    IncludeSubfolders=true,...
                    LabelSource="foldernames");
                app.DataSourceEditField.Value = folderpath;
                delete(progressbar)
            end
        end

        % Value changed function: StateChangeSwitch
        function StateChangeSwitchValueChanged(app, event)
            value = app.StateChangeSwitch.Value;
            
        end

        % Button pushed function: TestDataImportButton
        function TestDataImportButtonPushed(app, event)
            folderpath = uigetdir;


            if isscalar(folderpath)
                disp('User cancel import')
                return
            else
                   progressbar = uiprogressdlg(app.VIStudio_AnomalyDetectionUIFigure,...
                    Message="Data Importing" ,...
                    Title="Import Data",...
                    Indeterminate="on");

                app.TestDataSrc = imageDatastore(folderpath,...
                    IncludeSubfolders=true,...
                    LabelSource="foldernames");
                app.DataSourceEditField.Value = folderpath;
                delete(progressbar)

            %{
            labelNum = numel(app.rawDataSrc.Labels);
            if labelNum < 2
                disp('Only one class')
            else
                [app.normalDataSrc, app.validationDataSrc, app.abnormalDataSrc] = splitAnomalyData(app.rawDataSrc,"Anomaly");
            end

            normIdx = app.validationDataSrc.Labels == "Normal";
             app.normalValDataSrc = subset(app.validationDataSrc, normIdx);
             app.abnormalValDataSrc = subset(app.validationDataSrc, ~normIdx);
            %}
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VIStudio_AnomalyDetectionUIFigure = uifigure('Visible', 'off');
            app.VIStudio_AnomalyDetectionUIFigure.Name = 'VIStudio_AnomalyDetection';
            app.Panel = uipanel(app.VIStudio_AnomalyDetectionUIFigure);
            app.AnomalyDetectionLabel = uilabel(app.Panel);
            app.AnomalyDetectionLabel.Text = 'Anomaly Detection';
            app.ToolbarPanel = uipanel(app.VIStudio_AnomalyDetectionUIFigure);
            app.NormalDataImportButton = uibutton(app.ToolbarPanel, 'push');
            app.NormalDataImportButton.ButtonPushedFcn = createCallbackFcn(app, @NormalDataImportButtonPushed, true);
            app.NormalDataImportButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Import Data.png');
            app.NormalDataImportButton.Text = {'Normal Data'; 'Import'};
            app.StartTrainButton = uibutton(app.ToolbarPanel, 'push');
            app.StartTrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainButtonPushed, true);
            app.StartTrainButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Train.png');
            app.StartTrainButton.Text = {'Start'; 'Train'};
            app.TrainingOptionsButton = uibutton(app.ToolbarPanel, 'state');
            app.TrainingOptionsButton.ValueChangedFcn = createCallbackFcn(app, @TrainingOptionsButtonValueChanged, true);
            app.TrainingOptionsButton.Icon = fullfile(pathToMLAPP, 'Icon', 'setting.png');
            app.TrainingOptionsButton.Text = {'Training'; 'Options'};
            app.ExportModelButton = uibutton(app.ToolbarPanel, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Export.png');
            app.ExportModelButton.Text = {'Export'; 'Model'};
            app.AnomalyDetectionButton = uibutton(app.ToolbarPanel, 'push');
            app.AnomalyDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @AnomalyDetectionButtonPushed, true);
            app.AnomalyDetectionButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Detect Anomaly.png');
            app.AnomalyDetectionButton.Text = {'Anomaly'; 'Detection'};
            app.LoadImageButton = uibutton(app.ToolbarPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = fullfile(pathToMLAPP, 'Icon', 'photo.png');
            app.LoadImageButton.Text = {'Load'; 'Image'};
            app.AnomalyDataImportButton = uibutton(app.ToolbarPanel, 'push');
            app.AnomalyDataImportButton.ButtonPushedFcn = createCallbackFcn(app, @AnomalyDataImportButtonPushed, true);
            app.AnomalyDataImportButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Import Data.png');
            app.AnomalyDataImportButton.Text = {'Anomaly Data '; 'Import'};
            app.TestDataImportButton = uibutton(app.ToolbarPanel, 'push');
            app.TestDataImportButton.ButtonPushedFcn = createCallbackFcn(app, @TestDataImportButtonPushed, true);
            app.TestDataImportButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Import Data.png');
            app.TestDataImportButton.Text = {'Test Data '; 'Import'};
            app.Inference_Panel = uipanel(app.VIStudio_AnomalyDetectionUIFigure);
            app.InferenceImgUIAxes = uiaxes(app.Inference_Panel);
            title(app.InferenceImgUIAxes, 'Title')
            zlabel(app.InferenceImgUIAxes, 'Z')
            app.ModelReloadButton = uibutton(app.VIStudio_AnomalyDetectionUIFigure, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Text = 'Reload';
            app.StateChangeSwitch = uiswitch(app.VIStudio_AnomalyDetectionUIFigure, 'slider');
            app.StateChangeSwitch.Items = {'Training', 'Inference'};
            app.StateChangeSwitch.ValueChangedFcn = createCallbackFcn(app, @StateChangeSwitchValueChanged, true);
            app.StateChangeSwitch.Value = 'Training';
            app.ModelDropDownLabel = uilabel(app.VIStudio_AnomalyDetectionUIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.VIStudio_AnomalyDetectionUIFigure);
            app.ModelDropDown.Items = {'FCDD', 'PatchCore_resnet18', 'PatchCore_resnet50', 'EfficientAD_small', 'EfficientAD_medium', 'User Trained Model'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'FCDD';
            app.DataSourceEditFieldLabel = uilabel(app.VIStudio_AnomalyDetectionUIFigure);
            app.DataSourceEditFieldLabel.Text = 'Data Source';
            app.DataSourceEditField = uieditfield(app.VIStudio_AnomalyDetectionUIFigure, 'text');
            app.DataSourceEditField.Editable = 'off';
            app.ModelLoadedLampLabel = uilabel(app.VIStudio_AnomalyDetectionUIFigure);
            app.ModelLoadedLampLabel.Text = 'Model Loaded';
            app.ModelLoadedLamp = uilamp(app.VIStudio_AnomalyDetectionUIFigure);
            app.OnlyFCDDneedanomalytrainingdataLabel = uilabel(app.VIStudio_AnomalyDetectionUIFigure);
            app.OnlyFCDDneedanomalytrainingdataLabel.Text = 'Only FCDD need anomaly training data';
            % Show the figure after all components are created
            app.VIStudio_AnomalyDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_AnomalyDetectionApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VIStudio_AnomalyDetectionUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.VIStudio_AnomalyDetectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VIStudio_AnomalyDetectionUIFigure)
        end
    end
end