classdef ChkFileMiss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MoveToButton           matlab.ui.control.Button
        Report_Button          matlab.ui.control.Button
        TopColorBar            matlab.ui.container.Panel
        MissingNum_EditField   matlab.ui.control.NumericEditField
        MissingEditFieldLabel  matlab.ui.control.Label
        SessionDataLabel       matlab.ui.control.Label
        AnalyzeButton          matlab.ui.control.Button
        FileStatus_Table       matlab.ui.control.Table
    end


    properties (Access = public)
        mainapp % FATIVE_ImageLabeler.mlapp Object
        MissingIdx % Missing index from fileROIinfo
        LabeledIdx % Labeled image index from fileROIinfo
        currRow % Current selected row 
    end

    methods (Access = public)

        function missingTableUpdate(app)
            %% Check missing file in mainapp fileROIinfo filepath and generate analyze report table
            if ~isempty(app.mainapp.fileROIinfo)
                % Analyze missing file from fileROIinfo using mainapp
                % function: chkFileMissing
                labelStatus = false(height(app.mainapp.fileROIinfo),width(app.mainapp.fileROIinfo)-1);
                app.MissingIdx = app.mainapp.chkFileMissing(app.mainapp.fileROIinfo.Files);
                app.LabeledIdx = app.mainapp.chkFileLabeled;
                fileStatus = ~app.MissingIdx;
                labelStatus(app.LabeledIdx) = true;
                if isempty(app.mainapp.Label_list)
                    labelStatus = array2table(labelStatus);
                else
                    labelStatus = array2table(labelStatus,"VariableNames",app.mainapp.Label_list.Name);
                end
                [path,file,ext] = fileparts(app.mainapp.fileROIinfo.Files);
                fileName = append(file,ext);

                statusTable = [table(path,fileName,fileStatus,'VariableNames',{'Path','fileName','isExist'}),labelStatus];
                app.FileStatus_Table.Data = statusTable;
                %                 app.FileStatus_Table.ColumnWidth = {'2x','2x','1x','1x'};
                app.FileStatus_Table.ColumnName = statusTable.Properties.VariableNames;
                app.MissingNum_EditField.Value = sum(app.MissingIdx);

                % Highlight row with missing data in table
                removeStyle(app.FileStatus_Table);
                styleObj = uistyle('FontColor',[0.84	0.167	0.17],'FontWeight','bold');
                addStyle(app.FileStatus_Table,styleObj,'row',find(app.MissingIdx))
                if ~isempty(find(app.LabeledIdx))
                    styleObj_labeled = uistyle('BackgroundColor','green');
                    addStyle(app.FileStatus_Table,styleObj_labeled,'row',find(any(app.LabeledIdx,2)))
                end


            else
                InitialFcn(app)
            end
        end

        function generateReport(app)
            %% Generate missing file report from current session
            h = uiprogressdlg(app.UIFigure,'Title','Generate Report',...
                'Message','Report Generating...',...
                'Indeterminate','on');
            try
                timestamp = datetime("now");
                missingFile = app.mainapp.fileROIinfo.Files(app.MissingIdx);
                savename = append('chkReport_',datestr(timestamp,'yyyy-mm-dd'),'.txt');
                fid = fopen(savename,'a+'); % Append or create new file
                fprintf(fid,'%s %s\n','Check time:',datestr(timestamp,'yyyy/mm/dd HH:MM:SS'));
                fprintf(fid,'%s\n','Missing Files:');
                cellfun(@(x)fprintf(fid,'%s\n',x),missingFile); % Write missing file name is missingFile(cell list)
                fclose(fid);
                delete(h)
            catch ME
                delete(h)
                disp(ME.message)
            end

        end

        function InitialFcn(app)
            %% Initialize function
            app.FileStatus_Table.Data = [];
            app.MissingNum_EditField.Value = 0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.mainapp = mainapp; % Save FATIVE_ImageLabeler object
            missingTableUpdate(app)
            app.UIFigure.Name='Analyze Result'; %Version
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            missingTableUpdate(app)
        end

        % Button pushed function: Report_Button
        function Report_ButtonPushed(app, event)
            generateReport(app)
        end

        % Cell selection callback: FileStatus_Table
        function FileStatus_TableCellSelection(app, event)
            app.currRow = event.Indices(1);
            %% Preserved 
%             selectedFile = app.FileStatus_Table.Data.fileName(selectedRow);
%             fileIdx = find(contains(app.mainapp.fileROIinfo.Files,selectedFile));
%             % Check if selected file is missing
%             % else, do update main function with selected file
%             if ~app.MissingIdx(fileIdx)
%                 if fileIdx >= height(app.mainapp.fileROIinfo) - app.mainapp.panelNum
%                     app.mainapp.currfileIdx = height(app.mainapp.fileROIinfo)-app.mainapp.panelNum;
%                 else
%                     app.mainapp.currfileIdx = fileIdx;
%                 end
%                 % Update main app with selected image
%                 app.mainapp.currImgObj = app.mainapp.prepanelObj(1).Children;
%                 app.mainapp.currImgObj.UserData = strjoin(app.FileStatus_Table.Data{selectedRow,[1,2]},filesep);
%                 app.mainapp.previewImgUpdate
%                 idx = find(structfun(@(x)contains(x.UserData,selectedFile), app.mainapp.previewImgList));
%                 app.mainapp.currImgObj = app.mainapp.prepanelObj(idx).Children;
%                 app.mainapp.previewImgClicked(app.mainapp.currImgObj,event)
%                 % Scroll to relative location and trigger scroll listener
%                 filenum = length(app.mainapp.imgDs.Files);
%                 scrollbarLen = app.mainapp.previewScrollBar_HTML.Position(3) - (app.mainapp.previewScrollBar_Panel.Position(1) + app.mainapp.previewScrollBar_Panel.Position(3));
%                 posList = linspace(1,scrollbarLen,filenum-app.mainapp.panelNum);
%                 scroll(app.mainapp.previewScrollBar_Panel,[posList(app.mainapp.currfileIdx),1]);
%             end
        end

        % Button pushed function: MoveToButton
        function MoveToButtonPushed(app, event)
            selectedFile = app.FileStatus_Table.Data.fileName(app.currRow);
            fileIdx = find(contains(app.mainapp.fileROIinfo.Files,selectedFile));
            app.mainapp.preloadidx = 0;
            % Check if selected file is missing
            % else, do update main function with selected file
            if ~app.MissingIdx(fileIdx)
                if fileIdx >= height(app.mainapp.fileROIinfo) - app.mainapp.panelNum+1
                    app.mainapp.currfileIdx = height(app.mainapp.fileROIinfo)-app.mainapp.panelNum+1;
                else
                    app.mainapp.currfileIdx = fileIdx;
                end
                
                % Set user selected image to the middle
                moveIdx = app.mainapp.currfileIdx;
                dist2Img = 7; %TODO current number based-on 1920*1080 resolution 22-inch screen
                if moveIdx-dist2Img >0 && moveIdx+dist2Img<height(app.mainapp.fileROIinfo)-app.mainapp.panelNum
                    moveIdx = moveIdx - dist2Img;
                    app.mainapp.currfileIdx = moveIdx; % Update control index
                elseif moveIdx-dist2Img == 0
                    moveIdx = 1;
                    app.mainapp.currfileIdx = moveIdx; % Update control index
                end
                
                % Update main app with selected image
                app.mainapp.currImgObj = app.mainapp.prepanelObj(1).Children;
                app.mainapp.currImgObj.UserData = strjoin(app.FileStatus_Table.Data{app.currRow,[1,2]},filesep);
                app.mainapp.previewImgUpdate(moveIdx)
                idx = find(structfun(@(x)contains(x.UserData,selectedFile), app.mainapp.previewImgList));
                app.mainapp.currImgObj = app.mainapp.prepanelObj(idx).Children;
                app.mainapp.previewImgClicked(app.mainapp.currImgObj,event)
                % Scroll to relative location and trigger scroll listener
                filenum = length(app.mainapp.imgDs.Files);
                scrollbarLen = app.mainapp.previewScrollBar_HTML.Position(3) - (app.mainapp.previewScrollBar_Panel.Position(1) + app.mainapp.previewScrollBar_Panel.Position(3));
                app.mainapp.currEvnt = 'MoveToButtonPushed';
                posList = linspace(1,scrollbarLen,filenum-app.mainapp.panelNum+1);
                scroll(app.mainapp.previewScrollBar_Panel,[posList(moveIdx),1]);
                
                % If slider bar reach the left end, make sure preview image start from the first file
                % If slider bar reach the right end, make sure preview image end with the last file
                if moveIdx == 1 % ScrollableViewportLocation value is 1 at the begin (Left end)
                    app.mainapp.restorePanelFcn;
                elseif moveIdx+app.mainapp.panelNum>= filenum
                    poslist = app.mainapp.ImgPreview_Panel.Position(3) - app.mainapp.panelInterval*app.mainapp.panelNum:app.mainapp.panelInterval:app.mainapp.ImgPreview_Panel.Position(3)-app.mainapp.panelInterval;
                    app.mainapp.restorePanelFcn(poslist);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileStatus_Table = uitable(app.UIFigure);
            app.FileStatus_Table.ColumnName = {'Path'; 'File'; 'Status'; 'Labeled'};
            app.FileStatus_Table.RowName = {};
            app.FileStatus_Table.ColumnSortable = true;
            app.FileStatus_Table.CellSelectionCallback = createCallbackFcn(app, @FileStatus_TableCellSelection, true);
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.SessionDataLabel = uilabel(app.UIFigure);
            app.SessionDataLabel.Text = 'Session Data';
            app.MissingEditFieldLabel = uilabel(app.UIFigure);
            app.MissingEditFieldLabel.Text = 'Missing:';
            app.MissingNum_EditField = uieditfield(app.UIFigure, 'numeric');
            app.TopColorBar = uipanel(app.UIFigure);
            app.Report_Button = uibutton(app.UIFigure, 'push');
            app.Report_Button.ButtonPushedFcn = createCallbackFcn(app, @Report_ButtonPushed, true);
            app.Report_Button.Tooltip = {'Generate report for missing file'};
            app.Report_Button.Text = {'Report'; ''};
            app.MoveToButton = uibutton(app.UIFigure, 'push');
            app.MoveToButton.ButtonPushedFcn = createCallbackFcn(app, @MoveToButtonPushed, true);
            app.MoveToButton.Text = 'Move To';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChkFileMiss(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end