classdef ColorPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColorPickerUIFigure  matlab.ui.Figure
        CancelButton         matlab.ui.control.Button
        PreviewLabel         matlab.ui.control.Label
        OKButton             matlab.ui.control.Button
        BValue_EditField     matlab.ui.control.NumericEditField
        BEditFieldLabel      matlab.ui.control.Label
        GValue_EditField     matlab.ui.control.NumericEditField
        GEditFieldLabel      matlab.ui.control.Label
        RValue_EditField     matlab.ui.control.NumericEditField
        REditFieldLabel      matlab.ui.control.Label
        Button               matlab.ui.control.Button
        HTML                 matlab.ui.control.HTML
    end


    properties (Access = public)
        RGB = 0; % RGB value in ratio [0-1]
        LabelDefine % LabelDefine app object
    end

    methods (Access = private)

        function manualSetupRGB(app)
            R = app.RValue_EditField.Value;
            G = app.GValue_EditField.Value;
            B = app.BValue_EditField.Value;
            app.RGB = [R,G,B]/255;
            app.Button.BackgroundColor = app.RGB;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, LabelDefine)
            app.LabelDefine = LabelDefine;
            if ~isempty(app.LabelDefine.colorVec)
                app.RGB = app.LabelDefine.colorVec;
                app.RValue_EditField.Value = round(app.RGB(1)*255);
                app.GValue_EditField.Value = round(app.RGB(2)*255);
                app.BValue_EditField.Value = round(app.RGB(3)*255);
                app.Button.BackgroundColor = app.RGB;
            end
        end

        % Data changed function: HTML
        function HTMLDataChanged(app, event)
            data = app.HTML.Data;
            app.RGB = data'/255;
            app.Button.BackgroundColor = app.RGB;
            app.RValue_EditField.Value = data(1);
            app.GValue_EditField.Value = data(2);
            app.BValue_EditField.Value = data(3);
        end

        % Value changed function: RValue_EditField
        function RValue_EditFieldValueChanged(app, event)
            manualSetupRGB(app)
        end

        % Value changed function: GValue_EditField
        function GValue_EditFieldValueChanged(app, event)
            manualSetupRGB(app)
        end

        % Value changed function: BValue_EditField
        function BValue_EditFieldValueChanged(app, event)
            manualSetupRGB(app)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.LabelDefine.colorVec = app.RGB;
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.LabelDefine.colorVec = app.RGB;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColorPickerUIFigure = uifigure('Visible', 'off');
            app.ColorPickerUIFigure.Name = 'Color Picker';
            app.HTML = uihtml(app.ColorPickerUIFigure);
            app.HTML.HTMLSource = 'ColorPicker.html';
            app.HTML.DataChangedFcn = createCallbackFcn(app, @HTMLDataChanged, true);
            app.Button = uibutton(app.ColorPickerUIFigure, 'push');
            app.Button.Text = '';
            app.REditFieldLabel = uilabel(app.ColorPickerUIFigure);
            app.REditFieldLabel.Text = 'R';
            app.RValue_EditField = uieditfield(app.ColorPickerUIFigure, 'numeric');
            app.RValue_EditField.Limits = [0 255];
            app.RValue_EditField.ValueChangedFcn = createCallbackFcn(app, @RValue_EditFieldValueChanged, true);
            app.GEditFieldLabel = uilabel(app.ColorPickerUIFigure);
            app.GEditFieldLabel.Text = 'G';
            app.GValue_EditField = uieditfield(app.ColorPickerUIFigure, 'numeric');
            app.GValue_EditField.Limits = [0 255];
            app.GValue_EditField.ValueChangedFcn = createCallbackFcn(app, @GValue_EditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.ColorPickerUIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BValue_EditField = uieditfield(app.ColorPickerUIFigure, 'numeric');
            app.BValue_EditField.Limits = [0 255];
            app.BValue_EditField.ValueChangedFcn = createCallbackFcn(app, @BValue_EditFieldValueChanged, true);
            app.OKButton = uibutton(app.ColorPickerUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PreviewLabel = uilabel(app.ColorPickerUIFigure);
            app.PreviewLabel.Text = 'Preview';
            app.CancelButton = uibutton(app.ColorPickerUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ColorPickerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorPicker(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ColorPickerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ColorPickerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColorPickerUIFigure)
        end
    end
end