classdef LabelDefine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DescriptionOptionalLabel  matlab.ui.control.Label
        ColorLabel                matlab.ui.control.Label
        ColorSelector_Button      matlab.ui.control.Button
        TopColorBar               matlab.ui.container.Panel
        Cancel_Button             matlab.ui.control.Button
        OK_Button                 matlab.ui.control.Button
        Description_TextArea      matlab.ui.control.TextArea
        ROIType_DropDown          matlab.ui.control.DropDown
        LabelName                 matlab.ui.control.Label
        LabelName_EditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        mainapp % Labeler Main App Object
        colorSelectorObj % Color Selector Object
        LabelInfo = struct('Name',[],'Type',[],'Color',[],'Description',[]);
        isRename = false;
        previousName
        colorVec
    end
    
    methods (Access = private)
        
        function LabelCreate(app)
            % Label struct create
            app.LabelInfo.Name = string(app.LabelName_EditField.Value);
            app.LabelInfo.Type = app.ROIType_DropDown.Value;
            app.LabelInfo.Color = app.ColorSelector_Button.BackgroundColor;
            app.LabelInfo.Description = app.Description_TextArea.Value;
        end
        
        function AddLabelDefinition(app)
            app.mainapp.Label_list = [app.mainapp.Label_list;struct2table(app.LabelInfo)];
            app.mainapp.roiAddLabel;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            %% Mainapp Assign
            app.mainapp = mainapp;
            app.UIFigure.Name='Label Editor'; %Version
        end

        % Button pushed function: OK_Button
        function OK_ButtonPushed(app, event)
            %% Check if label name is valid
            chk_LabelName = app.LabelInfo.Name;
            chk_ColorRGB = app.LabelInfo.Color;
            chklist = get(app.mainapp.LabelListTree.Children,'Text');
            % Check if label name is empty
            % Check if label name already exist, is valid, ia keyword
            if isempty(chk_LabelName) || ~isvarname(chk_LabelName) || iskeyword(chk_LabelName)
                uialert(app.UIFigure,'You must specify a valid MATLAB variable name','Invalid Name')
                return
            elseif any(strcmp(chk_LabelName,chklist)) && ~app.isRename
                uialert(app.UIFigure,'Label name duplicated','Invalid Name')
                return
            end
            
            if ~app.isRename
                AddLabelDefinition(app)
                app.mainapp.LabelListUpdate(app)
            else
                % Locate label to rename
                % Rename "fileROIinfo" table's variable name (If exist)
                % Update status and mainapp treenode
                if ~isempty(app.mainapp.fileROIinfo)
                    repIdx = find(strcmp(app.mainapp.fileROIinfo.Properties.VariableNames,app.previousName));
                    app.mainapp.fileROIinfo.Properties.VariableNames(repIdx) = chk_LabelName;
                end
                matchIdx = strcmp(app.mainapp.Label_list.Name,app.previousName);
                app.mainapp.Label_list{matchIdx,'Name'} = chk_LabelName;
                app.mainapp.Label_list{matchIdx,'Color'} = chk_ColorRGB;
                app.isRename = false;
                app.mainapp.selectedLabel.Text = chk_LabelName;
                app.mainapp.selectedLabel.NodeData.Name = chk_LabelName;
                app.mainapp.selectedLabel.NodeData.Color = chk_ColorRGB;
                app.mainapp.selectedLabel.Icon = app.mainapp.createColorImg(chk_ColorRGB);
            end
            Cancel_ButtonPushed(app, event)
        end

        % Button pushed function: Cancel_Button
        function Cancel_ButtonPushed(app, event)
            % GUI close
            delete(app)
        end

        % Button pushed function: ColorSelector_Button
        function ColorSelector_ButtonPushed(app, event)
            %% ROI color selector
            % Open color select UI
            app.colorSelectorObj = ColorPicker(app);
            waitfor(app.colorSelectorObj)
            ROI_Color = app.colorVec;
            
            % Display selected color & update label information
            if size(ROI_Color,2) > 1 % If any color be chose, size greater than 1
                app.ColorSelector_Button.BackgroundColor = ROI_Color;
                LabelCreate(app)
                app.mainapp.Bring2Front
            end
        end

        % Value changed function: LabelName_EditField
        function LabelName_EditFieldValueChanged(app, event)
            % Update label information
            LabelCreate(app)
        end

        % Value changed function: Description_TextArea
        function Description_TextAreaValueChanged(app, event)
            % Update label information
            LabelCreate(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.colorSelectorObj)
                delete(app.colorSelectorObj);
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LabelName_EditField = uieditfield(app.UIFigure, 'text');
            app.LabelName_EditField.ValueChangedFcn = createCallbackFcn(app, @LabelName_EditFieldValueChanged, true);
            app.LabelName = uilabel(app.UIFigure);
            app.LabelName.Text = 'Label Name';
            app.ROIType_DropDown = uidropdown(app.UIFigure);
            app.ROIType_DropDown.Items = {'Rectangle', ''};
            app.ROIType_DropDown.Value = 'Rectangle';
            app.Description_TextArea = uitextarea(app.UIFigure);
            app.Description_TextArea.ValueChangedFcn = createCallbackFcn(app, @Description_TextAreaValueChanged, true);
            app.OK_Button = uibutton(app.UIFigure, 'push');
            app.OK_Button.ButtonPushedFcn = createCallbackFcn(app, @OK_ButtonPushed, true);
            app.OK_Button.Text = 'OK';
            app.Cancel_Button = uibutton(app.UIFigure, 'push');
            app.Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Cancel_ButtonPushed, true);
            app.Cancel_Button.Text = {'Cancel'; ''};
            app.TopColorBar = uipanel(app.UIFigure);
            app.ColorSelector_Button = uibutton(app.UIFigure, 'push');
            app.ColorSelector_Button.ButtonPushedFcn = createCallbackFcn(app, @ColorSelector_ButtonPushed, true);
            app.ColorSelector_Button.Text = '';
            app.ColorLabel = uilabel(app.UIFigure);
            app.ColorLabel.Text = {'Color'; ''};
            app.DescriptionOptionalLabel = uilabel(app.UIFigure);
            app.DescriptionOptionalLabel.Text = {'Description (Optional)'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabelDefine(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end