classdef VIStudio_ImageLabeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        MainLabel_Panel         matlab.ui.container.Panel
        CurrentFile_Label       matlab.ui.control.Label
        previewScrollBar_Panel  matlab.ui.container.Panel
        previewScrollBar_HTML   matlab.ui.control.HTML
        Label_Panel             matlab.ui.container.Panel
        Delete_Button           matlab.ui.control.Button
        Add_Button              matlab.ui.control.Button
        LabelListTree           matlab.ui.container.Tree
        MainViewArea_Panel      matlab.ui.container.Panel
        UIAxesContain_Panel     matlab.ui.container.Panel
        ZoomOutButton           matlab.ui.control.Button
        DrawStat_Panel          matlab.ui.container.Panel
        DrawOntocreateROILabel  matlab.ui.control.Label
        AutoZoomSwitchCheckBox  matlab.ui.control.CheckBox
        DrawStatSwitch_HTML     matlab.ui.control.HTML
        DrawLabel               matlab.ui.control.Label
        CurrentFileIndex_Label  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
        ImgPreview_Panel        matlab.ui.container.Panel
        BottomColorBar_Panel    matlab.ui.container.Panel
        TopColorBar_Panel       matlab.ui.container.Panel
        ToolBar_Panel           matlab.ui.container.Panel
        ImportSession_Button    matlab.ui.control.Button
        Export_Button           matlab.ui.control.Button
        ImportLabel_Button      matlab.ui.control.Button
        ImportFile_Button       matlab.ui.control.Button
        ImportFolder_Button     matlab.ui.control.Button
        AnalyzeButton           matlab.ui.control.Button
    end


    properties (Access = public)

        pre_folderpath %previous filder path

        isInbound % Check if user pointer is in drawing area
        DoDraw = false; % Drawing status
        initial_load = false; % Is any image loaded
        UserDrawing = true; % Is user drawing roi

        %% Subapp Object
        LabelDefineUIObj % LabelDefine.mlapp object
        AnalyzeFileUIObj % ChkFileMiss.mlapp object

        %% Shared Data for Datasource or Grouped Object
        imgDs % ImageDatastore  Object
        previewImgList % Uiimage object to store preview image in struct
        previewPanelList % Uipanel object to hold preview uiimage object in struct
        fileROIinfo % File and ROI position information table (Output Format)
        roi_list % Stored ROI list
        imgSourcePath

        %% Label List Tree
        Label_list % List for label information
        selectedLabel % Current selected label from label list tree

        %% Img Preview Panel Configuration
        panelInterval = 90; % Interval between preview panel object
        panelNum = 20; % Preview panel number to generate
        prepanelObj % Preview panel object list
        x_initial % Preview panel object initial x position
        scrollTimes = 0; % Preview panel scrollbar scroll times count

        %% Current Drawing Image
        currfilepath % Current image file path
        currfileIdx = 1; % Control preview image behavior
        currImgsize % Current image size
        currImgObj % Current preview image panel object

        %% Icon
        CorruptedImg % Pre-loaded image for replace missing image

        %% Testing
        currEvnt
        preimgObj
        preloadimg
        preloadidx = 0;
        zoomObj
        LineX
        LineY
    end

    methods (Access = public)
        function InitializeFcn(app)


            app.isInbound = [];
            app.DoDraw = false;
            app.initial_load = false;
            app.UserDrawing = true;
            app.DrawStatSwitch_HTML.Data = false;

            %% Subapp Object
            app.LabelDefineUIObj = [];

            %% Shared Data for Datasource or Grouped Object
            app.imgDs = [];
            app.fileROIinfo = [];
            app.roi_list = [];
            app.imgSourcePath = [];

            %% Label List Tree
            app.Label_list = [];
            app.selectedLabel = [];

            %% Img Preview Panel Configuration
            app.panelInterval = 90;
            app.panelNum = 20;
            app.prepanelObj = [];
            app.x_initial = [];
            app.scrollTimes = 0;

            %% Current Drawing Image
            app.currfilepath = [];
            app.currfileIdx = 1;
            app.currImgsize = [];

            %% Delete all label in list
            delete(app.LabelListTree.Children)

            %% Initialize scrollbar location
            app.previewScrollBar_Panel.ScrollableViewportLocation(1) = 1;


        end

        function inboundTest(app,obj,evnt)
            %% Check if pointer is in valid drawing area and check if draw switch in enable
            %% If condition satisfied, enable user to draw ROI continuously and store ROI object
            %% Disable "DoDraw" status before "startDraw" function
            try
                ax = findobj(obj.Children,'Type','axes');
                currpts = get(ax,'CurrentPoint');
                [imgsizeY,imgsizeX] = size(findobj(ax.Children,'Type','image').CData,[1,2]); % Get current drawing area image size
                % Update crossline with user current points
                app.LineX.Value = currpts(1,1);
                app.LineY.Value = currpts(1,2);
                % Update color with selected label
                if ~isempty(app.selectedLabel)
                    LabelColor = squeeze(app.selectedLabel.Icon(1,1,:))';
                    if ~all((LabelColor == app.LineX.Color)&(LabelColor == app.LineY.Color))
                        app.LineX.Color = LabelColor;
                        app.LineY.Color = LabelColor;
                        disp('line update')
                    end
                end

                % If user is drawing, ignore pointer outbound
                if currpts(1,1) >0 && currpts(1,1)<imgsizeX && currpts(1,2) >0 && currpts(1,2)<imgsizeY
                    %                 disp('inbound')
                    app.isInbound = true;

                    % Check "DoDraw" status and DrawSwitch status
                    % (DoDraw might not be synchronizing with DrawSwitch status due to ROI drawing process)
                    if app.DoDraw && app.DrawStatSwitch_HTML.Data
                        app.DoDraw = false;
                        disp('start draw')
                        startDraw(app)
                    end
                    % If user is not drawing (mouse release) and is outbound
                    % stop drawing process and update inbound status
                elseif ~app.UserDrawing && (currpts(1,1) <0 || currpts(1,1)>imgsizeX || currpts(1,2) <0 || currpts(1,2)>imgsizeY )
                    %                 disp('outbound')
                    uiresume(app.UIFigure)
                    app.isInbound = false;
                    return
                end
            catch ME
                % Error information stored in variable ME
                % If no image loaded, do nothing
            end
        end


        function startDraw(app)
            %% Create ROI object and start draw based on the selected label
            %% Enable "DoDraw" status after user finish drawing
            LabelName  = app.selectedLabel.Text;
            LabelColor = squeeze(app.selectedLabel.Icon(1,1,:))';
            % Restrict drawing area with current image size
            roi = drawrectangle(app.UIAxes,...
                'Label',LabelName,...
                'LabelVisible','hover',...
                'Color',LabelColor,...
                'FaceAlpha',0,...
                'SelectedColor',[1,1,0],...
                'DrawingArea',[1 1 app.currImgsize-1]);
            roiListenerAdd(app,roi)
            if isempty(roi.Position) || (roi.Position(3)==0 && roi.Position(4)==0)
                delete(roi)
                disp('Empty ROI')
                app.DoDraw = true;
            else
                roiTemplate(app,roi) % TODO
                roiUpdate(app)
                app.DoDraw = true;
            end

        end

        function isDrawing(app,obj,evnt)
            %% Detect if user's mouse press
            switch evnt.EventName
                case 'WindowMousePress'
                    disp('Is Drawing')
                    app.UserDrawing = true;
                    %zoom(app.UIAxes,'off');
                    
                    % Restore ROI state to unselected
                    roiObj = findobj(app.UIAxes.Children,'Type','images.roi.Rectangle');
                    if ~isempty(roiObj)
                        set(roiObj,{'Selected'},{false})
                    end
                    
                case 'WindowMouseRelease'
                    disp('Stop Drawing')
                    app.UserDrawing = false;
                    %zoom(app.UIAxes,'on');

            end
        end

        function stopDraw(app,obj,evnt)
        end

        function roiTemplate(app,roiObj)
            %% ROI inforamtion storage
            ROIinfo.Position = roiObj.Position;
            ROIinfo.Vertices = roiObj.Vertices;
            ROIinfo.Color = roiObj.Color;
            ROIinfo.Label = roiObj.Label;
            ROIinfo.file  = app.currfilepath;
            app.roi_list = [app.roi_list;ROIinfo];

        end


        function emptyROIchk(app)
            %% Check if ROI object has invalid bound after user draw, delete from ROI list
            validROI = isvalid(app.roi_list);
            app.roi_list = app.roi_list(validROI);
        end

        function roiListenerAdd(app,roi)
            %% Configurate ROI event listener
            addlistener(roi,'ROIMoved',@app.roiEvent);
            addlistener(roi,'DeletingROI',@app.roiEvent);

        end

        function roiEvent(app,src,event)
            %% Testing ROI event
            eventName = event.EventName;
            switch eventName
                case 'ROIMoved'
                    disp('moved')
                    disp(src.Position)
                    roiUpdate(app)
                case 'DrawingFinished'
                    disp('Draw Done!')
                case 'DeletingROI'
                    disp('Delete!')
                    delete(src)
                    roiUpdate(app)
            end
        end


        function previewSetup(app)
            %% Create preview panel and image object, Setup image from datastore
            %% Create preview panel object group from preview panel list structure
            %% Create and store preview panel x initial position
            PreviewPanelRange = app.ImgPreview_Panel.Position(3:4);
            PPR_width = PreviewPanelRange(1);
            PPR_height = PreviewPanelRange(2);
            panel_y = (PPR_height - 100)/2;

            % Create name list for preview panel object and preview image object
            if isempty(app.previewImgList)&&isempty(app.previewPanelList)
                namelist_panel = append('PreviewPanel',string(1:app.panelNum));
                namelist_img = append('Image',string(1:app.panelNum));

                for i = 1:app.panelNum
                    app.previewPanelList.(namelist_panel(i)) = uipanel(app.ImgPreview_Panel,...
                        'Position',[1+app.panelInterval*(i-1),panel_y,app.panelInterval,(PPR_height-panel_y*2)],...
                        'BorderType','none', ...
                        'Title','',...
                        'BackgroundColor','w',...
                        'Tag',namelist_panel(i));
                    app.previewImgList.(namelist_img(i)) = uiimage(app.previewPanelList.(namelist_panel(i)),...
                        'Position',[1 panel_y app.panelInterval 80],...  %% preview image size
                        'ImageClickedFcn',@app.previewImgClicked,...
                        'Tag',namelist_img(i),...
                        'ContextMenu',app.preImgContxtMenu);
                end
            end
            app.prepanelObj = app.struct2array(app.previewPanelList);
            app.preimgObj = app.struct2array(app.previewImgList);
            app.x_initial = 1:app.panelInterval:app.panelInterval*app.panelNum;
            previewImgUpdate(app)

        end


        function previewImgClicked(app,obj,evnt)
            %% Change background color for selected preview image and show it on the main axes
            %% Restore default background color for other non-selected preview image
            %% Show current selected image file name
            %% Restore exist image ROI
            delete(findall(app.UIAxes.Children,'-not','Type','Image'));
            zoom(app.UIFigure,'out');
            %             resetplotview(app.UIAxes,'ApplyStoredView');
            obj.Parent.BackgroundColor = [0.69	0.95 1]; % RGB value
            selectedCheck(app,obj.Parent)
            imgObj = findall(app.UIAxes.Children,'Type','Image');
            imgObj.CData = obj.ImageSource;
            [~,name,ext] = fileparts(obj.UserData);
            app.CurrentFile_Label.Text = strcat(name,ext);
            app.currfilepath = obj.UserData;
            app.currImgObj = obj;
            app.currImgsize = size(obj.ImageSource,[1,2]);
            app.UIAxes.XLim(2) = size(obj.ImageSource,2);
            app.UIAxes.YLim(2) = size(obj.ImageSource,1);
            roiRestore(app)
            CurrentFileIdxUpdate(app)
            createCrossLine(app) % Recreate cross line after axes update with new image
        end

        function previewImgUpdate(app,moveIdx)
            %% Update current preview image based on range(slider position)
            % TODO
            % Will be optimized in future release
            % Check function input argument numbers
            switch nargin

                case 1 % User does not use "move to" button
                    range = app.currfileIdx;

                case 2 % User use "move to" button
                    range = moveIdx;


            end

            %             fprintf('preImgUpdate currfileIdx = %f\n',range)

            % ======================= Algo POC(preserved) =======================
            %             if app.currfileIdx >= max(app.preloadidx)-app.panelNum || app.currfileIdx <= min(app.preloadidx)+app.panelNum
            %                 disp('Do Load!!!')
            %                 matchIdx = app.currfileIdx;
            %             preloadnum = app.panelNum+20;
            %             filenum = length(app.imgDs.Files);
            %                 if matchIdx - preloadnum >0 && matchIdx + preloadnum <= filenum
            %                     app.preloadimg = subset(app.imgDs,(matchIdx-preloadnum):(matchIdx+preloadnum));
            %                     app.preloadidx = (matchIdx - preloadnum):(matchIdx+preloadnum);
            %                 elseif matchIdx - preloadnum <=0
            %                     app.preloadimg = subset(app.imgDs,1:(matchIdx+preloadnum));
            %                     app.preloadidx = 1:(matchIdx+preloadnum);
            %                 else
            %                     app.preloadimg = subset(app.imgDs,matchIdx:filenum);
            %                     app.preloadidx = (matchIdx - preloadnum):filenum;
            %                 end
            %                 app.preloadimg = readall(app.preloadimg);
            %             end
            % ======================= Algo POC(preserved) =======================

            fileNum = range + app.panelNum;

            if fileNum > length(app.imgDs.Files) % Data approach to last image
                idxList = length(app.imgDs.Files) - app.panelNum +1:length(app.imgDs.Files);
            else % Normal behavior
                idxList = range:range + app.panelNum-1;
            end 

            %% If loaded image less than preview image container
            if length(app.imgDs.Files) < app.panelNum
                idxMax = length(app.imgDs.Files);
                idxList = 1:idxMax;
            else
                idxMax = app.panelNum;
            end

            %% Check labeled image in current preview panel
            % Initialize all preview panel with empty title
            totalabeledList = chkFileLabeled(app);
            totalabeledList = find(any(totalabeledList,2));
            labeledIdx = find(ismember(idxList,totalabeledList));
            set(app.prepanelObj,'Title','')

            %% Subset image datastore
            imgDs_sub = subset(app.imgDs,idxList);

            try
                imgList = readall(imgDs_sub);
            catch errMsg
                MissingIdx = chkFileMissing(app,imgDs_sub.Files);
                imgList(find(MissingIdx)) = {app.CorruptedImg};
                newsub = subset(imgDs_sub,find(~MissingIdx));
                imgList(find(~MissingIdx)) = readall(newsub);
                imgList = imgList';
            end

            %% Check import image length, if less than preview panel number disable empty panel

            if length(idxList) < app.panelNum
                set(app.preimgObj(idxList),{'ImageSource'},imgList);
                set(app.preimgObj(idxList),{'UserData'},imgDs_sub.Files);
                emptyIdx = length(idxList)+1;
                set(app.preimgObj(emptyIdx:end),'Enable',false)
            else
                set(app.preimgObj,{'ImageSource'},imgList);
                set(app.preimgObj,{'UserData'},imgDs_sub.Files);
            end

            % ======================= Algo POC(preserved) =======================
            %             tic
            %             for i = 1:idxMax
            %                 filepath = app.imgDs.Files(idxList(i));
            %                 try
            %                     info = imfinfo(app.imgDs.Files{idxList(i)});
            %                     [img,map] = imread(app.imgDs.Files{idxList(i)});
            %                     if strcmp(info.ColorType,'indexed')
            %                         img = ind2rgb(img,map);
            %                     end
            %                 catch ErrMsg
            %                     % If anything wrong with update image, replace by
            %                     % pre-loaded missing image
            %                     img = app.CorruptedImg;
            %                 end
            %                 app.previewImgList.(['Image',num2str(i)]).ImageSource = img;
            %                 app.previewImgList.(['Image',num2str(i)]).UserData = filepath;
            %             end
            %             oldItme = toc
            % ======================= Algo POC(preserved) =======================

            set(app.prepanelObj(labeledIdx),'Title','Labeled')

        end

        function selectedCheck(app,selectedObj)
            %% Check non-selected preview image and set background color as default (white)
            tagList = get(app.prepanelObj,'Tag');
            unSelected =  ~strcmp(selectedObj.Tag,tagList );
            set(app.prepanelObj(unSelected),'BackgroundColor' , 'w');
        end


        function previewScrollBarSetup(app)
            %% Expand uihtml to create preview panel scrollbar
            %% Setup preview panel scrollbar callback
            ratio = length(app.imgDs.Files)/app.panelNum;
            app.previewScrollBar_HTML.Position(3) = app.previewScrollBar_Panel.Position(3)*ratio;
            app.previewScrollBar_Panel.ScrollableViewportLocationChangingFcn= @app.scrollbarChanging;
            app.previewScrollBar_Panel.ScrollableViewportLocationChangedFcn= @app.scrollbarChanged;
        end


        function scrollbarChanged(app,obj,evnt)
            %% Setup scrolling ratio and scrolling behavior after scrolling position changed
            %% Restore and adjust panel position when scrollbar reach to head or last
            % Activate if user scroll manually
            if ~strcmp(app.currEvnt,'KeyPress') && ~strcmp(app.currEvnt,'MoveToButtonPushed')
                filenum = length(app.imgDs.Files);
                scrollbarLen = app.previewScrollBar_HTML.Position(3) - (app.previewScrollBar_Panel.Position(1) + app.previewScrollBar_Panel.Position(3));
                % TODO
                % -- Prevent no scrollbar exist --
                if scrollbarLen < 0 
                    return 
                end
                posList = linspace(1,scrollbarLen,filenum-app.panelNum+1);
                [~,matchIdx] = min(abs(posList - obj.ScrollableViewportLocation(1)));
                obj.ScrollableViewportLocation(1) = posList(matchIdx);
                app.currfileIdx = matchIdx;


                if app.currfileIdx ~= matchIdx
                    % TODO
                    % Unknow behavior occured
                    % Force return to avoid duplicate unexpected behavior
                    disp('Unmatch')
                end
                %                 fprintf('scrollbar changed currfileIdx = %f\n',app.currfileIdx)
                readlist = app.currfileIdx:app.currfileIdx+app.panelNum;

                % If slider bar reach the left end, make sure preview image start from the first file
                % If slider bar reach the right end, make sure preview image end with the last file
                if obj.ScrollableViewportLocation(1) == 1 % ScrollableViewportLocation value is 1 at the begin (Left end)
                    restorePanelFcn(app);
                elseif readlist(end)>= filenum
                    pos_list = app.ImgPreview_Panel.Position(3) - app.panelInterval*app.panelNum:app.panelInterval:app.ImgPreview_Panel.Position(3)-app.panelInterval;
                    restorePanelFcn(app,pos_list);
                end

                previewImgUpdate(app)
                previewImgClicked(app,app.currImgObj,evnt)
            end

        end


        function scrollbarChanging(app,obj,evnt)
            %% Setup scrolling behavior while user scrolling
            %% Scroll times counting and format preview panel background color
            if ~isempty(app.currEvnt)
                app.currEvnt = [];
            end
            app.scrollTimes = app.scrollTimes + 1;
            over10 = app.scrollTimes>10;
            PanelBehaviorFcn(app,over10);
            restoreImgFcn(app,app.preimgObj);
            set(app.prepanelObj,'BackgroundColor','w')
        end


        function PanelBehaviorFcn(app,over10)
            %% Panel behavior setting, scroll times counting
            %% Shifting panel due to scrolling
            % If scroll times over 10, restore panel position to initial
            if over10
                restorePanelFcn(app);
                app.scrollTimes = 0;
            else
                % Apply BehaviorFcn to all preview image panel object
                arrayfun(@BehaviorFcn,app.prepanelObj);
            end
            function BehaviorFcn(obj)
                % Reduce object position for 10 pixels (To left)
                obj.Position(1) = obj.Position(1)-10;
            end
        end


        function restorePanelFcn(app,pos_list)
            %% Restore preview panel group to initial position or last position
            switch nargin
                case 1
                    % Initialize preview panel positon
                    arrayfun(@(x,y)restoreFcn(x,y),app.prepanelObj,app.x_initial,'UniformOutput',false);
                case 2
                    % Set preview panel to given position
                    arrayfun(@(x,y)restoreFcn(x,y),app.prepanelObj,pos_list,'UniformOutput',false);
            end
            function restoreFcn(obj,init_pos)
                obj.Position(1) = init_pos;
            end
        end


        function restoreImgFcn(app,obj)
            %% Discharge preview image during scrolling
            set(obj,'ImageSource','');
        end

        function roiRestore(app,obj)
            %% Restore exist ROI to selected image
            if ~isempty(app.fileROIinfo)
                currFile = strcmp(app.currfilepath,app.fileROIinfo.imageFilename); % Get current file index in fileROIinfo table
                LabelName = app.fileROIinfo.Properties.VariableNames(2:end);
                % Check through valid label, extract position and color information
                for i  = 1:length(LabelName)
                    Pos = cell2mat(app.fileROIinfo{currFile,LabelName(i)});
                    Color = app.Label_list{strcmp(app.Label_list.Name,LabelName(i)),'Color'};
                    % Check through position array and recreate ROI object on current image
                    for j = 1:size(Pos,1)
                        roi = images.roi.Rectangle('Parent',app.UIAxes,...
                            'Position',Pos(j,:),...
                            'Color',Color,...
                            'FaceAlpha',0,...
                            'Label',LabelName{i},...
                            'LabelVisible','hover',...
                            'SelectedColor',[1,1,0],...
                            'DrawingArea',[1 1 app.currImgsize-1]);
                        roiListenerAdd(app,roi)
                    end
                end
            end
        end

        function HotKeyFcn(app,src,evnt)
            %% Assign hotkey
            switch evnt.Key
                case 'shift' %f2
                    % Hotkey for switch drawing status
                    app.DrawStatSwitch_HTML.Data = ~(app.DrawStatSwitch_HTML.Data);
                    app.DrawStatSwitch_HTMLDataChanged
                    uiresume(app.UIFigure)
                    if app.DrawStatSwitch_HTML.Data
                        zoom(app.UIFigure,'off')
                    end
                    %                     disp(app.DoDraw)

                case 'leftarrow'
                    disp('Press Left')
                    app.currEvnt = 'KeyPress';
                    filenum = length(app.imgDs.Files);
                    scrollbarLen = app.previewScrollBar_HTML.Position(3) - (app.previewScrollBar_Panel.Position(1) + app.previewScrollBar_Panel.Position(3));
                    posList = linspace(1,scrollbarLen,filenum-app.panelNum+1);
                    try

                        if (app.currfileIdx-1 > 0)
                            zoom(app.UIFigure,'out')
                            %                             zoom(app.UIFigure,'off')
                            app.currfileIdx = app.currfileIdx-1;
                            scroll(app.previewScrollBar_Panel,[posList(app.currfileIdx),0]);
                            app.previewImgUpdate;
                            app.previewImgClicked(app.currImgObj,evnt);
                            fprintf('leftarrow press currfileIdx = %f\n',app.currfileIdx)

                        elseif (app.currfileIdx < app.panelNum) && (find(strcmp(app.fileROIinfo.imageFilename,app.currImgObj.UserData))>1)
                            app.currImgObj = app.prepanelObj(find(app.prepanelObj == app.currImgObj.Parent)-1).Children;
                            previewImgClicked(app,app.currImgObj,evnt)
                            previewImgUpdate(app)
                            fprintf('leftarrow press currfileIdx = %f\n',app.currfileIdx)

                        end
                        app.DoDraw = true;
                    catch ME
                        disp(ME.message)
                    end


                case 'rightarrow'
                    disp('Press Right')
                    app.currEvnt = 'KeyPress';
                    filenum = length(app.imgDs.Files);
                    scrollbarLen = app.previewScrollBar_HTML.Position(3) - (app.previewScrollBar_Panel.Position(1) + app.previewScrollBar_Panel.Position(3));
                    posList = linspace(1,scrollbarLen,filenum-app.panelNum+1);
                    try
                        if (app.currfileIdx <= length(app.imgDs.Files)-app.panelNum)
                            zoom(app.UIFigure,'out')
                            %                             zoom(app.UIFigure,'off')
                            app.currfileIdx = app.currfileIdx+1;
                            scroll(app.previewScrollBar_Panel,posList(app.currfileIdx),0);
                            fprintf('rightarrow press currfileIdx = %f\n',app.currfileIdx)
                            previewImgUpdate(app);
                            previewImgClicked(app,app.currImgObj,evnt)

                        elseif app.currfileIdx >= length(app.imgDs.Files)-app.panelNum && find(strcmp(app.fileROIinfo.imageFilename,app.currImgObj.UserData))<length(app.fileROIinfo.imageFilename)
                            app.currImgObj = app.prepanelObj(find(app.prepanelObj == app.currImgObj.Parent)+1).Children;
                            previewImgClicked(app,app.currImgObj,evnt)
                            previewImgUpdate(app)
                            fprintf('rightarrow press currfileIdx = %f\n',app.currfileIdx)

                        end
                        app.DoDraw = true;
                    catch ME
                        disp(ME.message)
                    end

                case 'delete'
                    % Delete selected roi and update roi infomation
                    roiObj = findall(app.UIAxes.Children,'Type','images.roi.Rectangle');
                    roiSelected = [roiObj.Selected];
                    delete(roiObj(roiSelected));
                    roiUpdate(app)
            end
        end

        function updateTreeROINum(app)
            %% Update ROI number for each label in Label treenode
            for i = 1:height(app.Label_list) % Run through each label in label list
                LabelName = app.Label_list.Name(i);
                parentNode = findobj(app.LabelListTree.Children,'Text',LabelName);
                ROInum = sum(cellfun(@(x)size(x,1),app.fileROIinfo.(LabelName)));
                if isempty(parentNode.Children) % If is empty create new treenode object
                    numNode = uitreenode('Parent',parentNode,'Text',sprintf('ROI Num: %.0f\n',ROInum),'Tag','numTreeNode');
                    parentNode.expand;
                else % Else update text in treenode only
                    numNode = parentNode.Children;
                    numNode.Text = sprintf('ROI Num: %.0f\n',ROInum);
                end

            end
        end

        function DrawBlocknSync(app,isdone)
            % If is DoDraw, block until process done
            if app.DoDraw
                app.DoDraw = false;
            end
            % Synchronize DoDraw value wiht draw switch status
            switch nargin
                case 2
                    app.DoDraw = app.DrawStatSwitch_HTML.Data;
            end
        end

        function CurrentFileIdxUpdate(app)
            %% Update current file number tag
            Index = find(strcmp(app.currfilepath,app.fileROIinfo.imageFilename));
            totalFileNum = length(app.fileROIinfo.imageFilename);
            dispTag = sprintf('Current File: %.0f/%.0f\n',Index,totalFileNum);
            app.CurrentFileIndex_Label.Text = dispTag;
        end


        function  t = EnableHotkey(app,obj,evnt)
            %% Enable hotkey during zoom on/off
            hManager = uigetmodemanager(app.UIFigure);
            [hManager.WindowListenerHandles.Enabled] = deal(false);
            set(app.UIFigure, 'WindowKeyPressFcn', []);
            set(app.UIFigure, 'KeyPressFcn', @app.HotKeyFcn);
            t = true;
        end
    end

    methods (Access = public)
        %% Public function between Labeler mainapp and Label create subapp
        function LabelListUpdate(app,subapp)
            switch nargin
                case 1
                    % Label import from saved session or label
                    delete(app.LabelListTree.Children);
                    for i = 1:height(app.Label_list)
                        labelObj = table2struct(app.Label_list(i,:));
                        Label_Name = labelObj.Name;
                        color_tag = app.createColorImg(labelObj.Color);
                        node = uitreenode('Parent',app.LabelListTree,'Text',Label_Name,'Icon',color_tag,'NodeData',labelObj,'ContextMenu',app.LabelContxtMenu);
                    end
                    app.LabelListTree.SelectedNodes = app.LabelListTree.Children(1);
                    app.selectedLabel = app.LabelListTree.Children(1);
                case 2
                    % Label create and update from subapp(LabelDefine.mlapp)
                    labelObj = subapp.LabelInfo;
                    Label_Name  = labelObj.Name;
                    color_tag = app.createColorImg(labelObj.Color);
                    node = uitreenode('Parent',app.LabelListTree,'Text',Label_Name,'Icon',color_tag,'NodeData',labelObj,'ContextMenu',app.LabelContxtMenu);
                    app.LabelListTree.SelectedNodes = node;
                    app.selectedLabel = node;
            end
        end

        function color_tag = createColorImg(app,RGB)
            % Create pure color img for label tag from LabelInfo
            color_tag = ones(16,16,3); % 16x16 pixels
            for i = 1:3 % 1x3 RGB value
                color_tag(:,:,i) = color_tag(:,:,i)*RGB(i);
            end
        end

        function Bring2Front(app)
            % Focus on current figure and bring the app to the top level
            figure(app.UIFigure)
            if ~isempty(app.LabelDefineUIObj) && isvalid(app.LabelDefineUIObj)
                figure(app.LabelDefineUIObj.UIFigure)

            end
        end

        function cxtMenu = LabelContxtMenu(app)
            %% Create context menu for label list tree node
            cxtMenu = uicontextmenu(app.UIFigure);
            mu1 = uimenu(cxtMenu,'Text','Delete','MenuSelectedFcn',@DeleteFcn);
            mu2 = uimenu(cxtMenu,'Text','Edit','MenuSelectedFcn',@RenameFcn);

            function DeleteFcn(src,event)
                %% Remove the selected label and make alert before execute
                del_Label = app.selectedLabel.Text;
                del_message = sprintf('Delete Selected Label: "%s" ?\nCaution: All the labels wil be delete.',...
                    del_Label);
                selection = uiconfirm(app.UIFigure,del_message,'Delete Label','Icon','warning');
                if strcmp(selection,'OK')
                    app.Label_list(strcmp(app.Label_list.Name,del_Label),:) = [];
                    if ~isempty(app.fileROIinfo)
                        app.fileROIinfo.(del_Label) = [];
                    end
                    delete(app.selectedLabel)
                end
            end

            function RenameFcn(src,event)
                %% Open LabelDefine subapp with selected label information
                ren_Label = app.selectedLabel;
                app.LabelDefineUIObj = LabelDefine(app);
                app.LabelDefineUIObj.LabelName_EditField.Value  = ren_Label.NodeData.Name;
                app.LabelDefineUIObj.ColorSelector_Button.BackgroundColor = ren_Label.NodeData.Color;
                app.LabelDefineUIObj.colorVec = ren_Label.NodeData.Color;
                app.LabelDefineUIObj.ROIType_DropDown.Value = ren_Label.NodeData.Type;
                app.LabelDefineUIObj.Description_TextArea.Value = ren_Label.NodeData.Description;
                app.LabelDefineUIObj.previousName = ren_Label.NodeData.Name;
                app.LabelDefineUIObj.isRename = true;
                delete(findall(app.UIAxes.Children,'Type','images.roi.Rectangle'));
                roiRestore(app)
            end
        end

        function cxtMenu = preImgContxtMenu(app)
            %% Remove user seletced image in preview panel
            %% All information related to deleted image will be wipe out
            cxtMenu = uicontextmenu(app.UIFigure);
            mu1 = uimenu(cxtMenu,'Text','Delete','MenuSelectedFcn',@DeleteFcn);
            function DeleteFcn(src,event)
                % Find selected image
                delIdx = strcmp(app.imgDs.Files,app.currfilepath);
                % Remove from database and ROI information list
                app.imgDs.Files(delIdx) = [];
                app.fileROIinfo(delIdx,:) = [];
                % Restore with next image
                subsImg = find(delIdx);
                if subsImg >= length(app.imgDs.Files)+1
                    imgPath = app.imgDs.Files{end};
                else
                    imgPath = app.imgDs.Files{subsImg};
                end
                imshow(imread(imgPath),'Parent',app.UIAxes)
                [~,name,ext] = fileparts(imgPath);
                fprintf('"%s" Delete!\n',app.CurrentFile_Label.Text)
                app.CurrentFile_Label.Text = strcat(name,ext);
                app.currfilepath = imgPath;
                % Update image preview panel
                previewImgUpdate(app)
                % Update file index and total number
                CurrentFileIdxUpdate(app)
                % Update ROI number
                updateTreeROINum(app)

            end
        end

        function fileROIinfoUpdate(app,newFile)
            %% Create fileROIinfo structure
            if ~isempty(app.imgDs)
                switch nargin
                    case 1
                        % Create fileROIinfo from imgDs
                        app.fileROIinfo = array2table(app.imgDs.Files,'VariableNames',{'imageFilename'});
                        roiAddLabel(app)
                    case 2
                        % Update fileROIinfo with new data
                        newImgNum = length(newFile);
                        % Construct table format to append
                        temp = table('Size',[newImgNum,width(app.fileROIinfo)],...
                            'VariableTypes',repmat({'cell'},[1,width(app.fileROIinfo)]),...
                            'VariableNames',app.fileROIinfo.Properties.VariableNames);
                        temp.Files = newFile;
                        app.fileROIinfo = [app.fileROIinfo;temp];

                end
            end
        end

        function roiAddLabel(app)
            %% Add new label column in fileROIinfo table
            if ~isempty(app.fileROIinfo)
                tb_height = height(app.fileROIinfo);
                if ~isempty(app.Label_list)
                    col2Add = setdiff(app.Label_list.Name,app.fileROIinfo.Properties.VariableNames);
                    tb_width = length(col2Add);
                    % Pre-allocate table
                    pendding_tb = table('Size',[tb_height,tb_width],...
                        'VariableTypes',repmat({'cell'},[1,tb_width]),...
                        'VariableNames',col2Add); %TODO
                    app.fileROIinfo = [app.fileROIinfo,pendding_tb];
                end
            end
        end

        function roiUpdate(app)
            %% Update ROI information in fileROIinfo table
            roiObj = findall(app.UIAxes.Children,'Type','images.roi.Rectangle');
            fileIdx = strcmp(app.fileROIinfo.imageFilename,app.currfilepath);
            % Check if any exist ROI object in current image
            % If any valid ROI exist, update fileROIinfo with all ROI object in current image
            % If empty, ROI might been deleted or no ROI was existed
            if ~isempty(roiObj)
                validLabel = {roiObj.Label};
                for i  = 1:height(app.Label_list)
                    matchLabel = strcmp(app.Label_list.Name(i),validLabel);
                    positionInfo = cat(1,roiObj(matchLabel).Position);
                    positionInfo = round(positionInfo); % Round position to integer
                    app.fileROIinfo{fileIdx,app.Label_list.Name(i)} = {positionInfo};
                end
            else
                for i  = 1:height(app.Label_list)
                    app.fileROIinfo{fileIdx,app.Label_list.Name(i)} = {[]};
                end
            end
            updateTreeROINum(app)
            previewImgUpdate(app)
        end


        function MissingIdx = chkFileMissing(app,filepath)
            %% Check if file in filepath list is missing
            % Return status code 0, if file is misssing
            if isa(filepath,'cell')
                fileStatus = cellfun(@(x)exist(x,'file'),filepath);
                MissingIdx = eq(fileStatus,0);
            else
                fileStatus = exist(filepath,'file');
                MissingIdx = eq(fileStatus,0);
            end

        end

        function LabeledIdx = chkFileLabeled(app)
            if ~isempty(app.fileROIinfo{:,2:end})
                LabeledIdx = ~cellfun(@isempty,app.fileROIinfo{:,2:end});
            else
                LabeledIdx = [];
            end
        end

        function x = struct2array(app,x)
            % Convert struct to array
            x = struct2cell(x);
            x = cat(1,x{:})'; % transpose
        end

        function createCrossLine(app)
            % Cross line testing function
            % Create cross line on axes
            app.LineX= xline(app.UIAxes,0);
            app.LineY = yline(app.UIAxes,0);
            
            % Adjust object priority to avoid ROI unable to select situation 
            app.UIAxes.Children = flip(app.UIAxes.Children);
            app.UIAxes.Children(1:2) = flip(app.UIAxes.Children(1:2));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             % Resize fucntion
            %                                     monitorPos = get(0,'ScreenSize');
            %                                     monitorNum = size(monitorPos,2);
            %                                     [~,maxRes] = max(monitorPos(:,3));
            %                                     app.UIFigure.Position = monitorPos-30;
            app.UIFigure.WindowButtonMotionFcn = @app.inboundTest;
            app.UIFigure.WindowButtonDownFcn = @app.isDrawing;
            app.UIFigure.WindowButtonUpFcn = @app.isDrawing;
            app.UIFigure.KeyPressFcn = @app.HotKeyFcn;
            app.UIAxes.PositionConstraint ='innerposition';
            app.LabelListTree.ContextMenu = app.LabelContxtMenu;
            app.zoomObj = zoom(app.UIAxes);
            app.zoomObj.ButtonDownFilter = @(obj,evnt)EnableHotkey(app,obj,evnt);
            app.UIAxes.Interactions = [zoomInteraction];
            %             enableLegacyExplorationModes(app.UIFigure)
            %% Icon pre-load
%             app.CorruptedImg = imread('CorruptionImage.png');
%             app.UIFigure.Name='FAVITE Image Labeler v21.3.006(beta)'; %Version
        end

        % Button pushed function: Add_Button
        function Add_ButtonPushed(app, event)
            %% Allow single instance running only
            app.LabelDefineUIObj = LabelDefine(app);
        end

        % Button pushed function: Delete_Button
        function Delete_ButtonPushed(app, event)
            if ~isempty(app.selectedLabel)
                del_Label = app.selectedLabel.Text;
                del_message = sprintf('Delete Selected Label: "%s" ?\nCaution: All the labels wil be delete.',...
                    del_Label);
                selection = uiconfirm(app.UIFigure,del_message,'Delete Label','Icon','warning');
                if strcmp(selection,'OK')
                    app.Label_list(strcmp(app.Label_list.Name,del_Label),:) = [];
                    if ~isempty(app.fileROIinfo)
                        app.fileROIinfo.(del_Label) = [];
                    end
                    delete(app.selectedLabel)
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Popup window for confirm to exit.
            selection = uiconfirm(app.UIFigure,'Close Program?','Confirm Close',...
                'Icon','warning');
            if strcmp(selection,'OK')
                if ~isempty(app.LabelDefineUIObj)
                    delete(app.LabelDefineUIObj)
                end
                if ~isempty(app.AnalyzeFileUIObj)
                    delete(app.AnalyzeFileUIObj)
                end
                delete(app)
            else
            end

        end

        % Data changed function: DrawStatSwitch_HTML
        function DrawStatSwitch_HTMLDataChanged(app, event)
            % Check if valid label exist in tree list
            % else do not update DoDraw status
            LabelTreeObj = findall(0,'Type','uitree','tag','LabelListTree');
            if isempty(LabelTreeObj.Children)
                uialert(app.UIFigure,'Add valid label before start, enable draw switch again to update system.','No Valid Label')
                 app.DrawStatSwitch_HTML.Data = 0;
            else
                app.DoDraw = app.DrawStatSwitch_HTML.Data;

            end
            if app.DoDraw==1
                if app.AutoZoomSwitchCheckBox.Value==1
                    %pan(app.UIAxes,'off');
                    zoom(app.UIAxes,'off');
                else
                end
            elseif app.DoDraw==0
                if app.AutoZoomSwitchCheckBox.Value==1
                    %pan(app.UIAxes,'on');
                    zoom(app.UIAxes,'on');
                    app.EnableHotkey; % Enable hotkey action during zoom mode on
                else
                end
            end
        end

        % Selection changed function: LabelListTree
        function LabelListTreeSelectionChanged(app, event)
            selectedNodes = app.LabelListTree.SelectedNodes;
            % If treenode for showing ROI number has been selected
            % assgin parent treenode object as selected node.
            if strcmp(selectedNodes.Tag,'numTreeNode')
                app.selectedLabel = selectedNodes.Parent;
            else
                app.selectedLabel = selectedNodes;
            end

        end

        % Button pushed function: ImportFolder_Button
        function ImportFolder_ButtonPushed(app, event)
            DrawBlocknSync(app)
            keep = true; % keep process going
            selection = ''; % Default selection
            if ~isempty(app.imgDs)
                selection = uiconfirm(app.UIFigure,...
                    'Start a new session ?',...
                    'Add from folder',...
                    'Icon',"warning",...
                    "Options",{'New Session','Cancel'},...
                    "DefaultOption",'Cancel');

                if strcmp(selection,'Cancel')
                    keep = false; % Preserved, can be embedded to if-else section below
                end
            end


            if keep
                %folderpath = uigetdir;
                [~,folderpath] = uigetfile({'*.jpg;*.jpeg;*.bmp;*.png;*.tif;*.tiff'},...
                    'Select any image in the folder to get the direction of the folder.',...
                    'MultiSelect','off');

                if folderpath
                    switch selection
                        case 'New Session'
                            InitializeFcn(app)
                    end
                    app.imgSourcePath = folderpath;
                    try
                        h = uiprogressdlg(app.UIFigure,'Title','Access Data',...
                            'Message','Importing data from folder',...
                            'Indeterminate','on');
                        % Selection "Add in this" removed, if-else
                        % statement preserved
                        if ~isempty(app.imgDs) && strcmp(selection,'Add in this') % If user select 'Add in this'
                            newDs = imageDatastore(folderpath,'IncludeSubfolders',true);
                            app.imgDs.Files = [app.imgDs.Files;newDs.Files];
                            fileROIinfoUpdate(app,newDs.Files)
                        else
                            app.imgDs = imageDatastore(folderpath,'IncludeSubfolders',true);
                            fileROIinfoUpdate(app)
                        end
                        app.initial_load = true;
                        previewSetup(app)
                        previewScrollBarSetup(app)
                        restorePanelFcn(app)

                        % Set first image to selected and display
                        imshow(app.imgDs.Files{1},'Parent',app.UIAxes)
                        imgList = app.struct2array(app.previewImgList);
                        previewImgClicked(app,imgList(1),event)
                        app.initial_load = false;
                        Bring2Front(app)
                        delete(h)
                    catch ME
                        delete(h)
                        errorMsg = ME.message;
                        uialert(app.UIFigure,errorMsg,'Import Error')
                    end
                end
            end
            DrawBlocknSync(app,true)
            %             resetplotview(app.UIAxes,'SaveCurrentView');
            % Create crossline object after import image
            createCrossLine(app)
        end

        % Button pushed function: ImportFile_Button
        function ImportFile_ButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.jpeg;*.bmp;*.png;*.tif;*.tiff'},...
                'Select image files',...
                'MultiSelect','on');
            % Path check
            % TODO
            % Do not accept import single image from initial state(Before
            % using import file)
            if all(path)
                inCurrFolder = strcmp(path,app.imgSourcePath);
                if inCurrFolder
                    if ~isempty(app.imgDs)
                        newImgList = cellstr(strcat(path,file));
                        app.imgDs.Files = [app.imgDs.Files;newImgList];
                        previewSetup(app)
                        fileROIinfoUpdate(app,newImgList)
                        CurrentFileIdxUpdate(app)
                    elseif isempty(app.imgDs)
                        newImgList = strcat(path,file);
                        app.imgDs = imageDatastore(newImgList);
                        previewSetup(app)
                        fileROIinfoUpdate(app)
                    end
                else
                    uialert(app.UIFigure,'Can only import image from current selected folder','Invalid file path');
                end
            end
            createCrossLine(app)
        end

        % Button pushed function: Export_Button
        function Export_ButtonPushed(app, event)
            %% Export data into MATLAB .mat file
            % Question dialog
            DrawBlocknSync(app)

            selection = uiconfirm(app.UIFigure,...
                'Please choose export data',...
                'Export',...
                "Options",{'Session','Label Definition','Cancel'},...
                "DefaultOption",'Cancel');
            timestamp = datestr(datetime('now'),'yyyy-mm-dd');
            if isempty(app.imgSourcePath) %TODO
                return
            end
            
            % Replace '.' with '#' to avoid folder path split uncorrectly
            modPath = regexprep(app.imgSourcePath,'\.','\#');
            
            % Extract save path and parent folder of image source
            [rootpath,~,~] = fileparts(modPath);
            [saveFolder,imgSourceFolder,~] = fileparts(rootpath);

            % Replace '#' back to '.'
            rootpath = regexprep(rootpath,'\#','\.');
            saveFolder = regexprep(saveFolder,'\#','\.');
            imgSourceFolder = regexprep(imgSourceFolder,'\#','\.');
            
            switch selection
                case 'Session'
                    % Save whole session (fileROIList and Label_list)
                    fileName = ['SessionData_',imgSourceFolder,'.mat'];
                    exportROI = app.fileROIinfo;
                    exportLabel = app.Label_list;
                    fileName = chkFileExist(fileName);
                    if isempty(exportLabel) || isempty(exportROI)
                        uialert(app.UIFigure,'Label or image data must be not empty!','Save Error')
                    elseif ~isempty(fileName)
                        try
                            fileName = fullfile(saveFolder,fileName);
                            save(fileName,'exportROI','exportLabel')
                        catch ME
                            disp('Save failed')
                        end
                    end
                case 'Label Definition'
                    % Save label definition only (Label_list)
                    fileName = ['LabelData_',imgSourceFolder,'.mat'];
                    exportLabel = app.Label_list;
                    fileName = chkFileExist(fileName);
                    if isempty(exportLabel)
                        uialert(app.UIFigure,'Label data must not be empty!','Save Error')
                    elseif  ~isempty(fileName)
                        try
                            fileName = fullfile(saveFolder,fileName);
                            save(fileName,'exportLabel')
                        catch ME
                            disp('Save failed')
                        end
                    end
                case 'Cancel'
                    disp('Saving Process Cancel')
            end

            function fileName = chkFileExist(fileName)
                % Check and alert user if any duplicated name in target save directory
                dirInfo = dir(saveFolder);
                fileList = {dirInfo.name};
                IsFolder = [dirInfo.isdir];
                fileList = fileList(~IsFolder);
                [~,Name,ext] = fileparts(fileName);
                isExist = ismember(fileName,fileList);
                if isExist
                    msg = sprintf('The file %s already exists. Do you want to replace it?',fileName);
                    title = 'File Exists';
                    saveOpts = uiconfirm(app.UIFigure,msg,title,...
                        'Options',{'Overwrite','Save as new','Cancel'},...
                        'DefaultOption',2,...
                        'CancelOption',3,...
                        'Icon','warning');
                    switch saveOpts
                        case 'Overwrite'
                            % Do nothing (preserved)
                        case 'Save as new'
                            regName = regexprep(Name,{'\(','\)'},{'\\(','\\)'});
                            postfix = regexp(fileList,[regName,'\((?<filenum>\d)*\)\.mat'],'names');
                            postfix = cell2mat(postfix);
                            if ~isempty(postfix)
                                numTag = arrayfun(@(x)str2double(x.filenum),postfix);
                                numTag = max(numTag) + 1;
                                fileName = sprintf([Name,'(%.0f).mat'],numTag);
                            else
                                fileName = [Name,'(1)',ext];
                            end
                        case 'Cancel'
                            fileName = [];
                    end

                end

            end


            DrawBlocknSync(app,true)
        end

        % Button pushed function: ImportLabel_Button
        function ImportLabel_ButtonPushed(app, event)
            %% Load saved .mat file for label definition

            [file,path] = uigetfile({'*.mat'});
            if all(path)
                try
                    labelMat = load(fullfile(path,file));
                    if any(contains(fieldnames(labelMat),{'exportLabel'}))
                        app.Label_list = labelMat.exportLabel;
                        LabelListUpdate(app)
                        Bring2Front(app)
                    end
                catch ME
                    disp(ME.message)
                end
            else
                Bring2Front(app)
            end
        end

        % Button pushed function: ImportSession_Button
        function ImportSession_ButtonPushed(app, event)
            % Ask for depose current session, any unsaved value will be lost
            DrawBlocknSync(app)
            selection = uiconfirm(app.UIFigure,...
                'Current session will be lost, Continue?',...
                'Export',...
                "Options",{'OK','Cancel'},...
                "DefaultOption",'Cancel');
            if strcmp(selection,'OK')
                [file,path] = uigetfile({'*.mat'});
                if all(path)
                    try
                        SessionMat = load(fullfile(path,file));
                        InitializeFcn(app)
                        if all(contains(fieldnames(SessionMat),{'exportLabel','exportROI'})) % Check if the .mat file contain both variables
                            h = uiprogressdlg(app.UIFigure,'Title','Reload Data',...
                                'Message','Reload Session',...
                                'Indeterminate','on');
                            app.Label_list = SessionMat.exportLabel;
                            app.fileROIinfo = SessionMat.exportROI;
                            repName = regexp(file,'_(?<folderName>.*)','tokens');
                            [~,repfolder] = fileparts(repName{:});
                            repfolder = regexprep(repfolder,'\(\d.*\)','');
                            [path_old,filename_old,ext] = fileparts(SessionMat.exportROI.Files);
                            app.imgSourcePath = fullfile(path,repfolder,'\');
                            app.fileROIinfo.imageFilename = fullfile(path,repfolder,strcat(filename_old,ext));

                            % Check if any file is missing in saved file path
                            MissingIdx = app.chkFileMissing(app.fileROIinfo.imageFilename);
                            app.AnalyzeFileUIObj = ChkFileMiss(app);

                            % Question dialog
                            if any(MissingIdx)
                                selection = uiconfirm(app.UIFigure,...
                                    'Remove Missing File to Continue',...
                                    'File Missing',...
                                    'Options',{'Remove','Cancel'},...
                                    'DefaultOption',2,...
                                    'Icon','warning');
                            else
                                selection = 'Remove';
                            end

                            if strcmp(selection,'Remove') % Remove missing file path and re-generate datatstore based on new data
                                app.imgDs = imageDatastore(app.fileROIinfo.imageFilename(~MissingIdx));
                                app.fileROIinfo(MissingIdx,:) = [];
                                previewSetup(app)
                                previewScrollBarSetup(app)
                                LabelListUpdate(app)
                                updateTreeROINum(app)
                                app.AnalyzeFileUIObj.missingTableUpdate
                                % Select first loaded image automatically
                                imshow(app.imgDs.Files{1},'Parent',app.UIAxes)
                                imgList = app.struct2array(app.previewImgList);
                                previewImgClicked(app,imgList(1),event)
                                Bring2Front(app)
                                delete(h)
                            else % Initialize if cancel
                                InitializeFcn(app)
                                delete(h)
                            end
                        end
                    catch ME
                        delete(h)
                        disp(ME.message)
                    end
                end
            else
                Bring2Front(app)
            end
            DrawBlocknSync(app,true)

        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            %% Open subapp ChkFileMiss for checking missing file
            app.AnalyzeFileUIObj = ChkFileMiss(app);
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            zoom(app.UIFigure,'out')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'App Icon', 'LabelerIcon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolBar_Panel = uipanel(app.UIFigure);
            app.AnalyzeButton = uibutton(app.ToolBar_Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Icon = fullfile(pathToMLAPP, 'App Icon', 'AnalyzeMissing.png');
            app.AnalyzeButton.Tooltip = {'Analyze Missing Files'};
            app.AnalyzeButton.Text = 'Analyze';
            app.ImportFolder_Button = uibutton(app.ToolBar_Panel, 'push');
            app.ImportFolder_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportFolder_ButtonPushed, true);
            app.ImportFolder_Button.Icon = fullfile(pathToMLAPP, 'App Icon', 'ImportFromFolder.png');
            app.ImportFolder_Button.Tooltip = {'Import Data From Folder'};
            app.ImportFolder_Button.Text = 'Folder';
            app.ImportFile_Button = uibutton(app.ToolBar_Panel, 'push');
            app.ImportFile_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportFile_ButtonPushed, true);
            app.ImportFile_Button.Icon = fullfile(pathToMLAPP, 'App Icon', 'ImportFromImg.png');
            app.ImportFile_Button.Tooltip = {'Import Data From Image'};
            app.ImportFile_Button.Text = {'Image'; ''};
            app.ImportLabel_Button = uibutton(app.ToolBar_Panel, 'push');
            app.ImportLabel_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportLabel_ButtonPushed, true);
            app.ImportLabel_Button.Icon = fullfile(pathToMLAPP, 'App Icon', 'LabelDefinition.png');
            app.ImportLabel_Button.Tooltip = {'Import Label Definition'};
            app.ImportLabel_Button.Text = 'Label';
            app.Export_Button = uibutton(app.ToolBar_Panel, 'push');
            app.Export_Button.ButtonPushedFcn = createCallbackFcn(app, @Export_ButtonPushed, true);
            app.Export_Button.Icon = fullfile(pathToMLAPP, 'App Icon', 'Export.png');
            app.Export_Button.Tooltip = {'Export Session or Label Definition'};
            app.Export_Button.Text = {'Export'; ''};
            app.ImportSession_Button = uibutton(app.ToolBar_Panel, 'push');
            app.ImportSession_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportSession_ButtonPushed, true);
            app.ImportSession_Button.Icon = fullfile(pathToMLAPP, 'App Icon', 'ImportSession.png');
            app.ImportSession_Button.Tooltip = {'Import Saved Session'};
            app.ImportSession_Button.Text = 'Session';
            app.TopColorBar_Panel = uipanel(app.UIFigure);
            app.MainLabel_Panel = uipanel(app.UIFigure);
            app.BottomColorBar_Panel = uipanel(app.MainLabel_Panel);
            app.ImgPreview_Panel = uipanel(app.MainLabel_Panel);
            app.MainViewArea_Panel = uipanel(app.MainLabel_Panel);
            app.UIAxesContain_Panel = uipanel(app.MainViewArea_Panel);
            app.UIAxes = uiaxes(app.UIAxesContain_Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CurrentFileIndex_Label = uilabel(app.UIAxesContain_Panel);
            app.CurrentFileIndex_Label.Text = 'Current File: ';
            app.DrawStat_Panel = uipanel(app.UIAxesContain_Panel);
            app.DrawLabel = uilabel(app.DrawStat_Panel);
            app.DrawLabel.Text = 'Draw';
            app.DrawStatSwitch_HTML = uihtml(app.DrawStat_Panel);
            app.DrawStatSwitch_HTML.HTMLSource = 'DrawSwitch.html';
            app.DrawStatSwitch_HTML.DataChangedFcn = createCallbackFcn(app, @DrawStatSwitch_HTMLDataChanged, true);
            app.DrawStatSwitch_HTML.Tooltip = {''};
            app.DrawStatSwitch_HTML.Data = false;
            app.AutoZoomSwitchCheckBox = uicheckbox(app.DrawStat_Panel);
            app.AutoZoomSwitchCheckBox.Text = 'Auto Zoom Switch';
            app.AutoZoomSwitchCheckBox.Value = true;
            app.DrawOntocreateROILabel = uilabel(app.DrawStat_Panel);
            app.DrawOntocreateROILabel.Text = {'Draw On:'; '- Create ROI.'; ''; 'Draw Off:'; '- Click to select ROI.'; '- Select ROI to resize.'; '- Press delete key to delete selected ROI(s).'; '- Ctrl+Click to deselected ROI(s).'};
            app.ZoomOutButton = uibutton(app.UIAxesContain_Panel, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Icon = fullfile(pathToMLAPP, 'App Icon', 'ZoomOut.png');
            app.ZoomOutButton.Text = '';
            app.LabelListTree = uitree(app.MainLabel_Panel);
            app.LabelListTree.SelectionChangedFcn = createCallbackFcn(app, @LabelListTreeSelectionChanged, true);
            app.LabelListTree.Tag = 'LabelListTree';
            app.Label_Panel = uipanel(app.MainLabel_Panel);
            app.Label_Panel.Title = 'Label';
            app.Add_Button = uibutton(app.Label_Panel, 'push');
            app.Add_Button.ButtonPushedFcn = createCallbackFcn(app, @Add_ButtonPushed, true);
            app.Add_Button.Icon = fullfile(pathToMLAPP, 'App Icon', 'Label_Add.png');
            app.Add_Button.Text = 'Add ';
            app.Delete_Button = uibutton(app.Label_Panel, 'push');
            app.Delete_Button.ButtonPushedFcn = createCallbackFcn(app, @Delete_ButtonPushed, true);
            app.Delete_Button.Icon = fullfile(pathToMLAPP, 'App Icon', 'Label_Delete.png');
            app.Delete_Button.Text = 'Delete';
            app.previewScrollBar_Panel = uipanel(app.MainLabel_Panel);
            app.previewScrollBar_HTML = uihtml(app.previewScrollBar_Panel);
            app.previewScrollBar_HTML.HTMLSource = '<div></div>';
            app.CurrentFile_Label = uilabel(app.MainLabel_Panel);
            app.CurrentFile_Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App Icon', 'LabelerIcon_horizon_Ver2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_ImageLabeler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end