classdef VIStudio_ClassificationApp_T1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VIStudio_ClassificationUIFigure  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LoadDataTab                    matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        DataImportButton               matlab.ui.control.Button
        Panel_LoadData                 matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        CatDistributionChart_Panel     matlab.ui.container.Panel
        DataSourceEditField            matlab.ui.control.EditField
        DataSourceEditFieldLabel       matlab.ui.control.Label
        ImageTab                       matlab.ui.container.Tab
        Panel_8                        matlab.ui.container.Panel
        ContrastSlider                 matlab.ui.control.Slider
        ContrastSliderLabel            matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        BrightnessSlider               matlab.ui.control.Slider
        BrightnessSliderLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        Panel_5                        matlab.ui.container.Panel
        countIdx                       matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        datastoreLabel                 matlab.ui.control.Label
        TrainingTab                    matlab.ui.container.Tab
        Label_2                        matlab.ui.control.Label
        Panel_10                       matlab.ui.container.Panel
        ImageAugmentationPanel         matlab.ui.container.Panel
        CheckLamp_2                    matlab.ui.control.Lamp
        CheckLamp_2Label               matlab.ui.control.Label
        AugmentationButton             matlab.ui.control.Button
        RandRotationEditField_R        matlab.ui.control.NumericEditField
        RandRotationEditFieldLabel     matlab.ui.control.Label
        RandRotationEditField_L        matlab.ui.control.NumericEditField
        RandScaleEditField_R           matlab.ui.control.NumericEditField
        RandScaleEditFieldLabel        matlab.ui.control.Label
        RandScaleEditField_L           matlab.ui.control.NumericEditField
        RandYTranslationEditField_R    matlab.ui.control.NumericEditField
        RanYTranslationEditFieldLabel  matlab.ui.control.Label
        RandYTranslationEditField_L    matlab.ui.control.NumericEditField
        RandXTranslationEditField_R    matlab.ui.control.NumericEditField
        RanXTranslationEditFieldLabel  matlab.ui.control.Label
        RandXTranslationEditField_L    matlab.ui.control.NumericEditField
        RandYReflectionEditField_R     matlab.ui.control.NumericEditField
        RandYReflectionEditFieldLabel  matlab.ui.control.Label
        RandXReflectionEditField_R     matlab.ui.control.NumericEditField
        RandXReflectionEditFieldLabel  matlab.ui.control.Label
        SpiitDataPanel                 matlab.ui.container.Panel
        SplitDataButton                matlab.ui.control.Button
        CheckLamp                      matlab.ui.control.Lamp
        CheckLampLabel                 matlab.ui.control.Label
        TestingDataEditField           matlab.ui.control.NumericEditField
        TestingDataEditFieldLabel      matlab.ui.control.Label
        ValidationDataEditField        matlab.ui.control.NumericEditField
        ValidationDataLabel            matlab.ui.control.Label
        TrainingDataEditField          matlab.ui.control.NumericEditField
        TrainingDataEditFieldLabel     matlab.ui.control.Label
        Panel_9                        matlab.ui.container.Panel
        StartTrainButton               matlab.ui.control.Button
        TrainingOptionsButton          matlab.ui.control.StateButton
        Panel_3                        matlab.ui.container.Panel
        ModelLoadedLamp                matlab.ui.control.Lamp
        ModelLoadedLampLabel           matlab.ui.control.Label
        ModelDropDown                  matlab.ui.control.DropDown
        ModelDropDownLabel             matlab.ui.control.Label
        ModelReloadButton              matlab.ui.control.Button
        InferenceTab                   matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        InferencePanel                 matlab.ui.container.Panel
        UITable2                       matlab.ui.control.Table
        Panel_4                        matlab.ui.container.Panel
        StartTestDataButton            matlab.ui.control.Button
        ModelLoadedLamp_2              matlab.ui.control.Lamp
        ModelLoadedLamp_2Label         matlab.ui.control.Label
        ModelGroupButtonGroup          matlab.ui.container.ButtonGroup
        LoadanothermodelButton         matlab.ui.control.Button
        AddanothermodelButton          matlab.ui.control.RadioButton
        UseTrainedModelButton          matlab.ui.control.RadioButton
        ImageClassifyButton            matlab.ui.control.Button
        LoadOtherImageFoldButton       matlab.ui.control.Button
        ExplainedTab                   matlab.ui.container.Tab
        Label_3                        matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        SwitchLabel                    matlab.ui.control.Label
        MethodsDropDown                matlab.ui.control.DropDown
        MethodsDropDownLabel           matlab.ui.control.Label
        ExplainButton                  matlab.ui.control.Button
        Inference_Panel                matlab.ui.container.Panel
        ExplainedImage                 matlab.ui.control.Image
        Panel_11                       matlab.ui.container.Panel
        LoadImageButton                matlab.ui.control.Button
        ExportTab                      matlab.ui.container.Tab
        Panel_6                        matlab.ui.container.Panel
        ExportModelButton              matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        VersionDemo095Label            matlab.ui.control.Label
        ClassificationLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        currFigPosition
    end

    properties (Access = public)
        TrainingOptionApp % Description
        trainingDict = dictionary
        trainingOptionsName =[...
            "Solver";...
            "MaxEpochs";...
            "MiniBatchSize";...
            "ValidationData";...
            "ValidationFrequency";...
            "Verbose";...
            "Plots";...
            "Shuffle";...
            "InitialLearnRate";...
            "LearnRateSchedule";...
            "LearnRateDropPeriod";...
            "LearnRateDropFactor"
            ];

        % 訓練模型參數
        trainingOpts
              
        netInputSz
        preTrainedNetwork
        preTrainedNetwork_classname

        rawDataSrc
        trainingDataSrc
        validationDataSrc
        testingDataSrc

        validSpitRatio = 0.8
        trainClassNum
        trainClassName
        
        % 訓練好的模型
        trainedNet
        
        
        % Image分頁
        currentIdx %目前顯示照片
        NumofFiles %照片總數
        ImageInference %從datastore抓取出來的照片
        BrightImg %運算中的改變亮度影像
        ImageInference_bright %顯示當下改變亮度影像
        
        ContrastImg
        ImageInference_Contrast

        % Tran分頁
        augmentedTrainingData %擴充資料庫

        % inference datastore
        InferenceImds %實現資料庫
        othermodel_add %吃別的模型
        ExplainedImageforEXI %重新載入的影像可視化影像
        ResizeExImg


        inferenceImg
        inferenceResult
        inferenceScore
        explainMap
        blendImag


    end

    methods (Access = private)
        %% Get Training Options from Subapp
        % Function: Gather training options from subapp
        % "VIStudio_ClassificationTrainingOption.mlapp

        function gatherTrainingOptionsParameters(app)

            % Check if TrainingOptionApp is empty
            if ~isempty(app.TrainingOptionApp)

                subAppFig = app.TrainingOptionApp.TrainingOptionUIFigure;
                trainingOptionObj = findobj(subAppFig,'Tag','TrainingOptions');
                trainingOptionValue = get(trainingOptionObj,'Value');

                app.trainingDict = insert(app.trainingDict,app.trainingOptionsName,trainingOptionValue);

                if isempty(app.trainingDict{'ValidationData'})
                    app.trainingDict{'ValidationData'} = [];
                end

                % Create training options
                app.trainingOpts = trainingOptions(...
                    app.trainingDict{"Solver"},...
                    MaxEpochs = app.trainingDict{"MaxEpochs"},...
                    MiniBatchSize = app.trainingDict{"MiniBatchSize"},...
                    ValidationData = [],...%app.trainingDict{"ValidationData"},...
                    ValidationFrequency = app.trainingDict{"ValidationFrequency"},...
                    Verbose = app.trainingDict{"Verbose"},...
                    Plots = app.trainingDict{"Plots"},...
                    Shuffle = app.trainingDict{"Shuffle"},...
                    InitialLearnRate = app.trainingDict{"InitialLearnRate"},...
                    LearnRateSchedule = app.trainingDict{"LearnRateSchedule"},...
                    LearnRateDropPeriod = app.trainingDict{"LearnRateDropPeriod"},...
                    LearnRateDropFactor = app.trainingDict{"LearnRateDropFactor"},...
                    Metrics = ["accuracy","fscore"]...
                    );
            else
                app.trainingOpts = trainingOptions("adam", ...
                    InitialLearnRate=0.01,...
                    MaxEpochs=5,...
                    MiniBatchSize=64,...
                    ValidationData=app.validationDataSrc, ...
                    ValidationFrequency=5, ...
                    Plots="training-progress", ...
                    Metrics = ["accuracy","fscore"], ...
                    Verbose=false);
            end
        end

        %% Preprocess the Image Data Source
        function imgOut = preprocessDsImg(app,filename)
            img = imread(filename);
            imgOut = imresize(img, app.netInputSz(1:2));
        end

        %% Get Model Required Data Input Size
        function getNetworkInputSize(app)

            net = app.preTrainedNetwork;

            if ~isscalar(net.InputNames)
                errordlg("Networks with multiple inputs are not supported.")
            end
            inputName = net.InputNames{1};
            layer = net.Layers(1);

            % Supported layers list
            supportedLayers = [
                "ImageInputLayer",...
                "Image3DInputLayer",...
                "SequebceInputLayer",...
                "FeatureInputLayer"...
                ];
            if ~contains(class(layer), supportedLayers)
                errordlg("Input layer must be an image, 3-D image, sequence, or feature input layer.")
            end
            app.netInputSz  =layer.InputSize;
        end


        function importTrainingData(app)
        end

        %% Check for Model Loaded
        function ModelLoaded = chkModelLoaded(app)
            ModelLoaded = false;
            if ~isempty(app.preTrainedNetwork)
                ModelLoaded = true;
                app.ModelLoadedLamp.Color = [0.39,0.83,0.07];   % Change for green light
                app.ImageClassifyButton.Enable = true;
                app.StartTrainButton.Enable = true;
            end
        end

        %%  Check for Model Add-On installed
        function isInstalled = chkAddOnInstalled(app, ModelName)
            isInstalled = true;
            if ~isdeployed
                addon = matlab.addons.installedAddons();
                if ~any(strcmpi(addon.Identifier, ModelName))
                    msg = "Model: "+ModelName+" does not found, Please install from Add-On first";
                    uialert(app.VIStudio_ClassificationUIFigure, msg, 'Model not installed')
                    isInstalled = false;
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.currFigPosition = app.VIStudio_ClassificationUIFigure.Position(1:2);
        end

        % Window button down function: VIStudio_ClassificationUIFigure
        function VIStudio_ClassificationUIFigureWindowButtonDown(app, event)
            app.currFigPosition = app.VIStudio_ClassificationUIFigure.Position(1:2);
        end

        % Window button up function: VIStudio_ClassificationUIFigure
        function VIStudio_ClassificationUIFigureWindowButtonUp(app, event)
            app.currFigPosition = app.VIStudio_ClassificationUIFigure.Position(1:2);
        end

        % Close request function: VIStudio_ClassificationUIFigure
        function VIStudio_ClassificationUIFigureCloseRequest(app, event)
            delete(app.TrainingOptionApp)
            delete(app)

        end

        % Window button motion function: VIStudio_ClassificationUIFigure
        function VIStudio_ClassificationUIFigureWindowButtonMotion(app, event)
            if ~isempty(app.TrainingOptionApp)
                ismoved = app.VIStudio_ClassificationUIFigure.Position(2)~=app.TrainingOptionApp.TrainingOptionUIFigure.Position(2);
                if ismoved
                    app.TrainingOptionApp.PositionSetFcn
                end
            end
        end

        % Value changed function: TrainingOptionsButton
        function TrainingOptionsButtonValueChanged(app, event)
            OptionOnOff = app.TrainingOptionsButton.Value;

            switch OptionOnOff
                case true
                    if isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp = VIStudio_ClassificationTrainingOption(app);
                    else
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'On';
                    end
                case false
                    if ~isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'Off';
                    end
            end


        end

        % Button pushed function: StartTrainButton
        function StartTrainButtonPushed(app, event)
            % Get training info and model input size
            gatherTrainingOptionsParameters(app)
            getNetworkInputSize(app)

            [layerName,learnableNames] = networkHead(app.preTrainedNetwork);
            net = freezeNetwork(app.preTrainedNetwork, LayerNamesToIgnore=layerName);

            trainingProgressBar = uiprogressdlg(app.VIStudio_ClassificationUIFigure,...
                Message="Network Training...",...
                Title='Train network',...
                Indeterminate='on');
            
            %改成擴充資料
            app.trainedNet = trainnet(app.augmentedTrainingData,...
                net,...
                "crossentropy",...
                app.trainingOpts);


            delete(trainingProgressBar)

            % Validation result
            validationOutput = minibatchpredict( app.trainedNet, app.validationDataSrc);
            validationSCOutput = scores2label(validationOutput,app.trainClassName);

            trueLabel = app.validationDataSrc.Labels;
            %confusionchart(trueLabel, testOutput, Parent=app.ConfusionMat_Panel);
            %confusionchart(app.ConfusionMat_Panel,trueLabel, testOutput);
            %%confusionchart(app.UIAxes,trueLabel, testOutput);
            confusionchart(app.Panel_10,trueLabel, validationSCOutput);
            

            acc = mean(trueLabel==validationSCOutput)

            app.Label_2.Text = "Accuracy : " + string(round(acc*100,2)) + "%";

            %更改Inference的燈號
            app.ModelLoadedLamp_2.Color = [0,1,0];


        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            netWorkType = app.ModelDropDown.Value;

            switch netWorkType
                % User load trained network
                case "CustomTrainedNetwork"
                otherwise
                    if  chkAddOnInstalled(app, netWorkType)
                    else
                        return
                    end

                    progressbar = uiprogressdlg(app.VIStudio_ClassificationUIFigure,...
                        Message="Loading Model: "+ netWorkType +", Please wait...",...
                        Title="Import Model",...
                        Indeterminate="on");

                    % Get pretrained model
                    if ~isempty(app.trainClassNum)
                    app.preTrainedNetwork=...
                        imagePretrainedNetwork(netWorkType,NumClasses=app.trainClassNum);
                    else
                        [app.preTrainedNetwork, app.preTrainedNetwork_classname]  =...
                        imagePretrainedNetwork(netWorkType,NumClasses=app.trainClassNum);
                    
                    end
                    delete(progressbar)
            end


        end

        % Button pushed function: DataImportButton
        function DataImportButtonPushed(app, event)
            % Import image data
            folderpath = uigetdir;

            if isscalar(folderpath)
                disp('User cancel import')
                return
            else
                progressbar = uiprogressdlg(app.VIStudio_ClassificationUIFigure,...
                    Message="Data Importing" ,...
                    Title="Import Data",...
                    Indeterminate="on");

                app.rawDataSrc = imageDatastore(folderpath,...
                    IncludeSubfolders=true,...
                    LabelSource="foldernames");

             
                app.DataSourceEditField.Value = folderpath;

                delete(progressbar)

                % Data distribution visualization
                counts = countlabels(app.rawDataSrc.Labels);
                piechart(categorical(app.rawDataSrc.Labels),...
                    Parent=app.CatDistributionChart_Panel,...
                    Labels=categories(app.rawDataSrc.Labels)+"\newline"+string(counts.Count),...
                    Title="Training Data Distribution")
                %imshow(app.inferenceImg, Parent=app.ExplainedImage)

                % Load Type and Num into Table 將類別與數量載入Table
                app.UITable.Visible = "on";
                RawDataTable = countEachLabel(app.rawDataSrc);
                app.UITable.Data = RawDataTable;

                % 加在Image分頁中

                app.NumofFiles = numel(app.rawDataSrc.Files);
                app.currentIdx = 1;
                app.countIdx.Text = sprintf('%d / %d', app.currentIdx, app.NumofFiles);
                
                % 顯示目前影像
                app.ImageInference = readimage(app.rawDataSrc,app.currentIdx);
                %imshow(app.ImageInference, Parent=app.Image_UIAxes)
                app.Image.ImageSource = app.ImageInference; 

                % 檔名暫時不顯示
                folderpath = app.rawDataSrc.Files(app.currentIdx);
                [~, lastFileName, ~] = fileparts(folderpath);
                app.datastoreLabel.Text = lastFileName;
                
                %---------------------重點--------------------------
                % 視窗顯示與還原
                app.VIStudio_ClassificationUIFigure.WindowStyle = 'modal';
                pause(0.5)  
                app.VIStudio_ClassificationUIFigure.WindowStyle = 'normal';
                
            end


        end

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)
            ModelDropDownValueChanged(app)
            if chkModelLoaded(app)
                getNetworkInputSize(app)
            end
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            if ~isempty(app.trainedNet)
                [file,location] = uiputfile('.*', 'Save trained model', 'trainedModel.mat');
                model = app.trainedNet;
                save(fullfile(location,file), 'model')
            end
        end

        % Button pushed function: LoadOtherImageFoldButton
        function LoadOtherImageFoldButtonPushed(app, event)
 
            % 選擇資料夾
            folderpath = uigetdir;

            if isscalar(folderpath)
                disp('User cancel import')
                return
            else
                progressbar = uiprogressdlg(app.VIStudio_ClassificationUIFigure,...
                    Message="Data Importing" ,...
                    Title="Import Data",...
                    Indeterminate="on");

                app.rawDataSrc = imageDatastore(folderpath,...
                    IncludeSubfolders=true,...
                    LabelSource="foldernames");


                app.DataSourceEditField.Value = folderpath;

                delete(progressbar)
            end


            % 建立 imageDatastore（影像案例）
            app.InferenceImds = imageDatastore(folderpath, ...
                'IncludeSubfolders', true, ...
                'LabelSource', 'foldernames');

            % 顯示基本資訊
            msg = sprintf("已載入 %d 張影像，類別數：%d", ...
                numel(app.InferenceImds.Files), numel(unique(app.InferenceImds.Labels)));
            uialert(app.VIStudio_ClassificationUIFigure, msg, '載入完成');

            % 如果有狀態 Label
            if isprop(app, 'StatusLabel') && ~isempty(app.StatusLabel)
                app.StatusLabel.Text = msg;
            end

            T = countEachLabel(app.InferenceImds);  % 回傳 table: Label / Count
            if isprop(app,'UITable2') && ~isempty(app.UITable2)
                app.UITable2.Data = T;
                %（可選）漂亮一點的欄名
                app.UITable2.ColumnName = {'類別','筆數'};
            end


        end

        % Button pushed function: ImageClassifyButton
        function ImageClassifyButtonPushed(app, event)
            
                  
            app.InferenceImds.ReadFcn = @(x)preprocessDsImg(app,x);
                           
            InferenceOutput = minibatchpredict(app.trainedNet, app.InferenceImds);
            InferenceSCOutput = scores2label(InferenceOutput,app.trainClassName);

            trueLabel = app.InferenceImds.Labels;

            cm2 = confusionchart(app.InferencePanel,trueLabel, InferenceSCOutput);
            cm2.Title = 'Other Test Data';
    
            testacc = mean(trueLabel==InferenceSCOutput)

            app.Label.Text = "Accuracy : " + string(round(testacc*100,2)) + "%";

        end

        % Button pushed function: ExplainButton
        function ExplainButtonPushed(app, event)

            ExplainMethods = app.MethodsDropDown.Value;

            progressbar = uiprogressdlg(app.VIStudio_ClassificationUIFigure,...
                Message="Model explain using: "+ ExplainMethods ,...
                Title="Model Explain",...
                Indeterminate="on");
            
            % 轉換尺寸
            app.ResizeExImg = imresize( app.ExplainedImageforEXI,[224 224]);
            
            [exResult, app.inferenceScore] =predict(app.trainedNet, single(app.ResizeExImg));
            app.inferenceResult = scores2label(exResult,app.trainClassName);
            app.Label_3.Text =  app.inferenceResult;
            
            switch ExplainMethods
                case 'GradCam'                 
                    app.explainMap = gradCAM(app.trainedNet, app.ExplainedImageforEXI, app.inferenceResult);
                
                case 'LIME'
                     app.explainMap = imageLIME(app.trainedNet, app.ExplainedImageforEXI, double(app.inferenceResult));
                end

            delete(progressbar)

            I0 = im2double(app.ExplainedImageforEXI);
            if size(I0,3)==1, I0 = repmat(I0,[1 1 3]); end

            M = app.explainMap;
            if isa(M,'dlarray'), M = extractdata(M); end
            if isa(M,'gpuArray'), M = gather(M); end
            if ndims(M)==3, M = mean(M,3); end
            M = mat2gray(imresize(M,[size(I0,1) size(I0,2)]));

            cmap = jet(256);
            heatRGB = ind2rgb(gray2ind(M,256), cmap);

            alpha = 0.30;
            app.blendImag = (1-alpha)*I0 + alpha*heatRGB;     % 疊圖
            app.ExplainedImage.ImageSource = im2uint8(app.blendImag);  

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switch value
                case 'On'
                        if ~isempty(app.blendImag)
                            app.ExplainedImage.ImageSource = app.blendImag;  
                        else
                            ExplainButtonPushed(app)
                            
                            if ~isempty(app.blendImag)
                                app.ExplainedImage.ImageSource = app.blendImag;
                            end
                        end
                case 'Off'                    
                    app.ExplainedImage.ImageSource = app.ExplainedImageforEXI;  
            end
        end

        % Callback function
        function StateChangeSwitchValueChanged(app, event)
            session = app.StateChangeSwitch.Value;
            switch session
                case 'Inference'
                    app.Inference_Panel.Visible = "on";
                    app.Inference_Panel.Enable = "on";
                    app.CatDistributionChart_Panel.Visible = "off";
                    app.CatDistributionChart_Panel.Enable = "off";
                    app.ConfusionMat_Panel.Visible = "off";
                    app.ConfusionMat_Panel.Enable = "off";
                case 'Training'
                    app.Inference_Panel.Visible = "off";
                    app.Inference_Panel.Enable = "off";
                    app.CatDistributionChart_Panel.Visible = "on";
                    app.CatDistributionChart_Panel.Enable = "on";
                    app.ConfusionMat_Panel.Visible = "on";
                    app.ConfusionMat_Panel.Enable = "on";
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
                
            app.currentIdx = app.currentIdx+1;
            if(app.currentIdx >app.NumofFiles)
                app.currentIdx = app.NumofFiles;
            end

            app.countIdx.Text = sprintf('%d / %d', app.currentIdx, app.NumofFiles);
            
            folderpath = app.rawDataSrc.Files(app.currentIdx);
            [~, lastFileName, ~] = fileparts(folderpath);
            app.datastoreLabel.Text = lastFileName;
            
            % 拉霸數值歸零
            app.BrightnessSlider.Value = 0;
            app.ContrastSlider.Value = 0;

            % 顯示目前影像
            app.ImageInference = readimage(app.rawDataSrc,app.currentIdx);
            %imshow(app.ImageInference, Parent=app.Image_UIAxes)
            app.Image.ImageSource = app.ImageInference; 
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            app.currentIdx = app.currentIdx-1;
            if(app.currentIdx <1)
                app.currentIdx = 1;
            end
            app.countIdx.Text = sprintf('%d / %d', app.currentIdx, app.NumofFiles);
            
            folderpath = app.rawDataSrc.Files(app.currentIdx);
            [~, lastFileName, ~] = fileparts(folderpath);
            app.datastoreLabel.Text = lastFileName;
            
            % 拉霸數值歸零
            app.BrightnessSlider.Value = 0;
            app.ContrastSlider.Value = 0;

            % 顯示目前影像
            app.ImageInference = readimage(app.rawDataSrc,app.currentIdx);
            %imshow(app.ImageInference, Parent=app.Image_UIAxes)

            app.Image.ImageSource = app.ImageInference; 
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            BRawvalue = app.BrightnessSlider.Value;
            Bvalue = BRawvalue/100;
            app.BrightImg = im2double(app.ImageInference);

            app.BrightImg = min(max(app.BrightImg + Bvalue, 0),1);
     
            app.ImageInference_bright  = im2uint8(app.BrightImg);
            %app.BrightImg = imadjust(app.BrightImg, [0.5-Cvalue/2  0.5+Cvalue/2], []);
            %app.ImageInference = im2uint8(mat2gray(app.BrightImg));

            app.Image.ImageSource = app.ImageInference_bright;
        

        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            Contrastvalue = app.ContrastSlider.Value;
            app.ContrastImg = im2double(app.ImageInference);
            Cvalue = 1+(Contrastvalue/100);
            app.ContrastImg = (app.ContrastImg - 0.5)*Cvalue + 0.5;
            app.ContrastImg = min(max(app.ContrastImg,0),1);
            app.ImageInference_Contrast = im2uint8(app.ContrastImg);

            app.Image.ImageSource = app.ImageInference_Contrast;


        end

        % Button pushed function: SplitDataButton
        function SplitDataButtonPushed(app, event)
            % 取總數
            app.trainClassName = categories(app.rawDataSrc.Labels);
            app.trainClassNum = numel(app.trainClassName);
            app.rawDataSrc.ReadFcn = @(x)preprocessDsImg(app,x);

            % 先讀取三個比例
            trainPct = app.TrainingDataEditField.Value;
            valPct   = app.ValidationDataEditField.Value;
            testPct  = app.TestingDataEditField.Value;

            % 檢查加總是否為 100%
            if trainPct + valPct + testPct ~= 100
                uialert(app.VIStudio_ClassificationUIFigure, 'Training + Validation + Testing 必須加總為 100！', ...
                    '比例錯誤');
                app.CheckLamp.Color = 'red';
                return;
            end

            % 把百分比換成小數
            trainFrac = trainPct / 100;
            valFrac   = valPct   / 100;
            testFrac  = testPct  / 100;
            % 設定原始 datastore 的 preprocess
            app.rawDataSrc.ReadFcn = @(x) preprocessDsImg(app, x);

            % 第一階段：先把 rawData 切出 test 集
            %   剩下 train+val 共 (trainFrac + valFrac)，testFrac 給 test
            [trainValSrc, testSrc] = splitEachLabel( ...
                app.rawDataSrc, trainFrac + valFrac, 'randomize');

            % 第二階段：再把 train+val 的 trainFrac/(trainFrac+valFrac) 切出 train
            trainOnlyFrac = trainFrac / (trainFrac + valFrac);
            [trainSrc, valSrc] = splitEachLabel( ...
                trainValSrc, trainOnlyFrac, 'randomize');

            % 存回 app
            app.trainingDataSrc   = trainSrc;
            app.validationDataSrc = valSrc;
            app.testingDataSrc    = testSrc;

            % 切割成功，將指示燈設為綠色
            app.CheckLamp.Color = 'green';
        end

        % Button pushed function: AugmentationButton
        function AugmentationButtonPushed(app, event)
            
            try
            % 1. 從 UI 拿值
            rxR = app.RandXReflectionEditField_R.Value;
            ryR = app.RandYReflectionEditField_R.Value;

            txL = app.RandXTranslationEditField_L.Value;
            txR = app.RandXTranslationEditField_R.Value;
            tyL = app.RandYTranslationEditField_L.Value;
            tyR = app.RandYTranslationEditField_R.Value;

            sL  = app.RandScaleEditField_L.Value;
            sR  = app.RandScaleEditField_R.Value;
            rotL= app.RandRotationEditField_L.Value;
            rotR= app.RandRotationEditField_R.Value;


            % 2. 建 augmenter
            aug = imageDataAugmenter( ...
                'FillValue',           0, ...
                'RandXReflection',     rxR ~= 0, ...
                'RandYReflection',     ryR ~= 0, ...
                'RandRotation',        [rotL rotR], ...
                'RandScale',           [sL  sR], ...
                'RandXTranslation',    [txL txR], ...
                'RandYTranslation',    [tyL tyR] ...
            );

            % 3. 套用 augmentedImageDatastore
            inputSize = [224 224 3];  % 依模型 input 調整
            dsAug = augmentedImageDatastore(inputSize, ...
                app.trainingDataSrc, ...
                'DataAugmentation', aug);

            % 4. 成功，設定 lamp 為綠色
            app.CheckLamp_2.Color = 'green';

            % 5. 可把 dsAug 存回 app，後續 trainNetwork 用
            app.augmentedTrainingData = dsAug;

        catch ME
            % 發生錯誤，顯示警告並保持紅燈
            uialert(app.UIFigure, ME.message, 'Augmentation Error');
            app.AugStatusLamp.Color = 'red';
        end


        end

        % Selection changed function: ModelGroupButtonGroup
        function ModelGroupButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelGroupButtonGroup.SelectedObject;
            
            if app.AddanothermodelButton.Value == 1
                %更改=燈號
                app.ModelLoadedLamp_2.Color = [1,0,0];
            end

            if app.UseTrainedModelButton.Value == 1
                %更改=燈號
                app.ModelLoadedLamp_2.Color = [0,1,0];
            end

            %if app.AddanothermodelButton.Value == 1 && app.othermodel_add ==1
            %    %更改=燈號
            %    app.ModelLoadedLamp_2.Color = [0,1,0];
            %end
        end

        % Button pushed function: StartTestDataButton
        function StartTestDataButtonPushed(app, event)
            % Test result
            testOutput = minibatchpredict( app.trainedNet, app.testingDataSrc);
            testSCOutput = scores2label(testOutput,app.trainClassName);

            trueLabel = app.testingDataSrc.Labels;
            %confusionchart(trueLabel, testOutput, Parent=app.ConfusionMat_Panel);
            %confusionchart(app.ConfusionMat_Panel,trueLabel, testOutput);
            %%confusionchart(app.UIAxes,trueLabel, testOutput);
            cm = confusionchart(app.InferencePanel,trueLabel, testSCOutput);
            cm.Title = 'Test Data';
    
            testacc = mean(trueLabel==testSCOutput)

            app.Label.Text = "Accuracy : " + string(round(testacc*100,2)) + "%";

        end

        % Button pushed function: LoadanothermodelButton
        function LoadanothermodelButtonPushed(app, event)
            app.othermodel_add = 1;
            uialert(app.VIStudio_ClassificationUIFigure, ...
            '暫時不開放此功能', '功能未開放', 'Icon','warning');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)

            [f, p] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif;*.tiff','Image files'});
            if isequal(f,0), return; end   % 取消就離開

            file = fullfile(p,f);
            try
                app.ExplainedImageforEXI = imread(file);
                app.ExplainedImage.ImageSource = app.ExplainedImageforEXI; 

            catch ME
                uialert(app.VIStudio_ClassificationUIFigure, ...
                    getReport(ME,'basic','hyperlinks','off'), '讀取失敗', 'Icon','error');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VIStudio_ClassificationUIFigure = uifigure('Visible', 'off');
            app.VIStudio_ClassificationUIFigure.Name = 'VIStudio_Classification';
            app.VIStudio_ClassificationUIFigure.CloseRequestFcn = createCallbackFcn(app, @VIStudio_ClassificationUIFigureCloseRequest, true);
            app.VIStudio_ClassificationUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @VIStudio_ClassificationUIFigureWindowButtonDown, true);
            app.VIStudio_ClassificationUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @VIStudio_ClassificationUIFigureWindowButtonUp, true);
            app.VIStudio_ClassificationUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @VIStudio_ClassificationUIFigureWindowButtonMotion, true);
            app.Panel = uipanel(app.VIStudio_ClassificationUIFigure);
            app.ClassificationLabel = uilabel(app.Panel);
            app.ClassificationLabel.Text = 'Classification';
            app.VersionDemo095Label = uilabel(app.Panel);
            app.VersionDemo095Label.Text = 'Version: Demo 0.95';
            app.TabGroup = uitabgroup(app.VIStudio_ClassificationUIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.DataSourceEditFieldLabel = uilabel(app.LoadDataTab);
            app.DataSourceEditFieldLabel.Text = 'Data Source';
            app.DataSourceEditField = uieditfield(app.LoadDataTab, 'text');
            app.DataSourceEditField.Editable = 'off';
            app.Panel_LoadData = uipanel(app.LoadDataTab);
            app.CatDistributionChart_Panel = uipanel(app.Panel_LoadData);
            app.UITable = uitable(app.Panel_LoadData);
            app.UITable.ColumnName = {'類別(Class Type)'; '數量(Num)'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.Panel_2 = uipanel(app.LoadDataTab);
            app.DataImportButton = uibutton(app.Panel_2, 'push');
            app.DataImportButton.ButtonPushedFcn = createCallbackFcn(app, @DataImportButtonPushed, true);
            app.DataImportButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Import Data.png');
            app.DataImportButton.Text = {'Data'; 'Import'};
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image ';
            app.Panel_5 = uipanel(app.ImageTab);
            app.datastoreLabel = uilabel(app.Panel_5);
            app.datastoreLabel.Text = '';
            app.Button = uibutton(app.Panel_5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.Panel_5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.countIdx = uilabel(app.Panel_5);
            app.countIdx.Text = '';
            app.Image = uiimage(app.ImageTab);
            app.Panel_7 = uipanel(app.ImageTab);
            app.BrightnessSliderLabel = uilabel(app.Panel_7);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Panel_7);
            app.BrightnessSlider.Limits = [-100 100];
            app.BrightnessSlider.MajorTicks = [-100 0 100];
            app.BrightnessSlider.MajorTickLabels = {'-100', '0', '100'};
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.Panel_8 = uipanel(app.ImageTab);
            app.ContrastSliderLabel = uilabel(app.Panel_8);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.Panel_8);
            app.ContrastSlider.Limits = [-100 100];
            app.ContrastSlider.MajorTicks = [-100 0 100];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [-100 -96 -92 -88 -84 -80 -76 -72 -68 -64 -60 -56 -52 -48 -44 -40 -36 -32 -28 -24 -20 -16 -12 -8 -4 0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.Panel_3 = uipanel(app.TrainingTab);
            app.ModelReloadButton = uibutton(app.Panel_3, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Text = 'Reload';
            app.ModelDropDownLabel = uilabel(app.Panel_3);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.Panel_3);
            app.ModelDropDown.Items = {'resnet18', 'resnet50', 'xception', 'inceptionv3', 'mobilenetv2', 'densenet201', 'CustomTrainedNetwork'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'resnet18';
            app.ModelLoadedLampLabel = uilabel(app.Panel_3);
            app.ModelLoadedLampLabel.Text = 'Model Loaded';
            app.ModelLoadedLamp = uilamp(app.Panel_3);
            app.Panel_9 = uipanel(app.TrainingTab);
            app.TrainingOptionsButton = uibutton(app.Panel_9, 'state');
            app.TrainingOptionsButton.ValueChangedFcn = createCallbackFcn(app, @TrainingOptionsButtonValueChanged, true);
            app.TrainingOptionsButton.Icon = fullfile(pathToMLAPP, 'Icon', 'setting.png');
            app.TrainingOptionsButton.Text = {'Training'; 'Options'};
            app.StartTrainButton = uibutton(app.Panel_9, 'push');
            app.StartTrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainButtonPushed, true);
            app.StartTrainButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Train.png');
            app.StartTrainButton.Enable = 'off';
            app.StartTrainButton.Text = {'Start'; 'Train'};
            app.SpiitDataPanel = uipanel(app.TrainingTab);
            app.SpiitDataPanel.Title = 'Spiit Data';
            app.TrainingDataEditFieldLabel = uilabel(app.SpiitDataPanel);
            app.TrainingDataEditFieldLabel.Text = 'Training Data';
            app.TrainingDataEditField = uieditfield(app.SpiitDataPanel, 'numeric');
            app.TrainingDataEditField.Value = 80;
            app.ValidationDataLabel = uilabel(app.SpiitDataPanel);
            app.ValidationDataLabel.Text = 'Validation Data';
            app.ValidationDataEditField = uieditfield(app.SpiitDataPanel, 'numeric');
            app.ValidationDataEditField.Value = 10;
            app.TestingDataEditFieldLabel = uilabel(app.SpiitDataPanel);
            app.TestingDataEditFieldLabel.Text = 'Testing Data';
            app.TestingDataEditField = uieditfield(app.SpiitDataPanel, 'numeric');
            app.TestingDataEditField.Value = 10;
            app.CheckLampLabel = uilabel(app.SpiitDataPanel);
            app.CheckLampLabel.Text = 'Check';
            app.CheckLamp = uilamp(app.SpiitDataPanel);
            app.SplitDataButton = uibutton(app.SpiitDataPanel, 'push');
            app.SplitDataButton.ButtonPushedFcn = createCallbackFcn(app, @SplitDataButtonPushed, true);
            app.SplitDataButton.Text = 'Split Data';
            app.ImageAugmentationPanel = uipanel(app.TrainingTab);
            app.ImageAugmentationPanel.Title = 'Image Augmentation';
            app.RandXReflectionEditFieldLabel = uilabel(app.ImageAugmentationPanel);
            app.RandXReflectionEditFieldLabel.Text = 'RandXReflection';
            app.RandXReflectionEditField_R = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RandYReflectionEditFieldLabel = uilabel(app.ImageAugmentationPanel);
            app.RandYReflectionEditFieldLabel.Text = 'RandYReflection';
            app.RandYReflectionEditField_R = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RandXTranslationEditField_L = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RanXTranslationEditFieldLabel = uilabel(app.ImageAugmentationPanel);
            app.RanXTranslationEditFieldLabel.Text = 'RanXTranslation';
            app.RandXTranslationEditField_R = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RandYTranslationEditField_L = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RanYTranslationEditFieldLabel = uilabel(app.ImageAugmentationPanel);
            app.RanYTranslationEditFieldLabel.Text = 'RanYTranslation';
            app.RandYTranslationEditField_R = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RandScaleEditField_L = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RandScaleEditField_L.Value = 1;
            app.RandScaleEditFieldLabel = uilabel(app.ImageAugmentationPanel);
            app.RandScaleEditFieldLabel.Text = 'RandScale ';
            app.RandScaleEditField_R = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RandScaleEditField_R.Value = 1;
            app.RandRotationEditField_L = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.RandRotationEditFieldLabel = uilabel(app.ImageAugmentationPanel);
            app.RandRotationEditFieldLabel.Text = 'RandRotation';
            app.RandRotationEditField_R = uieditfield(app.ImageAugmentationPanel, 'numeric');
            app.AugmentationButton = uibutton(app.ImageAugmentationPanel, 'push');
            app.AugmentationButton.ButtonPushedFcn = createCallbackFcn(app, @AugmentationButtonPushed, true);
            app.AugmentationButton.Text = 'Augmentation';
            app.CheckLamp_2Label = uilabel(app.ImageAugmentationPanel);
            app.CheckLamp_2Label.Text = 'Check';
            app.CheckLamp_2 = uilamp(app.ImageAugmentationPanel);
            app.Panel_10 = uipanel(app.TrainingTab);
            app.Label_2 = uilabel(app.TrainingTab);
            app.Label_2.Text = '';
            app.InferenceTab = uitab(app.TabGroup);
            app.InferenceTab.Title = 'Inference';
            app.Panel_4 = uipanel(app.InferenceTab);
            app.LoadOtherImageFoldButton = uibutton(app.Panel_4, 'push');
            app.LoadOtherImageFoldButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOtherImageFoldButtonPushed, true);
            app.LoadOtherImageFoldButton.Icon = fullfile(pathToMLAPP, 'Icon', 'photo.png');
            app.LoadOtherImageFoldButton.Text = {'Load Other'; 'Image Fold'};
            app.ImageClassifyButton = uibutton(app.Panel_4, 'push');
            app.ImageClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ImageClassifyButtonPushed, true);
            app.ImageClassifyButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Inference.png');
            app.ImageClassifyButton.Text = {'Image'; 'Classify'};
            app.ModelGroupButtonGroup = uibuttongroup(app.Panel_4);
            app.ModelGroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelGroupButtonGroupSelectionChanged, true);
            app.ModelGroupButtonGroup.Title = 'Model Group';
            app.UseTrainedModelButton = uiradiobutton(app.ModelGroupButtonGroup);
            app.UseTrainedModelButton.Text = 'Use Trained Model';
            app.UseTrainedModelButton.Value = true;
            app.AddanothermodelButton = uiradiobutton(app.ModelGroupButtonGroup);
            app.AddanothermodelButton.Text = 'Add another model';
            app.LoadanothermodelButton = uibutton(app.ModelGroupButtonGroup, 'push');
            app.LoadanothermodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadanothermodelButtonPushed, true);
            app.LoadanothermodelButton.Text = {'Load another '; 'model'};
            app.ModelLoadedLamp_2Label = uilabel(app.Panel_4);
            app.ModelLoadedLamp_2Label.Text = 'Model Loaded';
            app.ModelLoadedLamp_2 = uilamp(app.Panel_4);
            app.StartTestDataButton = uibutton(app.Panel_4, 'push');
            app.StartTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestDataButtonPushed, true);
            app.StartTestDataButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Train.png');
            app.StartTestDataButton.Text = {'Start'; 'Test Data'};
            app.UITable2 = uitable(app.InferenceTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.InferencePanel = uipanel(app.InferenceTab);
            app.InferencePanel.Title = 'Inference ';
            app.Label = uilabel(app.InferenceTab);
            app.Label.Text = '';
            app.ExplainedTab = uitab(app.TabGroup);
            app.ExplainedTab.Title = 'Explained';
            app.Panel_11 = uipanel(app.ExplainedTab);
            app.LoadImageButton = uibutton(app.Panel_11, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = fullfile(pathToMLAPP, 'Icon', 'photo.png');
            app.LoadImageButton.Text = {'Load'; 'Image '};
            app.Inference_Panel = uipanel(app.ExplainedTab);
            app.ExplainedImage = uiimage(app.Inference_Panel);
            app.ExplainButton = uibutton(app.ExplainedTab, 'push');
            app.ExplainButton.ButtonPushedFcn = createCallbackFcn(app, @ExplainButtonPushed, true);
            app.ExplainButton.Text = 'Explain';
            app.MethodsDropDownLabel = uilabel(app.ExplainedTab);
            app.MethodsDropDownLabel.Text = 'Methods';
            app.MethodsDropDown = uidropdown(app.ExplainedTab);
            app.MethodsDropDown.Items = {'GradCam', 'LIME'};
            app.MethodsDropDown.Value = 'GradCam';
            app.SwitchLabel = uilabel(app.ExplainedTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.ExplainedTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
            app.Label_3 = uilabel(app.ExplainedTab);
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.Panel_6 = uipanel(app.ExportTab);
            app.ExportModelButton = uibutton(app.Panel_6, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Export.png');
            app.ExportModelButton.Text = {'Export'; 'Model'};
            % Show the figure after all components are created
            app.VIStudio_ClassificationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_ClassificationApp_T1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VIStudio_ClassificationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VIStudio_ClassificationUIFigure)
        end
    end
end