classdef VIStudio_ClassificationTrainingOption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrainingOptionUIFigure          matlab.ui.Figure
        FinishButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        SolverDropDown                  matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        ValidationDataDropDown          matlab.ui.control.DropDown
        ValidationDataDropDownLabel     matlab.ui.control.Label
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationFrequencyEditFieldLabel  matlab.ui.control.Label
        VerboseCheckBox                 matlab.ui.control.CheckBox
        VerboseLabel                    matlab.ui.control.Label
        PlotsDropDown                   matlab.ui.control.DropDown
        PlotsLabel                      matlab.ui.control.Label
        ShuffleDropDown                 matlab.ui.control.DropDown
        ShuffleDropDownLabel            matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        LearnRateScheduleDropDown       matlab.ui.control.DropDown
        LearnRateScheduleDropDownLabel  matlab.ui.control.Label
        LearnRateDropPeriodEditField    matlab.ui.control.NumericEditField
        LearnRateDropPeriodEditFieldLabel  matlab.ui.control.Label
        LearnRateDropFactorEditField    matlab.ui.control.NumericEditField
        LearnRateDropFactorEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp % Description
        
    end
    
    methods (Access = public)
        
        function PositionSetFcn(app)
            %mainAppPos = app.mainApp.VIStudio_ClassificationUIFigure.Position;
            %app.TrainingOptionUIFigure.Position(1) = mainAppPos(1) + mainAppPos(3) ;
            %app.TrainingOptionUIFigure.Position(2) = mainAppPos(2);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            %PositionSetFcn(app)
        end

        % Close request function: TrainingOptionUIFigure
        function TrainingOptionUIFigureCloseRequest(app, event)
            app.mainApp.TrainingOptionsButton.Value = false;
            app.mainApp.TrainingOptionApp = [];
            delete(app)
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            %{
            opts = struct();
            opts.Solver              = app.SolverDropDown.Value;
            opts.MaxEpochs           = app.MaxEpochsEditField.Value;
            opts.MiniBatchSize       = app.MiniBatchSizeEditField.Value;

            % ValidationData 下拉選單可能是 '[]' 或物件名稱
            valSel = app.ValidationDataDropDown.Value;
            if strcmp(valSel, '[]')
                opts.ValidationData = [];
            else
                % 假設主 App 存了 validationDataSrc
                opts.ValidationData = app.mainApp.validationDataSrc;
            end

            opts.ValidationFrequency = app.ValidationFrequencyEditField.Value;
            opts.Verbose             = app.VerboseCheckBox.Value;
            opts.Plots               = app.PlotsDropDown.Value;
            opts.Shuffle             = app.ShuffleDropDown.Value;
            opts.InitialLearnRate    = app.InitialLearnRateEditField.Value;
            opts.LearnRateSchedule   = app.LearnRateScheduleDropDown.Value;
            opts.LearnRateDropPeriod = app.LearnRateDropPeriodEditField.Value;
            opts.LearnRateDropFactor = app.LearnRateDropFactorEditField.Value;
            %}
            % 將選項存回主 App
            %app.mainApp.trainingOptions = opts;

            % 隱藏／關閉該視窗
            app.TrainingOptionUIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrainingOptionUIFigure = uifigure('Visible', 'off');
            app.TrainingOptionUIFigure.Name = 'Training Option';
            app.TrainingOptionUIFigure.CloseRequestFcn = createCallbackFcn(app, @TrainingOptionUIFigureCloseRequest, true);
            app.LearnRateDropFactorEditFieldLabel = uilabel(app.TrainingOptionUIFigure);
            app.LearnRateDropFactorEditFieldLabel.Text = 'LearnRateDropFactor';
            app.LearnRateDropFactorEditField = uieditfield(app.TrainingOptionUIFigure, 'numeric');
            app.LearnRateDropFactorEditField.Limits = [0 1];
            app.LearnRateDropFactorEditField.Tag = 'TrainingOptions';
            app.LearnRateDropFactorEditField.Value = 1;
            app.LearnRateDropPeriodEditFieldLabel = uilabel(app.TrainingOptionUIFigure);
            app.LearnRateDropPeriodEditFieldLabel.Text = 'LearnRateDropPeriod';
            app.LearnRateDropPeriodEditField = uieditfield(app.TrainingOptionUIFigure, 'numeric');
            app.LearnRateDropPeriodEditField.Limits = [0 Inf];
            app.LearnRateDropPeriodEditField.RoundFractionalValues = 'on';
            app.LearnRateDropPeriodEditField.Tag = 'TrainingOptions';
            app.LearnRateDropPeriodEditField.Value = 10;
            app.LearnRateScheduleDropDownLabel = uilabel(app.TrainingOptionUIFigure);
            app.LearnRateScheduleDropDownLabel.Text = 'LearnRateSchedule';
            app.LearnRateScheduleDropDown = uidropdown(app.TrainingOptionUIFigure);
            app.LearnRateScheduleDropDown.Items = {'none', 'piecewise'};
            app.LearnRateScheduleDropDown.Tag = 'TrainingOptions';
            app.LearnRateScheduleDropDown.Value = 'none';
            app.InitialLearnRateEditFieldLabel = uilabel(app.TrainingOptionUIFigure);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.TrainingOptionUIFigure, 'numeric');
            app.InitialLearnRateEditField.Limits = [0 Inf];
            app.InitialLearnRateEditField.Tag = 'TrainingOptions';
            app.InitialLearnRateEditField.Value = 0.01;
            app.ShuffleDropDownLabel = uilabel(app.TrainingOptionUIFigure);
            app.ShuffleDropDownLabel.Text = 'Shuffle';
            app.ShuffleDropDown = uidropdown(app.TrainingOptionUIFigure);
            app.ShuffleDropDown.Items = {'once', 'never', 'every-epoch'};
            app.ShuffleDropDown.Tag = 'TrainingOptions';
            app.ShuffleDropDown.Value = 'once';
            app.PlotsLabel = uilabel(app.TrainingOptionUIFigure);
            app.PlotsLabel.Text = 'Plots';
            app.PlotsDropDown = uidropdown(app.TrainingOptionUIFigure);
            app.PlotsDropDown.Items = {'training-progress', 'none'};
            app.PlotsDropDown.Tag = 'TrainingOptions';
            app.PlotsDropDown.Value = 'training-progress';
            app.VerboseLabel = uilabel(app.TrainingOptionUIFigure);
            app.VerboseLabel.Text = 'Verbose';
            app.VerboseCheckBox = uicheckbox(app.TrainingOptionUIFigure);
            app.VerboseCheckBox.Tag = 'TrainingOptions';
            app.VerboseCheckBox.Text = '';
            app.ValidationFrequencyEditFieldLabel = uilabel(app.TrainingOptionUIFigure);
            app.ValidationFrequencyEditFieldLabel.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField = uieditfield(app.TrainingOptionUIFigure, 'numeric');
            app.ValidationFrequencyEditField.Limits = [0 Inf];
            app.ValidationFrequencyEditField.RoundFractionalValues = 'on';
            app.ValidationFrequencyEditField.Tag = 'TrainingOptions';
            app.ValidationFrequencyEditField.Value = 10;
            app.ValidationDataDropDownLabel = uilabel(app.TrainingOptionUIFigure);
            app.ValidationDataDropDownLabel.Text = 'ValidationData';
            app.ValidationDataDropDown = uidropdown(app.TrainingOptionUIFigure);
            app.ValidationDataDropDown.Items = {'[]'};
            app.ValidationDataDropDown.Tag = 'TrainingOptions';
            app.ValidationDataDropDown.Placeholder = '[]';
            app.ValidationDataDropDown.Value = '[]';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.TrainingOptionUIFigure);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.TrainingOptionUIFigure, 'numeric');
            app.MiniBatchSizeEditField.Limits = [0 Inf];
            app.MiniBatchSizeEditField.RoundFractionalValues = 'on';
            app.MiniBatchSizeEditField.Tag = 'TrainingOptions';
            app.MiniBatchSizeEditField.Value = 32;
            app.MaxEpochsEditFieldLabel = uilabel(app.TrainingOptionUIFigure);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.TrainingOptionUIFigure, 'numeric');
            app.MaxEpochsEditField.Limits = [0 Inf];
            app.MaxEpochsEditField.RoundFractionalValues = 'on';
            app.MaxEpochsEditField.Tag = 'TrainingOptions';
            app.MaxEpochsEditField.Value = 10;
            app.SolverDropDownLabel = uilabel(app.TrainingOptionUIFigure);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.TrainingOptionUIFigure);
            app.SolverDropDown.Items = {'sgdm', 'rmsprop', 'adam', 'lbfgs'};
            app.SolverDropDown.Tag = 'TrainingOptions';
            app.SolverDropDown.Value = 'sgdm';
            app.Panel = uipanel(app.TrainingOptionUIFigure);
            app.FinishButton = uibutton(app.TrainingOptionUIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.TrainingOptionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_ClassificationTrainingOption(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TrainingOptionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TrainingOptionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrainingOptionUIFigure)
        end
    end
end