classdef VIStudio_ObjectDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VIStudio_ObjectDetectionUIFigure  matlab.ui.Figure
        ModelLoadedLamp           matlab.ui.control.Lamp
        ModelLoadedLampLabel      matlab.ui.control.Label
        DataSourceEditField       matlab.ui.control.EditField
        DataSourceEditFieldLabel  matlab.ui.control.Label
        ModelDropDown             matlab.ui.control.DropDown
        ModelDropDownLabel        matlab.ui.control.Label
        StateChangeSwitch         matlab.ui.control.Switch
        ModelReloadButton         matlab.ui.control.Button
        Panel_3                   matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        ObjectDetectionButton     matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        gTruthLoadedLamp          matlab.ui.control.Lamp
        gTruthLoadedLampLabel     matlab.ui.control.Label
        InferenceImageButton      matlab.ui.control.Button
        LoadgTruthButton          matlab.ui.control.Button
        ExportModelButton         matlab.ui.control.Button
        TrainingOptionsButton     matlab.ui.control.StateButton
        StartTrainButton          matlab.ui.control.Button
        Panel                     matlab.ui.container.Panel
        ObjectDetectionLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        TrainingOptionApp % Description
        trainingDict = dictionary
        trainingOptionsName =[...
            "Solver";...
            "MaxEpochs";...
            "MiniBatchSize";...
            "ValidationData";...
            "ValidationFrequency";...
            "Verbose";...
            "Plots";...
            "Shuffle";...
            "InitialLearnRate";...
            "LearnRateSchedule";...
            "LearnRateDropPeriod";...
            "LearnRateDropFactor"
            ];

        trainingOpts

        objDetectionMdl % Description


        trainingImgDataSrc
        trainingBoxDataSrc
        trainingCombDataSrc

        imgResizeSz = [640, 640]

        gTruth
        gTruthLabel

        trainedDetector
        inferenceImg
        inferenceBboxes
        inferenceScores
        inferenceResult

        trainingDataTbl
        testDataTbl

        imdsTrain
        bldsTrain
        imdsTest
        bldsTest

        trainingData
        testData
        classname
        filteredValues

        inputSize

    end

    methods (Access = private)

        function results = createTrainingSrc(app)
            if isempty(app.gTruth)
                return
            end

            % gTruth 第一欄為訓練圖檔位置，第二欄開始為 box label 資訊
            app.trainingImgDataSrc = imageDatastore(app.gTruth.Files, ReadFcn=@(x)preprocessImg(app, x));
            app.trainingBoxDataSrc = boxLabelDatastore(app.gTruth(:,2:end));

            app.trainingCombDataSrc = combine(app.trainingImgDataSrc,...
                app.trainingBoxDataSrc);

        end

        function outputImg = preprocessImg(app, files)
            img = imread(files);
            outputImg = imresize(img, app.imgResizeSz);

        end


        function gatherTrainingOptionsParameters(app)

            % Check if TrainingOptionApp is empty
            if ~isempty(app.TrainingOptionApp)

                subAppFig = app.TrainingOptionApp.TrainingOptionUIFigure;
                trainingOptionObj = findobj(subAppFig,'Tag','TrainingOptions');
                trainingOptionValue = get(trainingOptionObj,'Value');

                app.trainingDict = insert(app.trainingDict,app.trainingOptionsName,trainingOptionValue);

                if isempty(app.trainingDict{'ValidationData'})
                    app.trainingDict{'ValidationData'} = [];
                end

                % Create training options
                app.trainingOpts = trainingOptions(...
                    app.trainingDict{"Solver"},...
                    MaxEpochs = app.trainingDict{"MaxEpochs"},...
                    MiniBatchSize = app.trainingDict{"MiniBatchSize"},...
                    ValidationData = [],...%app.trainingDict{"ValidationData"},...
                    ValidationFrequency = app.trainingDict{"ValidationFrequency"},...
                    Verbose = app.trainingDict{"Verbose"},...
                    Plots = app.trainingDict{"Plots"},...
                    Shuffle = app.trainingDict{"Shuffle"},...
                    InitialLearnRate = app.trainingDict{"InitialLearnRate"},...
                    LearnRateSchedule = app.trainingDict{"LearnRateSchedule"},...
                    LearnRateDropPeriod = app.trainingDict{"LearnRateDropPeriod"},...
                    LearnRateDropFactor = app.trainingDict{"LearnRateDropFactor"}...
                    );
            else
                app.trainingOpts = trainingOptions("sgdm", ...
                    InitialLearnRate=5e-4,...
                    MaxEpochs=50,...
                    MiniBatchSize=16,...
                    ValidationData=app.testData, ...
                    ValidationFrequency=5, ...
                    Plots="training-progress", ...
                    Verbose=false);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)
            ModelDropDownValueChanged(app)
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            ModelType = app.ModelDropDown.Value;

            progressBar = uiprogressdlg(app.VIStudio_ObjectDetectionUIFigure,...
                Message="Loading Detector Model: "+ ModelType,...
                Title="Loading Model",...
                Indeterminate="on");

            app.filteredValues = app.gTruth.Properties.VariableNames;
            app.classname = app.filteredValues(cellfun(@(x) ~strcmp(x, 'imageFilename'), app.filteredValues));

            app.inputSize = size(imread(app.imdsTrain.Files{1}));

            switch ModelType
                case 'YOLOX-nano'
                    detector = yoloxObjectDetector("nano-coco",app.classname,InputSize=app.inputSize);
                case 'YOLOX-tiny'
                    detector = yoloxObjectDetector("tiny-coco",app.classname,InputSize=app.inputSize);
                case 'YOLOX-small'
                    detector = yoloxObjectDetector("small-coco",app.classname,InputSize=app.inputSize);
                case 'YOLOX-medium'
                    detector = yoloxObjectDetector("medium-coco",app.classname,InputSize=app.inputSize);
                case 'YOLOX-large'
                    detector = yoloxObjectDetector("large-coco",app.classname,InputSize=app.inputSize);
            end

            app.objDetectionMdl = detector;


            if ~isempty(app.objDetectionMdl)
                app.ModelLoadedLamp.Color = [0.47,0.67,0.19];
            end
            delete(progressBar)
        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionButtonPushed(app, event)
            if isempty(app.objDetectionMdl)
                return
            elseif ~isempty(app.trainedDetector) && ~isempty(app.inferenceImg)
                [app.inferenceBboxes, app.inferenceScores, app.inferenceResult] = detect(app.trainedDetector, app.inferenceImg);
            else
                [app.inferenceBboxes, app.inferenceScores, app.inferenceResult] = detect(app.objDetectionMdl, app.inferenceImg);
            end

            detectedImg = insertObjectAnnotation(app.inferenceImg,"Rectangle",app.inferenceBboxes,app.inferenceResult);

            imshow(detectedImg, Parent=app.UIAxes)
        end

        % Button pushed function: InferenceImageButton
        function InferenceImageButtonPushed(app, event)
            [file, path] = uigetfile('*.*',MultiSelect='off');
            if isscalar(file) || isscalar(path)
                disp('User discard import file')
                return
            end
            app.inferenceImg = imread(fullfile(path,file));

            imshow(app.inferenceImg, Parent=app.UIAxes)
        end

        % Button pushed function: LoadgTruthButton
        function LoadgTruthButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', MultiSelect='off');
            if isscalar(file) || isscalar(path)
                disp('User discard import file')
                return
            end

            loadedMat = load(fullfile(path, file));
            try
                app.gTruth = loadedMat.exportROI; % gTruth variable from VIStudio_ImageLabeler
            catch
            end

            try
                app.gTruth = loadedMat.gTruth; % Labler table gTruth import
            catch
            end

            if ~isempty(app.gTruth )
                app.gTruthLoadedLamp.Color = [0.47,0.67,0.19];
            end

            % Import label data (split data)
            shuffledIndices = randperm(height( app.gTruth));
            idx = floor(0.8 * height( app.gTruth));

            trainingIdx = 1:idx;
            app.trainingDataTbl =  app.gTruth(shuffledIndices(trainingIdx),:);

            testIdx = trainingIdx(end)+1 : length(shuffledIndices);
            app.testDataTbl =  app.gTruth(shuffledIndices(testIdx),:);

            % Build Datastore
            % Train
            app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
            app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));

            % Test
            app.imdsTest = imageDatastore(app.testDataTbl.imageFilename);
            app.bldsTest = boxLabelDatastore(app.testDataTbl(:,2:end));

            % Combine Datastore
            app.trainingData = combine(app.imdsTrain,app.bldsTrain);
            app.testData = combine(app.imdsTest,app.bldsTest);


        end

        % Button pushed function: StartTrainButton
        function StartTrainButtonPushed(app, event)
            if isempty(app.objDetectionMdl)
                disp('No Model Loaded')
                return
            end
            gatherTrainingOptionsParameters(app)

            [app.trainedDetector, info] = trainYOLOXObjectDetector(...
                app.trainingData,...
                app.objDetectionMdl, ...
                app.trainingOpts,...
                FreezeSubNetwork="none");

        end

        % Value changed function: TrainingOptionsButton
        function TrainingOptionsButtonValueChanged(app, event)
            value = app.TrainingOptionsButton.Value;
            OptionOnOff = app.TrainingOptionsButton.Value;

            switch OptionOnOff
                case true
                    if isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp = VIStudio_ObjectDetectionTrainingOption(app);
                    else
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'On';
                    end
                case false
                    if ~isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'Off';
                    end
            end
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VIStudio_ObjectDetectionUIFigure = uifigure('Visible', 'off');
            app.VIStudio_ObjectDetectionUIFigure.Name = 'VIStudio_ObjectDetection';
            app.Panel = uipanel(app.VIStudio_ObjectDetectionUIFigure);
            app.ObjectDetectionLabel = uilabel(app.Panel);
            app.ObjectDetectionLabel.Text = 'Object Detection';
            app.Panel_2 = uipanel(app.VIStudio_ObjectDetectionUIFigure);
            app.StartTrainButton = uibutton(app.Panel_2, 'push');
            app.StartTrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainButtonPushed, true);
            app.StartTrainButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Train.png');
            app.StartTrainButton.Text = {'Start'; 'Train'};
            app.TrainingOptionsButton = uibutton(app.Panel_2, 'state');
            app.TrainingOptionsButton.ValueChangedFcn = createCallbackFcn(app, @TrainingOptionsButtonValueChanged, true);
            app.TrainingOptionsButton.Icon = fullfile(pathToMLAPP, 'Icon', 'setting.png');
            app.TrainingOptionsButton.Text = {'Training '; 'Options'};
            app.ExportModelButton = uibutton(app.Panel_2, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Export.png');
            app.ExportModelButton.Text = {'Export'; 'Model'};
            app.LoadgTruthButton = uibutton(app.Panel_2, 'push');
            app.LoadgTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadgTruthButtonPushed, true);
            app.LoadgTruthButton.Icon = fullfile(pathToMLAPP, 'Icon', 'groundTruth.png');
            app.LoadgTruthButton.Text = {'Load'; 'gTruth'};
            app.InferenceImageButton = uibutton(app.Panel_2, 'push');
            app.InferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @InferenceImageButtonPushed, true);
            app.InferenceImageButton.Icon = fullfile(pathToMLAPP, 'Icon', 'photo.png');
            app.InferenceImageButton.Text = {'Inference'; 'Image'};
            app.gTruthLoadedLampLabel = uilabel(app.Panel_2);
            app.gTruthLoadedLampLabel.Text = 'gTruth Loaded';
            app.gTruthLoadedLamp = uilamp(app.Panel_2);
            app.ObjectDetectionButton = uibutton(app.VIStudio_ObjectDetectionUIFigure, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionButtonPushed, true);
            app.ObjectDetectionButton.Icon = fullfile(pathToMLAPP, 'Icon', 'eye.png');
            app.ObjectDetectionButton.Text = {'Object'; 'Detection'};
            app.Panel_3 = uipanel(app.VIStudio_ObjectDetectionUIFigure);
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.ModelReloadButton = uibutton(app.VIStudio_ObjectDetectionUIFigure, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Text = 'Reload';
            app.StateChangeSwitch = uiswitch(app.VIStudio_ObjectDetectionUIFigure, 'slider');
            app.StateChangeSwitch.Items = {'Inference', 'Training'};
            app.StateChangeSwitch.Value = 'Inference';
            app.ModelDropDownLabel = uilabel(app.VIStudio_ObjectDetectionUIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.VIStudio_ObjectDetectionUIFigure);
            app.ModelDropDown.Items = {'YOLOX-tiny', 'YOLOX-small', 'CustomTrainedNetwork'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'YOLOX-tiny';
            app.DataSourceEditFieldLabel = uilabel(app.VIStudio_ObjectDetectionUIFigure);
            app.DataSourceEditFieldLabel.Text = 'Data Source';
            app.DataSourceEditField = uieditfield(app.VIStudio_ObjectDetectionUIFigure, 'text');
            app.DataSourceEditField.Editable = 'off';
            app.ModelLoadedLampLabel = uilabel(app.VIStudio_ObjectDetectionUIFigure);
            app.ModelLoadedLampLabel.Text = 'Model Loaded';
            app.ModelLoadedLamp = uilamp(app.VIStudio_ObjectDetectionUIFigure);
            % Show the figure after all components are created
            app.VIStudio_ObjectDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_ObjectDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VIStudio_ObjectDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VIStudio_ObjectDetectionUIFigure)
        end
    end
end