classdef VIStudio_ObjectDetectionApp_T1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VIStudio_ObjectDetectionUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        Panel_9                         matlab.ui.container.Panel
        countIdx                        matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        datastoreLabel                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Panel_5                         matlab.ui.container.Panel
        DataSourceEditField             matlab.ui.control.EditField
        DataSourceEditFieldLabel        matlab.ui.control.Label
        gTruthLoadedLamp                matlab.ui.control.Lamp
        gTruthLoadedLampLabel           matlab.ui.control.Label
        LoadgTruthButton                matlab.ui.control.Button
        TrainingTab                     matlab.ui.container.Tab
        ChageThresholdEditField         matlab.ui.control.NumericEditField
        ChageThresholdEditFieldLabel    matlab.ui.control.Label
        HaveBBoxLamp                    matlab.ui.control.Lamp
        HaveBBoxLampLabel               matlab.ui.control.Label
        Panel_12                        matlab.ui.container.Panel
        countIdx_2                      matlab.ui.control.Label
        Right_TainButton                matlab.ui.control.Button
        left_TainButton                 matlab.ui.control.Button
        datastoreLabel_2                matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Panel_11                        matlab.ui.container.Panel
        CheckLamp_3                     matlab.ui.control.Lamp
        CheckLamp_3Label                matlab.ui.control.Label
        InputSizeButton                 matlab.ui.control.Button
        InputYEditField                 matlab.ui.control.NumericEditField
        InputYEditFieldLabel            matlab.ui.control.Label
        InputXEditField                 matlab.ui.control.NumericEditField
        InputXEditFieldLabel            matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        ModelLoadedLamp                 matlab.ui.control.Lamp
        ModelLoadedLampLabel            matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        ModelReloadButton               matlab.ui.control.Button
        ImageAugmentationPanel          matlab.ui.container.Panel
        Tree                            matlab.ui.container.CheckBoxTree
        jitterColorHSVNode              matlab.ui.container.TreeNode
        HueNode                         matlab.ui.container.TreeNode
        SaturationNode                  matlab.ui.container.TreeNode
        BrightnessNode                  matlab.ui.container.TreeNode
        ContrastNode                    matlab.ui.container.TreeNode
        randomAffine2dNode              matlab.ui.container.TreeNode
        XReflectionNode                 matlab.ui.container.TreeNode
        YReflectionNode                 matlab.ui.container.TreeNode
        ScaleNode                       matlab.ui.container.TreeNode
        RotationNode                    matlab.ui.container.TreeNode
        CheckLamp_2                     matlab.ui.control.Lamp
        CheckLamp_2Label                matlab.ui.control.Label
        AugmentationButton              matlab.ui.control.Button
        SpiitDataPanel                  matlab.ui.container.Panel
        SplitDataButton                 matlab.ui.control.Button
        CheckLamp                       matlab.ui.control.Lamp
        CheckLampLabel                  matlab.ui.control.Label
        TestingDataEditField            matlab.ui.control.NumericEditField
        TestingDataEditFieldLabel       matlab.ui.control.Label
        ValidationDataEditField         matlab.ui.control.NumericEditField
        ValidationDataEditFieldLabel    matlab.ui.control.Label
        TrainingDataEditField           matlab.ui.control.NumericEditField
        TrainingDataEditFieldLabel      matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        TrainingOptionsButton           matlab.ui.control.StateButton
        StartTrainButton                matlab.ui.control.Button
        InferenceTab                    matlab.ui.container.Tab
        HaveBBoxLamp_2                  matlab.ui.control.Lamp
        HaveBBoxLamp_2Label             matlab.ui.control.Label
        ChageThresholdEditField_3       matlab.ui.control.NumericEditField
        ChageThresholdEditField_3Label  matlab.ui.control.Label
        ChageThresholdEditField_2       matlab.ui.control.NumericEditField
        ChageThresholdEditField_2Label  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Panel_7                         matlab.ui.container.Panel
        ModelLoadedLamp_2               matlab.ui.control.Lamp
        ModelLoadedLamp_2Label          matlab.ui.control.Label
        StartTestDataButton             matlab.ui.control.Button
        ModelGroupButtonGroup           matlab.ui.container.ButtonGroup
        LoadanothermodelButton          matlab.ui.control.Button
        AddanothermodelButton           matlab.ui.control.RadioButton
        UseTrainedModelButton           matlab.ui.control.RadioButton
        InferenceImageButton            matlab.ui.control.Button
        ObjectDetectionButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ExportTab                       matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        ExportModelButton               matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        ObjectDetectionLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        TrainingOptionApp % Description
        trainingDict = dictionary
        trainingOptionsName =[...
            "Solver";...
            "MaxEpochs";...
            "MiniBatchSize";...
            "ValidationData";...
            "ValidationFrequency";...
            "Verbose";...
            "Plots";...
            "Shuffle";...
            "InitialLearnRate";...
            "LearnRateSchedule";...
            "LearnRateDropPeriod";...
            "LearnRateDropFactor"
            ];

        trainingOpts

        objDetectionMdl % Description

        trainingImgDataSrc
        trainingBoxDataSrc
        trainingCombDataSrc

        imgResizeSz = [640, 640]

        gTruth
        gTruthLabel

        trainedDetector
        inferenceImg
        inferenceBboxes
        inferenceScores
        inferenceResult

        trainingDataTbl
        validationDataTbl
        testDataTbl

        imdsTrain
        bldsTrain
        imdsVal
        bldsVal
        imdsTest
        bldsTest

        trainingData
        testData
        classname
        filteredValues

        inputSize
        
        % Load Data Page
        dsIdx %決定會讀取到第幾張照片，並且要加上邊界值
        dsImg %讀取的影像
        DataImg % 在data page 顯示的影像
        currentIdx

        % Train Page
        trainNum

        %
        opts %影像擴增參數
        dsTrainAug %做影像增強
        dsTrainPrep %做物件框調整
        dsValPrep

        % Training
        trainingInfo %訓練資訊Info
        trainDetectedImg  %訓練後辨識的影像
        trainIdx
        traincurrentIdx


    end

    methods (Access = private)

        function results = createTrainingSrc(app)
            if isempty(app.gTruth)
                return
            end

            % gTruth 第一欄為訓練圖檔位置，第二欄開始為 box label 資訊
            app.trainingImgDataSrc = imageDatastore(app.gTruth.Files, ReadFcn=@(x)preprocessImg(app, x));
            app.trainingBoxDataSrc = boxLabelDatastore(app.gTruth(:,2:end));

            app.trainingCombDataSrc = combine(app.trainingImgDataSrc,...
                app.trainingBoxDataSrc);

        end

        function outputImg = preprocessImg(app, files)
            img = imread(files);
            outputImg = imresize(img, app.imgResizeSz);

        end


        function gatherTrainingOptionsParameters(app)

            % metric test
            %metric1 = recallMetric(AverageType="macro");
            %metric2 = accuracyMetric(AverageType="macro");
            metric = mAPObjectDetectionMetric(Name="mAP50", OverlapThreshold=0.50);
            %metric4 = accuracyMetric(NumTopKClasses=1,Name="Top1Accuracy");

            % Check if TrainingOptionApp is empty
            if ~isempty(app.TrainingOptionApp)

                subAppFig = app.TrainingOptionApp.TrainingOptionUIFigure;
                trainingOptionObj = findobj(subAppFig,'Tag','TrainingOptions');
                trainingOptionValue = get(trainingOptionObj,'Value');

                app.trainingDict = insert(app.trainingDict,app.trainingOptionsName,trainingOptionValue);

                if isempty(app.trainingDict{'ValidationData'})
                    app.trainingDict{'ValidationData'} = [];
                end
                

                % Create training options
                app.trainingOpts = trainingOptions(...
                    app.trainingDict{"Solver"},...
                    MaxEpochs = app.trainingDict{"MaxEpochs"},...
                    MiniBatchSize = app.trainingDict{"MiniBatchSize"},...
                    ValidationData = app.dsValPrep,...%app.trainingDict{"ValidationData"},...
                    ValidationFrequency = app.trainingDict{"ValidationFrequency"},...
                    Verbose = app.trainingDict{"Verbose"},...
                    Plots = app.trainingDict{"Plots"},...
                    Shuffle = app.trainingDict{"Shuffle"},...
                    InitialLearnRate = app.trainingDict{"InitialLearnRate"},...
                    LearnRateSchedule = app.trainingDict{"LearnRateSchedule"},...
                    LearnRateDropPeriod = app.trainingDict{"LearnRateDropPeriod"},...
                    LearnRateDropFactor = app.trainingDict{"LearnRateDropFactor"},...
                    Metrics = metric...
                    );
            else
                app.trainingOpts = trainingOptions("sgdm", ...
                    InitialLearnRate=0.001,...
                    MaxEpochs=20,...
                    MiniBatchSize=16,...
                    ValidationData= app.dsValPrep, ...
                    ValidationFrequency = 5, ...
                    Shuffle ="every-epoch", ... 
                    Plots="training-progress", ...
                    Metrics = metric,...
                    Verbose=false);
            end
        end


        %載入資料時的ReadImg
        function ReadImg(app, dsIdx)

            app.dsIdx = dsIdx;
            % 1) 讀第一列影像
            imgPath = app.gTruth.imageFilename{app.dsIdx};   % 路徑字串
            app.dsImg = imread(imgPath);

            % 2) 蒐集所有標記欄位（除了 imageFilename）
            labelVars = app.gTruth.Properties.VariableNames;
            labelVars = setdiff(labelVars, {'imageFilename'});  % 例如 {'r1','r2',...}

            allBboxes = [];           % Nx4 [x y w h]
            allLabels = strings(0,1); % Nx1 label 字串

            % 3) 將每個標記欄位的框取出並累加
            for k = 1:numel(labelVars)
                varName = labelVars{k};
                % 這一列該欄位通常是「cell 裡面放 Mx4 double 或 []」
                boxes = app.gTruth.(varName){dsIdx};   % 取第一列
                if ~isempty(boxes)
                    % boxes 可能是 1x4 或 Mx4；統一接到 allBboxes
                    allBboxes = [allBboxes; boxes]; %#ok<AGROW>
                    % 同數量的標籤名（用欄位名當類別）
                    allLabels  = [allLabels; repmat(string(varName), size(boxes,1), 1)]; %#ok<AGROW>
                end
            end

            % 4) 疊合標記框與標籤（若沒有框就直接顯示原圖）
            if ~isempty(allBboxes)
                app.DataImg = insertObjectAnnotation(app.dsImg, 'rectangle', allBboxes, cellstr(allLabels), ...
                    'FontSize', 14, 'LineWidth', 3);
            else
                app.DataImg = app.dsImg;
                warning('第一張影像沒有任何方框標記。');
            end

            % 5) 顯示
            app.Image.ImageSource = app.DataImg;

            % 6) 顯示在上方Data Source
            app.DataSourceEditField.Value = app.gTruth.imageFilename{app.dsIdx};

        end


        % 訓練資料時的ReadImg
        function TrainReadImg(app, trainIdx)
           
            app.trainIdx = trainIdx;

            % 1) 讀資料集影像
            imgPath = app.gTruth.imageFilename{app.trainIdx};   % 路徑字串
            app.dsImg = imread(imgPath);
            
            % ) Inference Image
            [trainBoxes,trainScores,trainLabels] = detect( app.trainedDetector,app.dsImg,Threshold = app.ChageThresholdEditField.Value);
            app.trainDetectedImg = insertObjectAnnotation(app.dsImg,"rectangle",trainBoxes,trainLabels);

            % 3) 顯示
            app.Image2.ImageSource = app.trainDetectedImg;

            % 4) 改變燈號
            if isempty(trainBoxes)
                app.HaveBBoxLamp.Color = [1 0 0];
            else
                app.HaveBBoxLamp.Color = [0 1 0];
            end
        end


        %% 影像擴充
        function data = augmentData(app, A)

            % 一些內建的安全預設（不放進 opts，函式內部自管）
            overlapThreshold = 0.25;
            boundsStyle      = 'centerOutput';
            interpMethod     = 'bilinear';
            fillValue        = 0;

            data = cell(size(A));
            for ii = 1:size(A,1)
                I      = A{ii,1};
                bboxes = A{ii,2};
                labels = A{ii,3};
                sz     = size(I);

                % ----- 顏色抖動（只對 RGB） -----
                if isfield(app,'opts') && app.opts.EnableColorJitter ...
                        && numel(sz)==3 && sz(3)==3
                    I = jitterColorHSV(I, ...
                        'Hue',        app.opts.Hue, ...
                        'Saturation', app.opts.Saturation, ...
                        'Brightness', app.opts.Brightness, ...
                        'Contrast',   app.opts.Contrast);
                end

                % ----- 幾何形變 -----
                if isfield(app,'opts') && app.opts.EnableAffine
                    tform = randomAffine2d( ...
                        'XReflection', app.opts.XReflection, ...
                        'YReflection', app.opts.YReflection, ...
                        'Scale',       app.opts.ScaleRange, ...
                        'Rotation',    app.opts.RotationRange);

                    % 依原圖大小建立輸出參考座標
                    rout = affineOutputView(sz, tform, 'BoundsStyle', boundsStyle);

                    % 影像形變
                    I = imwarp(I, tform, ...
                        'OutputView', rout, ...
                        'InterpolationMethod', interpMethod, ...
                        'FillValues', fillValue);

                    % 框同步形變 + 依重疊門檻過濾
                    [bboxes, idx] = bboxwarp(bboxes, tform, rout, 'OverlapThreshold', overlapThreshold);
                    labels = labels(idx);

                    % 若全部框被移除，就回傳原始樣本（避免整筆丟失）
                    if isempty(idx)
                        data(ii,:) = A(ii,:);
                        continue
                    end
                end

                % 回填
                data(ii,:) = {I, bboxes, labels};
            end
        end

        %%
        function data = preprocessData(app, A)
            % 將影像與框等比縮放到 app.inputSize = [H W 3 或 2]
            targetH = app.inputSize(1);
            targetW = app.inputSize(2);

            data = cell(size(A));
            for ii = 1:size(A,1)
                I      = A{ii,1};
                bboxes = A{ii,2};
                labels = A{ii,3};

                [h, w, ~] = size(I);
                % 直接縮放到固定大小（會改變長寬比；如需 letterbox 可改）
                I2 = imresize(I, [targetH targetW]);

                % 對應縮放比
                sx = targetW / w;
                sy = targetH / h;

                if ~isempty(bboxes)
                    bboxes(:,1) = bboxes(:,1) * sx; % x
                    bboxes(:,2) = bboxes(:,2) * sy; % y
                    bboxes(:,3) = bboxes(:,3) * sx; % w
                    bboxes(:,4) = bboxes(:,4) * sy; % h
                    % 夾在影像內，避免負值
                    bboxes(:,1) = max(bboxes(:,1), 1);
                    bboxes(:,2) = max(bboxes(:,2), 1);
                    bboxes(:,3) = max(bboxes(:,3), 1);
                    bboxes(:,4) = max(bboxes(:,4), 1);
                    % 也可加上寬高不超界的裁切邏輯（需要再補我可以加）
                end

                data(ii,:) = {I2, bboxes, labels};
            end
        end

%%
        function plotTrainingCurves(app, info)
            % 安全取得欄位
            it   = getfield_safe(info,'Iteration',[]);
            tl   = getfield_safe(info,'TrainingYoloXLoss',[]);
            vl   = getfield_safe(info,'ValidationLoss',[]);
            lr   = getfield_safe(info,'LearnRate',[]);  % 名稱可能為 LearnRate / BaseLearningRate

            if isempty(it) || isempty(tl)
                return
            end

            % 建圖：用新 figure（若你有 UIAxes 可改到 UIAxes）
            f = figure('Name','Training curves','NumberTitle','off');
            tiledlayout(2,1,'Padding','compact','TileSpacing','compact');

            nexttile
            plot(it, tl,'-'); hold on
            if ~isempty(vl), plot(it, vl,'-'); legend('Train Loss','Val Loss','Location','best'); else, legend('Train Loss'); end
            grid on; xlabel('Iteration'); ylabel('Loss'); title('Loss vs Iteration');

            nexttile
            if ~isempty(lr)
                plot(it, lr,'-'); grid on; xlabel('Iteration'); ylabel('Learning Rate'); title('LR schedule');
            else
                text(0.5,0.5,'No learning-rate info','HorizontalAlignment','center'); axis off
            end

        function v = getfield_safe(s,name,default)
            if isstruct(s) && isfield(s,name), v = s.(name); else, v = default; end
        end
        end

%%
        function out = evaluateOnTest(app, detector)
            % 參考 IoU 門檻，可做成 UI 可調
            iouThr = 0.50;
            N = numel(app.imdsTest.Files);

            % 蒐集 detectionResults：每張影像 -> {Boxes, Scores, Labels}
            Boxes = cell(N,1); Scores = cell(N,1); Labels = cell(N,1);

            reset(app.imdsTest);
            for i = 1:N
                I = readimage(app.imdsTest,i);
                [b, s, l] = detect(detector, I);   %#ok<*NASGU>
                Boxes{i}  = b;
                Scores{i} = s;
                Labels{i} = l;
            end
            detectionResults = table(Boxes, Scores, Labels);

            % 用 test 的 ground truth（你前面切割後的表格）
            gtTbl = app.testDataTbl;

            % 評估（會回多類別 AP，另給 PR 曲線資料）
            [ap, recall, precision] = evaluateDetectionPrecision(detectionResults, gtTbl, iouThr);

            % 類別名稱（從 bldsTest 讀比較穩）
            classNames = [];
            try
                lblDef = app.bldsTest.LabelData; %#ok<NASGU>
                % 當不同 MATLAB 版本可能取不到；保險起見用 table 欄名剖析
                classNames = setdiff(gtTbl.Properties.VariableNames, 'imageFilename','stable');
            catch
                classNames = setdiff(gtTbl.Properties.VariableNames, 'imageFilename','stable');
            end
            if iscell(classNames), classNames = string(classNames); else, classNames = string(classNames); end

            % mAP
            mAP = mean(ap,'omitnan');

            % 畫 PR 曲線（新 figure；若你有 UIAxes 也可改）
            f = figure('Name',sprintf('PR@%.2f (mAP=%.3f)',iouThr,mAP),'NumberTitle','off');
            hold on; grid on
            for k = 1:numel(ap)
                plot(recall{k}, precision{k});
            end
            xlabel('Recall'); ylabel('Precision');
            title(sprintf('Precision-Recall (IoU=%.2f), mAP=%.3f', iouThr, mAP));
            if ~isempty(classNames) && numel(classNames)==numel(ap)
                leg = arrayfun(@(i)sprintf('%s AP=%.3f',classNames(i),ap(i)), 1:numel(ap), 'uni',0);
                legend(leg,'Interpreter','none','Location','southwest');
            else
                legend(arrayfun(@(i)sprintf('Class %d AP=%.3f',i,ap(i)),1:numel(ap),'uni',0), ...
                    'Location','southwest');
            end

            % 回傳
            out = struct('AP',ap,'mAP',mAP,'Recall',recall,'Precision',precision,'IoU',iouThr,'Classes',classNames);
        end


%%
        function showSampleDetections(app, detector, k)
            if nargin<3, k = 4; end
            N = min(k, numel(app.imdsTest.Files));

            % 用 UIPanel/UIAxes 顯示（若沒有就新建 figure）
            useUI = isprop(app,'DetectionPanel') || isprop(app,'UIFigure');

            if ~useUI
                figure('Name','Detections','NumberTitle','off');
                tiledlayout(ceil(N/2),2,'TileSpacing','compact');
            end

            for i = 1:N
                I = readimage(app.imdsTest,i);
                [bboxes,scores,labels] = detect(detector,I);
                if ~isempty(bboxes)
                    ann = strcat(string(labels), " (", string(round(scores*100)), "%)");
                    J = insertObjectAnnotation(I,'rectangle',bboxes, cellstr(ann));
                else
                    J = I;
                end

                if useUI
                    % 若你在 App 有 UIAxesDetection1/2/3/4，可改成指定軸
                    f = figure('Name',sprintf('Detection %d',i),'NumberTitle','off'); %#ok<UNRCH>
                    imshow(J);
                    title(sprintf('Test #%d',i));
                else
                    nexttile; imshow(J); title(sprintf('Test #%d',i));
                end
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModelReloadButton
        function ModelReloadButtonPushed(app, event)
            ModelDropDownValueChanged(app)


        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            ModelType = app.ModelDropDown.Value;


            % 開進度條（一定會關）
            progressBar = uiprogressdlg(app.VIStudio_ObjectDetectionUIFigure, ...
                "Message", "Loading Detector Model: " + ModelType, ...
                "Title",   "Loading Model", ...
                "Indeterminate", true);
            cleanupObj = onCleanup(@() safeClose(progressBar));  % <- 確保刪除

            try
                % 1) 檢查模型選項
                validModels = ["YOLOX-nano","YOLOX-tiny","YOLOX-small","YOLOX-medium","YOLOX-large"];
                if ~ismember(ModelType, validModels)
                    error("Cfg:ModelType","未選擇或不支援的模型類型：%s", ModelType);
                end

                % 2) 檢查並取得類別名稱（支援 table 或 groundTruth）
                if isempty(app.gTruth)
                    error("Cfg:NoGT","尚未載入 gTruth。");
                end

                if istable(app.gTruth)
                    vars = app.gTruth.Properties.VariableNames;
                elseif isa(app.gTruth, "groundTruth")
                    vars = app.gTruth.LabelData.Properties.VariableNames;
                else
                    error("Cfg:GTType","gTruth 型別不支援（%s）。", class(app.gTruth));
                end

                % 排除非標籤欄位
                vars = setdiff(vars, {'imageFilename','SceneLabel'}, 'stable');
                if isempty(vars)
                    error("Cfg:NoClasses","gTruth 中沒有物件標籤欄位。");
                end
                app.filteredValues = vars;
                app.classname = string(vars);

                % 3) 準備影像來源取得 InputSize
                files = [];
                if isprop(app,"imdsTrain") && ~isempty(app.imdsTrain) && ~isempty(app.imdsTrain.Files)
                    files = app.imdsTrain.Files;
                else
                    % 從 gTruth 回推影像清單
                    if istable(app.gTruth)
                        files = app.gTruth.imageFilename;
                    else
                        files = app.gTruth.DataSource.Source;   % groundTruth
                    end
                end

                files = cellstr(files);
                idx = find(cellfun(@(p) isfile(p), files), 1, 'first');
                if isempty(idx)
                    error("Cfg:NoImages","找不到任何可讀取的影像檔。");
                end


                % 4) 依 ModelType 建立偵測器
                switch ModelType
                    case "YOLOX-nano"
                        detector = yoloxObjectDetector("nano-coco",  app.classname, InputSize=app.inputSize);
                    case "YOLOX-tiny"
                        detector = yoloxObjectDetector("tiny-coco",  app.classname, InputSize=app.inputSize);
                    case "YOLOX-small"
                        detector = yoloxObjectDetector("small-coco", app.classname, InputSize=app.inputSize);
                    case "YOLOX-medium"
                        detector = yoloxObjectDetector("medium-coco",app.classname, InputSize=app.inputSize);
                    case "YOLOX-large"
                        detector = yoloxObjectDetector("large-coco", app.classname, InputSize=app.inputSize);
                end

                % 5) 成功：保存模型、點綠燈
                app.objDetectionMdl = detector;
                if ~isempty(app.objDetectionMdl)
                    app.ModelLoadedLamp.Color = [0 1 0]; % 綠
                end

                % 把主視窗帶回前景
                drawnow;
                app.VIStudio_ObjectDetectionUIFigure.WindowState = 'normal';

            catch ME
                % 失敗：清空模型、點紅燈並提示
                app.objDetectionMdl = [];
                app.ModelLoadedLamp.Color = [0.85,0.33,0.10];     % 紅/橘
                app.VIStudio_ObjectDetectionUIFigure.WindowState = 'normal';
                uialert(app.VIStudio_ObjectDetectionUIFigure, ...
                    sprintf("載入模型失敗：\n%s", getReport(ME,'basic')), ...
                    "Loading Error", "Icon","error");
                return
            end

            % （progressBar 會由 cleanupObj 自動刪掉）
            % 若想顯示成功提示，可加：
            % uialert(app.VIStudio_ObjectDetectionUIFigure,'Model loaded','Done','Icon','success');

            % ---- 小工具：安全關閉進度條 ----
            function safeClose(p)
                try
                    if ~isempty(p) && isvalid(p), delete(p); end
                catch
                end
            end

        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionButtonPushed(app, event)
            if isempty(app.objDetectionMdl)
                return
            elseif ~isempty(app.trainedDetector) && ~isempty(app.inferenceImg)
                [app.inferenceBboxes, app.inferenceScores, app.inferenceResult] = detect(app.trainedDetector, app.inferenceImg, Threshold = app.ChageThresholdEditField_3.Value);
            else
                [app.inferenceBboxes, app.inferenceScores, app.inferenceResult] = detect(app.objDetectionMdl, app.inferenceImg, Threshold = app.ChageThresholdEditField_3.Value);
            end

            if ~isempty(app.inferenceBboxes)
                app.HaveBBoxLamp_2.Color = [0 1 0];
            else
                 app.HaveBBoxLamp_2.Color = [1 0 0];
            end


            detectedImg = insertObjectAnnotation(app.inferenceImg,"Rectangle",app.inferenceBboxes,app.inferenceResult);
            
            app.Image3.ImageSource = detectedImg;
        end

        % Button pushed function: InferenceImageButton
        function InferenceImageButtonPushed(app, event)
            [file, path] = uigetfile('*.*',MultiSelect='off');
            if isscalar(file) || isscalar(path)
                disp('User discard import file')
                return
            end
            app.inferenceImg = imread(fullfile(path,file));
            
            app.Image3.ImageSource = app.inferenceImg;
        end

        % Button pushed function: LoadgTruthButton
        function LoadgTruthButtonPushed(app, event)
            %== 選檔 ==
            [file, path] = uigetfile('*.mat', 'Select gTruth MAT', 'MultiSelect','off');
            if isequal(file,0) || isequal(path,0)
                % 使用者取消
                app.gTruth = [];
                app.gTruthLoadedLamp.Color = [0.85 0.33 0.10];   % 紅/橘燈
                app.VIStudio_ObjectDetectionUIFigure.WindowState = 'normal';
                drawnow;
                return
            end

            %== 讀檔 ==
            matFile = fullfile(path, file);
            try
                loadedMat = load(matFile);
            catch ME
                app.gTruth = [];
                app.gTruthLoadedLamp.Color = [0.85 0.33 0.10];
                app.VIStudio_ObjectDetectionUIFigure.WindowState = 'normal';
                uialert(app.VIStudio_ObjectDetectionUIFigure, ...
                    ['讀取 MAT 檔失敗：' ME.message], '讀取失敗', 'Icon','error');
                return
            end

           
            if isfield(loadedMat,'gTruth')
                app.gTruth = loadedMat.gTruth;
            elseif isfield(S,'exportROI')
                app.gTruth = S.exportROI;   % VIStudio_ImageLabeler 匯出
            else
                app.gTruth = [];
                app.gTruthLoadedLamp.Color = [0.85 0.33 0.10];
                app.VIStudio_ObjectDetectionUIFigure.WindowState = 'normal';
                uialert(app.VIStudio_ObjectDetectionUIFigure, ...
                    '檔案中找不到變數 gTruth 或 exportROI。', '格式不符', 'Icon','warning');
                return
            end

         
            try
                app.gTruth = loadedMat.exportROI; % gTruth variable from VIStudio_ImageLabeler
            catch
            end

            try
                app.gTruth = loadedMat.gTruth; % Labler table gTruth import
            catch
            end

            %=======讀圖========           
            ReadImg(app,1)
             
            %===================
            app.gTruthLoadedLamp.Color = [0 1 0];
            %=================
            app.VIStudio_ObjectDetectionUIFigure.WindowState = 'normal';
 
            uialert(app.VIStudio_ObjectDetectionUIFigure,'gTruth loaded','Done','Icon','success');
            
        end

        % Button pushed function: StartTrainButton
        function StartTrainButtonPushed(app, event)
            try
                %== 1) 基本檢查 ==
                if isempty(app.objDetectionMdl)
                    uialert(app.UIFigure,'No model loaded.','Train');
                    return
                end
                gatherTrainingOptionsParameters(app);  % 既有的函式：填好 app.trainingOpts

                %== 2) 構建 / 檢查訓練與驗證 Datastore（偵測任務請用 transform，而非 augmentedImageDatastore）
                if ~isprop(app,'dsTrainPrep') || isempty(app.dsTrainPrep) || ...
                        ~isprop(app,'dsValPrep')   || isempty(app.dsValPrep)

                    % 先確認基礎 imds / blds 已就緒（前面切割那段會建好）
                    assert(~isempty(app.imdsTrain) && ~isempty(app.bldsTrain), 'Training datastores are empty.');
                    assert(~isempty(app.imdsVal)   && ~isempty(app.bldsVal),   'Validation datastores are empty.');

                    dsTrain = combine(app.imdsTrain, app.bldsTrain);
                    dsVal   = combine(app.imdsVal,   app.bldsVal);

                    % 讀 Tree → 產生最小 opts（若已有別處呼叫，可移除）
                    if ismethod(app,'syncOptsFromTree_Min')
                        app.syncOptsFromTree_Min();
                    end

                    % 資料增強 + 前處理到網路輸入尺寸
                    app.dsTrainAug      = transform(dsTrain, @(d) app.augmentData(d));
                    app.dsTrainPrep = transform(app.dsTrainAug, @(d) app.preprocessData(d));
                    app.dsValPrep   = transform(dsVal,      @(d) app.preprocessData(d));
                end

                %== 3) 執行訓練 ==
                t = tic;
                [detector, info] = trainYOLOXObjectDetector( ...
                    app.dsTrainPrep, ...
                    app.objDetectionMdl, ...
                    app.trainingOpts, ...
                    "FreezeSubNetwork","none");   % 可依需求改 'backbone'/'neck'/...

                elapsed = toc(t);
                app.trainedDetector = detector;
                app.trainingInfo    = info;

                %
                app.trainIdx = 1;
                TrainReadImg(app, app.trainIdx )
                app.ModelLoadedLamp_2.Color = [0 1 0];

                %== 4) 視覺化訓練曲線 ==
                %app.plotTrainingCurves(info);  % ②

                %{
                %== 5) 在 Test 集做評估（mAP / PR）並顯示幾張偵測結果 ==
                if ~isempty(app.imdsTest) && ~isempty(app.bldsTest)
                    evalOut = app.evaluateOnTest(detector); % ③：回傳 mAP、AP、classNames...
                    app.lastEval = evalOut;                 % 存起來給 UI 其他地方用
                    app.showSampleDetections(detector, 4);  % ④：示範顯示 4 張結果
                    msg = sprintf('Training done in %.1f min. mAP@0.50 = %.3f', elapsed/60, evalOut.mAP);
                else
                    msg = sprintf('Training done in %.1f min.', elapsed/60);
                end

                if isprop(app,'StatusTextArea'), app.StatusTextArea.Value = msg; end
                if isprop(app,'CheckLamp'),      app.CheckLamp.Color      = [0 0.6 0]; end
                %}

            catch ME
                if isprop(app,'CheckLamp'), app.CheckLamp.Color=[1 0 0]; end
                uialert(app.VIStudio_ObjectDetectionUIFigure, ME.message, 'Train Failed');
            end
                

        end

        % Value changed function: TrainingOptionsButton
        function TrainingOptionsButtonValueChanged(app, event)
            value = app.TrainingOptionsButton.Value;
            OptionOnOff = app.TrainingOptionsButton.Value;

            switch OptionOnOff
                case true
                    if isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp = VIStudio_ObjectDetectionTrainingOption(app);
                    else
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'On';
                    end
                case false
                    if ~isempty(app.TrainingOptionApp)
                        app.TrainingOptionApp.TrainingOptionUIFigure.Visible = 'Off';
                    end
            end
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            if ~isempty(app.trainedDetector)
                [file,location] = uiputfile('.*', 'Save trained model', 'trainedModel.mat');
                model = app.trainedDetector;
                save(fullfile(location,file), 'model')
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            

            app.currentIdx = app.dsIdx+1;
            if(app.currentIdx >numel(app.gTruth.imageFilename))
                app.currentIdx = numel(app.gTruth.imageFilename);
            end

            app.countIdx.Text = sprintf('%d / %d', app.currentIdx, numel(app.gTruth.imageFilename));

            folderpath = app.gTruth.imageFilename{app.currentIdx};
            [~, lastFileName, ~] = fileparts(folderpath);
            app.datastoreLabel.Text = lastFileName;

            % 顯示目前影像
            ReadImg(app, app.currentIdx);
            

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            app.currentIdx = app.dsIdx -1;
            if(app.currentIdx < 1)
                app.currentIdx = 1;
            end

            app.countIdx.Text = sprintf('%d / %d', app.currentIdx, numel(app.gTruth.imageFilename));

            folderpath = app.gTruth.imageFilename{app.currentIdx};
            [~, lastFileName, ~] = fileparts(folderpath);
            app.datastoreLabel.Text = lastFileName;

            % 顯示目前影像
            ReadImg(app, app.currentIdx);
            

        end

        % Button pushed function: SplitDataButton
        function SplitDataButtonPushed(app, event)
            
            % —— 基本檢查 ——
            if ~isprop(app,'gTruth') || isempty(app.gTruth) || ~istable(app.gTruth)
                error('app.gTruth 必須是非空的 table。');
            end

            nTotal = height(app.gTruth);
            app.trainNum = nTotal;

            if nTotal < 3
                error('資料筆數過少（%d），無法切出 train/val/test 三組。', nTotal);
            end

            % 讀取 UI 比例（假設為數值百分比）
            trainPct = double(app.TrainingDataEditField.Value);
            valPct   = double(app.ValidationDataEditField.Value);
            testPct  = double(app.TestingDataEditField.Value);

            % 非負與上限檢查
            if any([trainPct, valPct, testPct] < 0) || any([trainPct, valPct, testPct] > 100)
                error('比例必須介於 0 到 100 之間。');
            end

            % 加總 100%（容差）
            if abs((trainPct + valPct + testPct) - 100) > 1e-6
                if isprop(app,'CheckLamp'), app.CheckLamp.Color = [1 0 0]; end
                uialert(app.VIStudio_ObjectDetectionUIFigure, 'Training + Validation + Testing 必須加總為 100！', '比例錯誤');
                return;
            end

            % 轉小數
            trainFrac = trainPct / 100;
            valFrac   = valPct   / 100;
            testFrac  = testPct  / 100;

            % —— RNG（可重現/隨機）——
            try
                if isprop(app,'RandomSeedEditField') && ~isempty(app.RandomSeedEditField.Value)
                    rng(double(app.RandomSeedEditField.Value), 'twister');
                else
                    rng('shuffle');
                end
            catch
                rng('shuffle');
            end

            % —— 生成切割索引（含殘差處理）——
            shuffledIndices = randperm(nTotal);

            nTrain = floor(trainFrac * nTotal);
            nVal   = floor(valFrac   * nTotal);
            % 將餘數全部丟給 test，保證三者總和為 nTotal
            nTest  = nTotal - (nTrain + nVal);

            % 若某一組設定 >0% 卻因四捨五入變成 0，做最低 1 筆修正
            if trainPct > 0 && nTrain == 0, nTrain = 1; nTest = nTest - 1; end
            if valPct   > 0 && nVal   == 0, nVal   = 1; nTest = nTest - 1; end
            if testPct  > 0 && nTest  == 0
                % 從較大的前兩組借一筆
                if nTrain >= nVal && nTrain > 1
                    nTrain = nTrain - 1; nTest = 1;
                elseif nVal > 1
                    nVal = nVal - 1; nTest = 1;
                else
                    error('比例太極端，導致無法分配至少 1 筆給 Testing。');
                end
            end

            % 實際切割
            trainIdx = shuffledIndices(1:nTrain);
            valIdx   = shuffledIndices(nTrain+1 : nTrain+nVal);
            testIdx  = shuffledIndices(nTrain+nVal+1 : nTotal);

            app.trainingDataTbl   = app.gTruth(sort(trainIdx), :);
            app.validationDataTbl = app.gTruth(sort(valIdx), :);
            app.testDataTbl       = app.gTruth(sort(testIdx), :);

            % 基本不變式檢查（互斥 + 無重疊 + 覆蓋全部）
            if numel(unique([trainIdx,valIdx,testIdx])) ~= nTotal
                error('索引切割發生重疊或遺漏，請重試或檢查資料表。');
            end

            %% —— 自動辨識欄位與任務型態（偵測 vs 分類）——
            varNames = app.gTruth.Properties.VariableNames;

            % 影像路徑欄位：優先找 imageFilename；否則假定第一欄
            if ismember('imageFilename', varNames)
                imgCol = 'imageFilename';
            else
                imgCol = varNames{1};
            end

            % 基本型別檢查：影像欄必須可轉成字串路徑
            paths = app.gTruth.(imgCol);
            if ~(iscellstr(paths) || isstring(paths))
                error('找不到有效的影像路徑欄位（預期為 imageFilename 或第一欄為路徑字串）。');
            end

            % 可能的標籤欄位（除去影像欄）
            labelVars = setdiff(varNames, imgCol, 'stable');

            % 挑出「像 bbox」的欄位（常見為 cell，每列是一組 Mx4 框）
            isBoxLike = @(v) iscell(app.gTruth.(v));
            boxCols   = labelVars(cellfun(isBoxLike, labelVars));

            isDetection = false;
            try
                if ~isempty(boxCols)
                    app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:, boxCols));
                    app.bldsVal   = boxLabelDatastore(app.validationDataTbl(:, boxCols));
                    app.bldsTest  = boxLabelDatastore(app.testDataTbl(:, boxCols));
                    isDetection   = true;
                else
                    % 若完全沒框欄，視情況可在此轉成分類（此處僅清空）
                    app.bldsTrain = []; app.bldsVal = []; app.bldsTest = [];
                end
            catch
                % 若 blds 建立失敗，也視為非偵測
                isDetection = false;
                app.bldsTrain = []; app.bldsVal = []; app.bldsTest = [];
            end

            % —— 建立影像 Datastore（無論偵測/分類都會有）——
            app.imdsTrain = imageDatastore(app.trainingDataTbl.(imgCol));
            app.imdsVal   = imageDatastore(app.validationDataTbl.(imgCol));
            app.imdsTest  = imageDatastore(app.testDataTbl.(imgCol));

            % （可選）若 isDetection=false 而你其實是分類資料，
            % 可在此找一個 label 欄位並設定 Labels：
            % if ~isDetection
            %     lblCol = pick your label column here...
            %     app.imdsTrain.Labels = categorical(app.trainingDataTbl.(lblCol));
            %     app.imdsVal.Labels   = categorical(app.validationDataTbl.(lblCol));
            %     app.imdsTest.Labels  = categorical(app.testDataTbl.(lblCol));
            % end

            % 改變燈號（RGB 向量）
            if isprop(app,'CheckLamp')
                app.CheckLamp.Color = [0 1 0];
            end
            
        end

        % Button pushed function: AugmentationButton
        function AugmentationButtonPushed(app, event)

                % ——初始化最小化的 opts（只留本段會用到的鍵）——
                app.opts = struct( ...
                    'EnableColorJitter', false, ...
                    'Hue',        0, ...
                    'Saturation', 0, ...
                    'Brightness', 0, ...
                    'Contrast',   0, ...
                    'EnableAffine', false, ...
                    'XReflection', false, ...
                    'YReflection', false, ...
                    'ScaleRange',    [1 1], ...  % 中性：不縮放
                    'RotationRange', [0 0] ...   % 中性：不旋轉
                    );

                % ——取被勾選節點（這裡用 Text；若你改用 NodeData，換成 {nodes.NodeData}）——
                nodes = app.Tree.CheckedNodes;
                if isempty(nodes)
                    keys = string([]);  % 沒勾任何項目
                else
                    keys = lower(string({nodes.Text}));
                end
                has    = @(k)   any(keys == lower(string(k)));                 % 單鍵命中？
                hasAny = @(arr) any(ismember(keys, lower(string(arr))));       % 多鍵其一命中？

                %% ===== ColorJitter 區 =====
                useHue = has('Hue');
                useSat = has('Saturation');
                useBri = has('Brightness');
                useCtr = has('Contrast');

                useCJ  = hasAny({'jitterColorHSV','jitter','Hue','Saturation','Brightness','Contrast'});
                app.opts.EnableColorJitter = useCJ;

                % 勾選才讀 UI；未勾選給「中性值」（0 = 不改動）
                if useHue, app.opts.Hue        = getVal(app,'HueEditField',        0.1); else, app.opts.Hue        = 0; end
                if useSat, app.opts.Saturation = getVal(app,'SaturationEditField', 0.2); else, app.opts.Saturation = 0; end
                if useBri, app.opts.Brightness = getVal(app,'BrightnessEditField', 0.2); else, app.opts.Brightness = 0; end
                if useCtr, app.opts.Contrast   = getVal(app,'ContrastEditField',   0.0); else, app.opts.Contrast   = 0; end

                %% ===== Affine 區 =====
                useX  = has('XReflection');
                useY  = has('YReflection');
                useSc = has('Scale');
                useR  = has('Rotation');

                useAf = hasAny({'randomAffine2d','affine','XReflection','YReflection','Scale','Rotation'});
                app.opts.EnableAffine = useAf;

                app.opts.XReflection = useX;        % 直接用勾選布林
                app.opts.YReflection = useY;

                % 勾選才讀範圍；未勾選給「中性範圍」
                if useSc
                    app.opts.ScaleRange    = getRange(app,'ScaleMinField','ScaleMaxField',[1 1.1]);
                else
                    app.opts.ScaleRange    = [1 1];
                end
                if useR
                    app.opts.RotationRange = getRange(app,'RotMinField','RotMaxField',[0 15]); % 依你 UI 預設
                else
                    app.opts.RotationRange = [0 0];
                end


                % 執行副程式
                % 這裡組合成偵測訓練的 datastore
                app.imdsTrain = imageDatastore(app.trainingDataTbl.imageFilename);
                app.bldsTrain = boxLabelDatastore(app.trainingDataTbl(:,2:end));
                dsTrain = combine(app.imdsTrain, app.bldsTrain);

                app.imdsVal = imageDatastore(app.validationDataTbl.imageFilename);
                app.bldsVal = boxLabelDatastore(app.validationDataTbl(:,2:end));
                dsVal = combine(app.imdsVal, app.bldsVal);

                % 先做「資料增強」，再做「前處理到網路輸入大小」
                app.dsTrainAug  = transform(dsTrain,  @(data) augmentData(app, data));
                app.dsTrainPrep = transform(app.dsTrainAug,@(data) preprocessData(app, data));
                app.dsValPrep   = transform(dsVal,    @(data) preprocessData(app, data));


                % 4. 成功，設定 lamp 為綠色
                app.CheckLamp_2.Color = [0 1 0];

                % ===== 小工具 =====
                function v = getVal(app, fieldName, fallback)
                    % 讀某個 UI 的 Value；沒有該元件就用 fallback
                    v = fallback;
                    if isprop(app, fieldName) && isprop(app.(fieldName),'Value') && ~isempty(app.(fieldName).Value)
                        vv = app.(fieldName).Value;
                        if ischar(vv) || isstring(vv)
                            t = str2double(string(vv));
                            if ~isnan(t), v = t; else, v = fallback; end
                        else
                            v = double(vv);
                        end
                    end
                end

                function r = getRange(app, minName, maxName, fallback)
                    % 從兩個欄位組成 [min max]；不合法就回 fallback
                    r = fallback;
                    ok = isprop(app,minName) && isprop(app.(minName),'Value') && ...
                        isprop(app,maxName) && isprop(app.(maxName),'Value');
                    if ok
                        a = app.(minName).Value; b = app.(maxName).Value;
                        a = parseNum(a); b = parseNum(b);
                        if ~isnan(a) && ~isnan(b)
                            r = [a b];
                        end
                    end

                    function x = parseNum(v)
                        if ischar(v) || isstring(v), x = str2double(string(v)); else, x = double(v); end
                    end
                end


        end

        % Button pushed function: InputSizeButton
        function InputSizeButtonPushed(app, event)
            
            app.inputSize = [app.InputXEditField.Value, app.InputYEditField.Value, 3];
            app.CheckLamp_3.Color =[0 1 0];

        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            
        end

        % Button pushed function: left_TainButton
        function left_TainButtonPushed(app, event)
            
            app.traincurrentIdx = app.trainIdx -1;
            if(app.traincurrentIdx < 1)
                app.traincurrentIdx = 1;
            end

            app.countIdx_2.Text = sprintf('%d / %d', app.traincurrentIdx, numel(app.gTruth.imageFilename));

            folderpath = app.gTruth.imageFilename{app.traincurrentIdx};
            [~, lastFileName, ~] = fileparts(folderpath);
            app.datastoreLabel_2.Text = lastFileName;

            % 顯示目前影像
            TrainReadImg(app, app.traincurrentIdx);
        end

        % Button pushed function: Right_TainButton
        function Right_TainButtonPushed(app, event)
            
            app.traincurrentIdx = app.trainIdx + 1;
            if(app.traincurrentIdx >numel(app.gTruth.imageFilename))
                app.traincurrentIdx = numel(app.gTruth.imageFilename);
            end

            app.countIdx_2.Text = sprintf('%d / %d', app.traincurrentIdx, numel(app.gTruth.imageFilename));

            folderpath = app.gTruth.imageFilename{app.traincurrentIdx};
            [~, lastFileName, ~] = fileparts(folderpath);
            app.datastoreLabel_2.Text = lastFileName;

            % 顯示目前影像
            TrainReadImg(app, app.traincurrentIdx);


        end

        % Button pushed function: StartTestDataButton
        function StartTestDataButtonPushed(app, event)

            detectionResults = detect(app.trainedDetector,app.imdsTest,Threshold = app.ChageThresholdEditField_2.Value);
            metrics = evaluateObjectDetection(detectionResults, app.bldsTest);      

            [recall,precision,scores] = precisionRecall(metrics);
            ap = averagePrecision(metrics);

            ax = app.UIAxes;  
            cla(ax);                           % 清空
            plot(ax, recall{1}, precision{1}, '-', 'LineWidth', 1.5);
            grid(ax, 'on');
            xlabel(ax, 'Recall');
            ylabel(ax, 'Precision');
            title(ax, sprintf('mAP = %.3f', mean(ap, 'omitnan')));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VIStudio_ObjectDetectionUIFigure = uifigure('Visible', 'off');
            app.VIStudio_ObjectDetectionUIFigure.Name = 'VIStudio_ObjectDetection';
            app.Panel = uipanel(app.VIStudio_ObjectDetectionUIFigure);
            app.ObjectDetectionLabel = uilabel(app.Panel);
            app.ObjectDetectionLabel.Text = 'Object Detection';
            app.TabGroup = uitabgroup(app.VIStudio_ObjectDetectionUIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.Panel_5 = uipanel(app.LoadDataTab);
            app.LoadgTruthButton = uibutton(app.Panel_5, 'push');
            app.LoadgTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadgTruthButtonPushed, true);
            app.LoadgTruthButton.Icon = fullfile(pathToMLAPP, 'Icon', 'groundTruth.png');
            app.LoadgTruthButton.Text = {'Load'; 'gTruth'};
            app.gTruthLoadedLampLabel = uilabel(app.Panel_5);
            app.gTruthLoadedLampLabel.Text = 'gTruth Loaded';
            app.gTruthLoadedLamp = uilamp(app.Panel_5);
            app.DataSourceEditFieldLabel = uilabel(app.Panel_5);
            app.DataSourceEditFieldLabel.Text = 'Data Source';
            app.DataSourceEditField = uieditfield(app.Panel_5, 'text');
            app.DataSourceEditField.Editable = 'off';
            app.Image = uiimage(app.LoadDataTab);
            app.Panel_9 = uipanel(app.LoadDataTab);
            app.datastoreLabel = uilabel(app.Panel_9);
            app.datastoreLabel.Text = '';
            app.Button = uibutton(app.Panel_9, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.Panel_9, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.countIdx = uilabel(app.Panel_9);
            app.countIdx.Text = '';
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.Panel_6 = uipanel(app.TrainingTab);
            app.StartTrainButton = uibutton(app.Panel_6, 'push');
            app.StartTrainButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainButtonPushed, true);
            app.StartTrainButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Train.png');
            app.StartTrainButton.Text = {'Start'; 'Train'};
            app.TrainingOptionsButton = uibutton(app.Panel_6, 'state');
            app.TrainingOptionsButton.ValueChangedFcn = createCallbackFcn(app, @TrainingOptionsButtonValueChanged, true);
            app.TrainingOptionsButton.Icon = fullfile(pathToMLAPP, 'Icon', 'setting.png');
            app.TrainingOptionsButton.Text = {'Training '; 'Options'};
            app.SpiitDataPanel = uipanel(app.TrainingTab);
            app.SpiitDataPanel.Title = 'Spiit Data';
            app.TrainingDataEditFieldLabel = uilabel(app.SpiitDataPanel);
            app.TrainingDataEditFieldLabel.Text = 'Training Data';
            app.TrainingDataEditField = uieditfield(app.SpiitDataPanel, 'numeric');
            app.TrainingDataEditField.Value = 80;
            app.ValidationDataEditFieldLabel = uilabel(app.SpiitDataPanel);
            app.ValidationDataEditFieldLabel.Text = 'Validation Data';
            app.ValidationDataEditField = uieditfield(app.SpiitDataPanel, 'numeric');
            app.ValidationDataEditField.Value = 10;
            app.TestingDataEditFieldLabel = uilabel(app.SpiitDataPanel);
            app.TestingDataEditFieldLabel.Text = 'Testing Data';
            app.TestingDataEditField = uieditfield(app.SpiitDataPanel, 'numeric');
            app.TestingDataEditField.Value = 10;
            app.CheckLampLabel = uilabel(app.SpiitDataPanel);
            app.CheckLampLabel.Text = 'Check';
            app.CheckLamp = uilamp(app.SpiitDataPanel);
            app.SplitDataButton = uibutton(app.SpiitDataPanel, 'push');
            app.SplitDataButton.ButtonPushedFcn = createCallbackFcn(app, @SplitDataButtonPushed, true);
            app.SplitDataButton.Text = 'Split Data';
            app.ImageAugmentationPanel = uipanel(app.TrainingTab);
            app.ImageAugmentationPanel.Title = 'Image Augmentation';
            app.AugmentationButton = uibutton(app.ImageAugmentationPanel, 'push');
            app.AugmentationButton.ButtonPushedFcn = createCallbackFcn(app, @AugmentationButtonPushed, true);
            app.AugmentationButton.Text = 'Augmentation';
            app.CheckLamp_2Label = uilabel(app.ImageAugmentationPanel);
            app.CheckLamp_2Label.Text = 'Check';
            app.CheckLamp_2 = uilamp(app.ImageAugmentationPanel);
            app.Tree = uitree(app.ImageAugmentationPanel, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.Editable = 'on';
            app.jitterColorHSVNode = uitreenode(app.Tree);
            app.jitterColorHSVNode.Text = 'jitterColorHSV';
            app.HueNode = uitreenode(app.jitterColorHSVNode);
            app.HueNode.Text = 'Hue';
            app.SaturationNode = uitreenode(app.jitterColorHSVNode);
            app.SaturationNode.Text = 'Saturation';
            app.BrightnessNode = uitreenode(app.jitterColorHSVNode);
            app.BrightnessNode.Text = 'Brightness';
            app.ContrastNode = uitreenode(app.jitterColorHSVNode);
            app.ContrastNode.Text = 'Contrast';
            app.randomAffine2dNode = uitreenode(app.Tree);
            app.randomAffine2dNode.Text = 'randomAffine2d';
            app.XReflectionNode = uitreenode(app.randomAffine2dNode);
            app.XReflectionNode.Text = 'XReflection';
            app.YReflectionNode = uitreenode(app.randomAffine2dNode);
            app.YReflectionNode.Text = 'YReflection';
            app.ScaleNode = uitreenode(app.randomAffine2dNode);
            app.ScaleNode.Text = 'Scale';
            app.RotationNode = uitreenode(app.randomAffine2dNode);
            app.RotationNode.Text = 'Rotation';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.HueNode, app.SaturationNode, app.BrightnessNode, app.XReflectionNode, app.YReflectionNode];
            app.Panel_10 = uipanel(app.TrainingTab);
            app.ModelReloadButton = uibutton(app.Panel_10, 'push');
            app.ModelReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ModelReloadButtonPushed, true);
            app.ModelReloadButton.Text = 'Reload';
            app.ModelDropDownLabel = uilabel(app.Panel_10);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.Panel_10);
            app.ModelDropDown.Items = {'YOLOX-nano', 'YOLOX-tiny', 'YOLOX-small', 'YOLOX-medium', 'YOLOX-large', 'CustomTrainedNetwork'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'YOLOX-tiny';
            app.ModelLoadedLampLabel = uilabel(app.Panel_10);
            app.ModelLoadedLampLabel.Text = 'Model Loaded';
            app.ModelLoadedLamp = uilamp(app.Panel_10);
            app.Panel_11 = uipanel(app.TrainingTab);
            app.InputXEditFieldLabel = uilabel(app.Panel_11);
            app.InputXEditFieldLabel.Text = 'Input X';
            app.InputXEditField = uieditfield(app.Panel_11, 'numeric');
            app.InputXEditField.Value = 608;
            app.InputYEditFieldLabel = uilabel(app.Panel_11);
            app.InputYEditFieldLabel.Text = 'Input Y';
            app.InputYEditField = uieditfield(app.Panel_11, 'numeric');
            app.InputYEditField.Value = 608;
            app.InputSizeButton = uibutton(app.Panel_11, 'push');
            app.InputSizeButton.ButtonPushedFcn = createCallbackFcn(app, @InputSizeButtonPushed, true);
            app.InputSizeButton.Text = 'Input Size';
            app.CheckLamp_3Label = uilabel(app.Panel_11);
            app.CheckLamp_3Label.Text = 'Check';
            app.CheckLamp_3 = uilamp(app.Panel_11);
            app.Image2 = uiimage(app.TrainingTab);
            app.Panel_12 = uipanel(app.TrainingTab);
            app.datastoreLabel_2 = uilabel(app.Panel_12);
            app.datastoreLabel_2.Text = '';
            app.left_TainButton = uibutton(app.Panel_12, 'push');
            app.left_TainButton.ButtonPushedFcn = createCallbackFcn(app, @left_TainButtonPushed, true);
            app.left_TainButton.Text = '<';
            app.Right_TainButton = uibutton(app.Panel_12, 'push');
            app.Right_TainButton.ButtonPushedFcn = createCallbackFcn(app, @Right_TainButtonPushed, true);
            app.Right_TainButton.Text = '>';
            app.countIdx_2 = uilabel(app.Panel_12);
            app.countIdx_2.Text = '';
            app.HaveBBoxLampLabel = uilabel(app.TrainingTab);
            app.HaveBBoxLampLabel.Text = 'Have BBox';
            app.HaveBBoxLamp = uilamp(app.TrainingTab);
            app.ChageThresholdEditFieldLabel = uilabel(app.TrainingTab);
            app.ChageThresholdEditFieldLabel.Text = 'Chage Threshold';
            app.ChageThresholdEditField = uieditfield(app.TrainingTab, 'numeric');
            app.ChageThresholdEditField.Value = 0.5;
            app.InferenceTab = uitab(app.TabGroup);
            app.InferenceTab.Title = 'Inference';
            app.UIAxes = uiaxes(app.InferenceTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_7 = uipanel(app.InferenceTab);
            app.ObjectDetectionButton = uibutton(app.Panel_7, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionButtonPushed, true);
            app.ObjectDetectionButton.Icon = fullfile(pathToMLAPP, 'Icon', 'eye.png');
            app.ObjectDetectionButton.Text = {'Object'; 'Detection'};
            app.InferenceImageButton = uibutton(app.Panel_7, 'push');
            app.InferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @InferenceImageButtonPushed, true);
            app.InferenceImageButton.Icon = fullfile(pathToMLAPP, 'Icon', 'photo.png');
            app.InferenceImageButton.Text = {'Inference'; 'Image'};
            app.ModelGroupButtonGroup = uibuttongroup(app.Panel_7);
            app.ModelGroupButtonGroup.Title = 'Model Group';
            app.UseTrainedModelButton = uiradiobutton(app.ModelGroupButtonGroup);
            app.UseTrainedModelButton.Text = 'Use Trained Model';
            app.UseTrainedModelButton.Value = true;
            app.AddanothermodelButton = uiradiobutton(app.ModelGroupButtonGroup);
            app.AddanothermodelButton.Text = 'Add another model';
            app.LoadanothermodelButton = uibutton(app.ModelGroupButtonGroup, 'push');
            app.LoadanothermodelButton.Text = {'Load another '; 'model'};
            app.StartTestDataButton = uibutton(app.Panel_7, 'push');
            app.StartTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestDataButtonPushed, true);
            app.StartTestDataButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Train.png');
            app.StartTestDataButton.Text = {'Start'; 'Test Data'};
            app.ModelLoadedLamp_2Label = uilabel(app.Panel_7);
            app.ModelLoadedLamp_2Label.Text = 'Model Loaded';
            app.ModelLoadedLamp_2 = uilamp(app.Panel_7);
            app.Image3 = uiimage(app.InferenceTab);
            app.ChageThresholdEditField_2Label = uilabel(app.InferenceTab);
            app.ChageThresholdEditField_2Label.Text = 'Chage Threshold';
            app.ChageThresholdEditField_2 = uieditfield(app.InferenceTab, 'numeric');
            app.ChageThresholdEditField_2.Value = 0.1;
            app.ChageThresholdEditField_3Label = uilabel(app.InferenceTab);
            app.ChageThresholdEditField_3Label.Text = 'Chage Threshold';
            app.ChageThresholdEditField_3 = uieditfield(app.InferenceTab, 'numeric');
            app.ChageThresholdEditField_3.Value = 0.4;
            app.HaveBBoxLamp_2Label = uilabel(app.InferenceTab);
            app.HaveBBoxLamp_2Label.Text = 'Have BBox';
            app.HaveBBoxLamp_2 = uilamp(app.InferenceTab);
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.Panel_8 = uipanel(app.ExportTab);
            app.ExportModelButton = uibutton(app.Panel_8, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Icon = fullfile(pathToMLAPP, 'Icon', 'Export.png');
            app.ExportModelButton.Text = {'Export'; 'Model'};
            % Show the figure after all components are created
            app.VIStudio_ObjectDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_ObjectDetectionApp_T1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VIStudio_ObjectDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VIStudio_ObjectDetectionUIFigure)
        end
    end
end