classdef VIStudio_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VIStudioUIFigure        matlab.ui.Figure
        AnomalyDetectionButton  matlab.ui.control.Button
        VIStudioButton          matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        ObjectDetectionButton   matlab.ui.control.Button
        ClassificationButton    matlab.ui.control.Button
        LabelerButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        LabelerApp % Description
        ClassificationApp
        ObjectDetectionApp
        AnomalyDetectionApp

        AddedPath char
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ============================================
            %   2025/09/026 - Fred Liu
            %   v1.0 version 
            % ============================================
            % 例：將與 .mlapp 同層的 "toolbox" 整包加入
            appRoot = fileparts(mfilename('fullpath'));
            folderToAdd = fullfile(appRoot); % 加入此資料夾
            %folderToAdd = fullfile(appRoot, 'toolbox');

            if isfolder(folderToAdd)
                p = genpath(folderToAdd);    % 產生含所有子夾的路徑字串
                addpath(p);                  % 加入搜尋路徑
                app.AddedPath = p;           % 記錄以便關閉時移除
            else
                uialert(app.VIStudioUIFigure, "找不到資料夾: " + folderToAdd, "Path");
            end


        end

        % Button pushed function: LabelerButton
        function LabelerButtonPushed(app, event)
            % -- Call Labeler App for labeling --
            app.LabelerApp = VIStudio_ImageLabeler;
        end

        % Button pushed function: ClassificationButton
        function ClassificationButtonPushed(app, event)
            app.ClassificationApp = VIStudio_ClassificationApp_T1;
        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionButtonPushed(app, event)
            app.ObjectDetectionApp = VIStudio_ObjectDetectionApp_T1;
        end

        % Button pushed function: AnomalyDetectionButton
        function AnomalyDetectionButtonPushed(app, event)
            app.AnomalyDetectionApp = VIStudio_AnomalyDetectionApp;
        end

        % Close request function: VIStudioUIFigure
        function VIStudioUIFigureCloseRequest(app, event)
            delete(app.LabelerApp);
            delete(app.ClassificationApp);
            delete(app.ObjectDetectionApp);
            delete(app.AnomalyDetectionApp);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VIStudioUIFigure = uifigure('Visible', 'off');
            app.VIStudioUIFigure.Name = 'VIStudio';
            app.VIStudioUIFigure.CloseRequestFcn = createCallbackFcn(app, @VIStudioUIFigureCloseRequest, true);
            app.LabelerButton = uibutton(app.VIStudioUIFigure, 'push');
            app.LabelerButton.ButtonPushedFcn = createCallbackFcn(app, @LabelerButtonPushed, true);
            app.LabelerButton.Icon = fullfile(pathToMLAPP, 'Icon', 'tags.png');
            app.LabelerButton.Text = 'Labeler';
            app.ClassificationButton = uibutton(app.VIStudioUIFigure, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificationButtonPushed, true);
            app.ClassificationButton.Icon = fullfile(pathToMLAPP, 'Icon', 'classification.png');
            app.ClassificationButton.Text = 'Classification';
            app.ObjectDetectionButton = uibutton(app.VIStudioUIFigure, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionButtonPushed, true);
            app.ObjectDetectionButton.Icon = fullfile(pathToMLAPP, 'Icon', 'vision.png');
            app.ObjectDetectionButton.Text = 'Object Detection';
            app.Panel = uipanel(app.VIStudioUIFigure);
            app.VIStudioButton = uibutton(app.VIStudioUIFigure, 'push');
            app.VIStudioButton.Text = 'VIStudio';
            app.AnomalyDetectionButton = uibutton(app.VIStudioUIFigure, 'push');
            app.AnomalyDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @AnomalyDetectionButtonPushed, true);
            app.AnomalyDetectionButton.Icon = fullfile(pathToMLAPP, 'Icon', 'target.png');
            app.AnomalyDetectionButton.Text = 'Anomaly Detection';
            % Show the figure after all components are created
            app.VIStudioUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_demo

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VIStudioUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VIStudioUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VIStudioUIFigure)
        end
    end
end